classdef STICCSandTFMgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DSTICCSAnalysisTab              matlab.ui.container.Tab
        WriteAlignedandCropedImagestoTIFButton  matlab.ui.control.Button
        TemporalAlignementSeriesPanel   matlab.ui.container.Panel
        FindBeadsButton                 matlab.ui.control.Button
        BeadRadiuspixelsEditField       matlab.ui.control.NumericEditField
        BeadRadiuspixelsEditFieldLabel  matlab.ui.control.Label
        MinIntensityBeadEditField       matlab.ui.control.NumericEditField
        MinIntensityBeadEditFieldLabel  matlab.ui.control.Label
        MaxCenttoCentDistEditField      matlab.ui.control.NumericEditField
        MaxCenttoCentDistEditFieldLabel  matlab.ui.control.Label
        AligntoUnstressedBeadsButton    matlab.ui.control.StateButton
        DefineOverallCellMaskPanel      matlab.ui.container.Panel
        orSelectCellContourManuallyButton  matlab.ui.control.Button
        LBCellAreaSlider                matlab.ui.control.Slider
        LBCellAreaSliderLabel           matlab.ui.control.Label
        GenerateOverallCellsMaskButton  matlab.ui.control.Button
        ImageforoverallcellshapeDropDown  matlab.ui.control.DropDown
        ImageforoverallcellshapeDropDownLabel  matlab.ui.control.Label
        BinariseCellShapeSlider         matlab.ui.control.Slider
        BinariseCellShapeSliderLabel    matlab.ui.control.Label
        RunSTICCSPanel                  matlab.ui.container.Panel
        ChaneltowhichapplySTICSEditField  matlab.ui.control.NumericEditField
        ChaneltowhichapplySTICSEditFieldLabel  matlab.ui.control.Label
        RunSTICSButton                  matlab.ui.control.Button
        LoadParamsButton                matlab.ui.control.Button
        SaveParamsButton                matlab.ui.control.Button
        BatchRunSTICCSButton            matlab.ui.control.Button
        RunSTICCSButton                 matlab.ui.control.Button
        STICCInputParametersPanel       matlab.ui.container.Panel
        FilteringChoiceDropDown         matlab.ui.control.DropDown
        FilteringChoiceDropDownLabel    matlab.ui.control.Label
        OuputDirectoryEditField         matlab.ui.control.EditField
        OuputDirectoryEditFieldLabel    matlab.ui.control.Label
        MovieFormatavimp4jpegEditField  matlab.ui.control.EditField
        MovieFormatavimp4jpegEditFieldLabel  matlab.ui.control.Label
        ImagesFormatpngpdfjpgEditField  matlab.ui.control.EditField
        ImagesFormatpngpdfjpgEditFieldLabel  matlab.ui.control.Label
        ExportImagesEditField           matlab.ui.control.EditField
        ExportImagesEditFieldLabel      matlab.ui.control.Label
        OutputFileNameEditField         matlab.ui.control.EditField
        OutputFileNameEditFieldLabel    matlab.ui.control.Label
        AxisTitleEditField              matlab.ui.control.EditField
        AxisTitleEditFieldLabel         matlab.ui.control.Label
        TOIshiftframesEditField         matlab.ui.control.NumericEditField
        TOIshiftframesEditFieldLabel    matlab.ui.control.Label
        TOIsizeframesEditField          matlab.ui.control.NumericEditField
        TOIsizeframesEditFieldLabel     matlab.ui.control.Label
        ROIshiftpixelsEditField         matlab.ui.control.NumericEditField
        ROIshiftpixelsEditFieldLabel    matlab.ui.control.Label
        ROIsizepixelsEditField          matlab.ui.control.NumericEditField
        ROIsizepixelsEditFieldLabel     matlab.ui.control.Label
        stdspeedthresholdEditField      matlab.ui.control.NumericEditField
        stdspeedthresholdEditFieldLabel  matlab.ui.control.Label
        TauFitLimitframeEditField       matlab.ui.control.NumericEditField
        TauFitLimitframeEditFieldLabel  matlab.ui.control.Label
        FitRadiuspixelsEditField        matlab.ui.control.NumericEditField
        FitRadiuspixelsEditFieldLabel   matlab.ui.control.Label
        MovAveRadiusframesEditField     matlab.ui.control.NumericEditField
        MovAveRadiusframesEditFieldLabel  matlab.ui.control.Label
        TauLimitframesEditField         matlab.ui.control.NumericEditField
        TauLimitframesEditFieldLabel    matlab.ui.control.Label
        TimeDelay2ChsecEditField        matlab.ui.control.NumericEditField
        TimeDelay2ChsecEditFieldLabel   matlab.ui.control.Label
        FrameTimesecondsEditField       matlab.ui.control.NumericEditField
        FrameTimesecondsEditFieldLabel  matlab.ui.control.Label
        PixelSizemicrometersEditField   matlab.ui.control.NumericEditField
        PixelSizemicrometersEditFieldLabel  matlab.ui.control.Label
        FramesSliderLabel               matlab.ui.control.Label
        LoadCropandAlignDataPanel       matlab.ui.container.Panel
        LoadUnstressedBeadsOMEImageButton  matlab.ui.control.Button
        BeadsChIDEditField              matlab.ui.control.NumericEditField
        BeadsChIDEditFieldLabel         matlab.ui.control.Label
        LBYSlider                       matlab.ui.control.Slider
        LBYSliderLabel                  matlab.ui.control.Label
        UBYSlider                       matlab.ui.control.Slider
        UBYSliderLabel                  matlab.ui.control.Label
        LoadMultitiforOMEdatafileButton  matlab.ui.control.Button
        UBXSlider                       matlab.ui.control.Slider
        UBXSliderLabel                  matlab.ui.control.Label
        LBXSlider                       matlab.ui.control.Slider
        LBXSliderLabel                  matlab.ui.control.Label
        FramesSlider                    matlab.ui.control.Slider
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        TFMAnalysisTab                  matlab.ui.container.Tab
        LoadBlackColormapFileButton     matlab.ui.control.Button
        OtherParametersPanel            matlab.ui.container.Panel
        DisplacementfieldsmoothingEditField  matlab.ui.control.NumericEditField
        DisplacementfieldsmoothingEditFieldLabel  matlab.ui.control.Label
        WindowSizepixelsEditField       matlab.ui.control.NumericEditField
        WindowSizepixelsEditFieldLabel  matlab.ui.control.Label
        RunTractionForceMicroscopyAnalysisPanel  matlab.ui.container.Panel
        DisplayTFMdataDropDown          matlab.ui.control.DropDown
        DisplayTFMdataDropDownLabel     matlab.ui.control.Label
        RunTFMandSaveAllDataButton      matlab.ui.control.Button
        MakeStressMagnitudeandDisplacementsMovieButton  matlab.ui.control.Button
        ShowForcesExertedbyCellOnSubstrateAndSaveinTIFButton  matlab.ui.control.Button
        ShowBeadsDisplacementVectorMapandSaveinTIFButton  matlab.ui.control.Button
        RunTFMButton                    matlab.ui.control.Button
        TrackingParametersPanel         matlab.ui.container.Panel
        MemoryframesparticlecandisappeartemporarilyEditField  matlab.ui.control.NumericEditField
        MemoryframesparticlecandisappeartemporarilyEditFieldLabel  matlab.ui.control.Label
        TheminimumlengthoftrajectoryEditField  matlab.ui.control.NumericEditField
        TheminimumlengthoftrajectoryEditFieldLabel  matlab.ui.control.Label
        CorrelationThresholdEditField   matlab.ui.control.NumericEditField
        CorrelationThresholdEditFieldLabel  matlab.ui.control.Label
        MinimumdistancetonearestneighbourpixelsEditField  matlab.ui.control.NumericEditField
        MinimumdistancetonearestneighbourpixelsEditFieldLabel  matlab.ui.control.Label
        MaximaldisplacementbetweentwoimagespixelsEditField  matlab.ui.control.NumericEditField
        MaximaldisplacementbetweentwoimagespixelsEditFieldLabel  matlab.ui.control.Label
        MinimalintegratedintensityEditField  matlab.ui.control.NumericEditField
        MinimalintegratedintensityEditFieldLabel  matlab.ui.control.Label
        MinimalRatioIntensitytoradiusEditField  matlab.ui.control.NumericEditField
        MinimalRatioIntensitytoradiusEditFieldLabel  matlab.ui.control.Label
        Particleseccentricity0roundEditField  matlab.ui.control.NumericEditField
        Particleseccentricity0roundEditFieldLabel  matlab.ui.control.Label
        MaximumradiusofgyrationofparticlespixelsEditField  matlab.ui.control.NumericEditField
        MaximumradiusofgyrationofparticlespixelsEditFieldLabel  matlab.ui.control.Label
        MinimalparticlesizepixelsEditField  matlab.ui.control.NumericEditField
        MinimalparticlesizepixelsEditFieldLabel  matlab.ui.control.Label
        PIVParametersPanel              matlab.ui.container.Panel
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        StepSizeMultipleofPixelEditField  matlab.ui.control.NumericEditField
        StepSizeMultipleofPixelEditFieldLabel  matlab.ui.control.Label
        IntervalpixelsEditField         matlab.ui.control.NumericEditField
        IntervalpixelsEditFieldLabel    matlab.ui.control.Label
        OverlapTrackpixelsEditField     matlab.ui.control.NumericEditField
        OverlapTrackpixelsEditFieldLabel  matlab.ui.control.Label
        OverlapPIVpixelsEditField       matlab.ui.control.NumericEditField
        OverlapPIVpixelsEditFieldLabel  matlab.ui.control.Label
        PixelSizemEditField             matlab.ui.control.NumericEditField
        PixelSizemEditFieldLabel        matlab.ui.control.Label
        GelpropertiesPanel              matlab.ui.container.Panel
        CoefofRegularisationdependonYoungModulusEditField  matlab.ui.control.NumericEditField
        CoefofRegularisationdependonYoungModulusEditFieldLabel  matlab.ui.control.Label
        PoissonratioEditField           matlab.ui.control.NumericEditField
        PoissonratioEditFieldLabel      matlab.ui.control.Label
        YoungModulusPaEditField         matlab.ui.control.NumericEditField
        YoungModulusPaEditFieldLabel    matlab.ui.control.Label
        LoadordefinedatasetsandmasksPanel  matlab.ui.container.Panel
        LoadAKBeadsImageButton          matlab.ui.control.Button
        UseImagesandMasksDefinedinSTICCSTabButton  matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        LoadMasksTIFButton              matlab.ui.control.Button
        LoadCellChTIFButton             matlab.ui.control.Button
        LoadBeadsTIFButton              matlab.ui.control.Button
        FramesSliderLabel_2             matlab.ui.control.Label
        FramesSlider_2                  matlab.ui.control.Slider
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        DataVisualisationTab            matlab.ui.container.Tab
        DisplaySTICCSandTFMdatatogetherPanel  matlab.ui.container.Panel
        SavehistogramofvectordotproductButton  matlab.ui.control.Button
        ShowhistogramofvectordotproductButton  matlab.ui.control.Button
        CalculatevectordotproductandshowmapButton  matlab.ui.control.Button
        SavevectordotproductmapButton   matlab.ui.control.Button
        UBYSlider_2                     matlab.ui.control.Slider
        UBYSlider_2Label                matlab.ui.control.Label
        LBYSlider_2                     matlab.ui.control.Slider
        LBYSlider_2Label                matlab.ui.control.Label
        UBXSlider_2                     matlab.ui.control.Slider
        UBXSlider_2Label                matlab.ui.control.Label
        LBXSlider_2                     matlab.ui.control.Slider
        LBXSlider_2Label                matlab.ui.control.Label
        SaveMagnitudesMapsSuperimposedButton  matlab.ui.control.Button
        SaveVectorMapsSuperimposedButton  matlab.ui.control.Button
        ShowMagnitudesMapsSuperimposedButton  matlab.ui.control.Button
        ShowVectorMapsSuperimposedButton  matlab.ui.control.Button
        TFMdataDropDown                 matlab.ui.control.DropDown
        TFMdataDropDownLabel            matlab.ui.control.Label
        STICCSdataDropDown              matlab.ui.control.DropDown
        STICCSdataDropDownLabel         matlab.ui.control.Label
        DisplaySTICCSDataPanel          matlab.ui.container.Panel
        VectorScaleFactorEditField      matlab.ui.control.NumericEditField
        VectorScaleFactorEditFieldLabel  matlab.ui.control.Label
        BckdImageDropDown               matlab.ui.control.DropDown
        BckdImageDropDownLabel          matlab.ui.control.Label
        LBspeedSlider                   matlab.ui.control.Slider
        LBspeedSliderLabel              matlab.ui.control.Label
        ShowVectorMapsForSelectedChannelButton  matlab.ui.control.Button
        UseFilteredVectorsOnlyButton    matlab.ui.control.StateButton
        STICCSdataDropDown_2            matlab.ui.control.DropDown
        STICCSdataDropDown_2Label       matlab.ui.control.Label
        ExportVectorMapsForAllChannelsButton  matlab.ui.control.Button
        ExportVectorMapsForSelectedChannelButton  matlab.ui.control.Button
        ExportSpeedHistogramButton      matlab.ui.control.Button
        SpeedHistogramButton            matlab.ui.control.Button
        UBspeedSlider                   matlab.ui.control.Slider
        UBspeedSliderLabel              matlab.ui.control.Label
        LoadPanel                       matlab.ui.container.Panel
        LoadBlackColormapFileButton_2   matlab.ui.control.Button
        LoadTFMAnalysisResultsButton    matlab.ui.control.Button
        LoadSTICCSresultsButton         matlab.ui.control.Button
        FramesSliderLabel_3             matlab.ui.control.Label
        FramesSlider_3                  matlab.ui.control.Slider
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        file
        path
        beadsChID
        pixelSize
        timeFrame
        images
        im1
        im2
        crop1
        crop2
        LBX
        UBX
        LBY
        UBY
        TempAlign
        imShape
        bw1
        thresh1
        LBcellArea
        bw2
        timeDelay
        tauLimit
        filtering
        MoveAverage
        fitRadius
        TauFitLimit
        threshVector
        ROIsize
        ROIshift
        TOIsize
        TOIshift
        axisTitle
        outputName
        path2
        exportimages
        imagesformat
        movieformat
        postouse
        showNow
        sliderFrame
        choice1
        ChSTICS
        %%%% TFM
        path3
        filebeads
        stressed
        filecell
        cellule
        filemask
        mask1
        nonstressed
        fileunstressed
        blackcmap
        Dx
        Dy
        Brightfield
        Mask1
        Tx
        Ty
        wsmoothing
        pix      % pixel size in meter
        E          % Young modulus in Pa
        nu           % Poisson ratio
        alphadef     % coef of regularization should vary with the young modulus
        window        %initial window size
        iter%number of iterations
        overlapPIV     % in pixels (applied to first window)               %32  %64
        overlapTrack  % in pixels (applied to last window befor tracking)  %16  %32
        interval
        pas
        featsize        % minimal particle size in pixel
        barrg          % maximal particle size in pixel ?
        barcc          % eccentricity (0 = round 1 = not round at all)
        IdivRg       % minimal ratio intensity/radius
        masscut     % minimal intensity: the higher, the fewer particles are detected
        maxd             % maximal displacement of one particle between the 2 images %5
        r_neighbor     % minimum distance to the nearest neighbor in pixel
        corr_th
        goodenough
        memory
        gridX
        gridY
        BWgrid
        TractionX
        TractionY
        bf
        shownow
        sliderFrame2
        cl
        redbluecmap
        position_x2
        position_y2
        position_t2
        postouse2
        velocityMap2
        velMag1
        good1
        velMag2
        good2
        velMag12
        good12
        velMag21
        good21
        showCorr
        UseGoodOnly
        currvelMag
        UBV
        LBV
        p
        f
        p2
        f2
        corrch
        sliderFrame3
        shownow2
        bckdCh
        scaleVector
        vecfield1
        vecfield2
        indT
        LBX2
        UBX2
        LBY2
        UBY2
        vecdotprodall
        DisplayTFMResults
        CtoCdist
        MinInt
        SzFeat
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadMultitiforOMEdatafileButton
        function LoadMultitiforOMEdatafileButtonPushed(app, event)
            [app.file,app.path,indx] = uigetfile('*.tif;*.czi;*.nd2;*lif;*oir;*.lsm','Please select a file to import data','MultiSelect','on');
            if length(app.file)>2
                extension=app.file(end-2:end);
            else
                extension=app.file{1}(end-2:end);
            end
            %%%%%%%
            if strcmp(extension,'tif') && length(app.file)>2
                app.im1=rd_img16([app.path app.file]);
                app.im2=[];
                app.pixelSize=1;
                app.timeFrame=1;
            elseif strcmp(extension,'tif') && length(app.file)==2
                app.im1=rd_img16([app.path app.file{1}]);
                app.im2=rd_img16([app.path app.file{2}]);
                app.pixelSize=1;
                app.timeFrame=1;
            elseif strcmp(extension,'czi') || strcmp(extension,'nd2') || strcmp(extension,'lsm') || strcmp(extension,'lif') || strcmp(extension,'oir')
                [app.images,specs]=readOMEAlternativeGUI(app.UIAxes2,[app.path app.file]);
                app.pixelSize=specs.ScaleX;
                app.timeFrame=specs.ScaleT;
                %i if 2 channels assigne data
                if specs.SizeC>1
                    app.im1=squeeze(app.images(:,:,:,1));
                    app.im2=squeeze(app.images(:,:,:,2));
                elseif specs.SizeC==1
                    app.im1=squeeze(app.images);
                    app.im2=[];
                end
            end
%             app.im1=double(app.im1);
%             app.im2=double(app.im2);
            app.PixelSizemicrometersEditField.Value=app.pixelSize;
            app.PixelSizemEditField.Value=app.pixelSize*10-6;
            app.pix=app.pixelSize*10-6;
            app.FrameTimesecondsEditField.Value=app.timeFrame;
            app.sliderFrame=1;
            app.sliderFrame2=1;
            app.FramesSlider.Limits = [1 size(app.im1,3)];
            app.FramesSlider.MajorTicks = [1:round(size(app.im1,3)/10):size(app.im1,3)];
            app.LBXSlider.Limits = [1 size(app.im1,2)];
            app.LBXSlider.MajorTicks = [1:ceil(size(app.im1,2)/10):size(app.im1,2)];
            app.LBXSlider.Value=1;
            app.UBXSlider.Limits = [1 size(app.im1,2)];
            app.UBXSlider.MajorTicks = [1:ceil(size(app.im1,2)/10):size(app.im1,2)];
            app.UBXSlider.Value=size(app.im1,2);
            app.LBYSlider.Limits = [1 size(app.im1,1)];
            app.LBYSlider.MajorTicks = [1:ceil(size(app.im1,1)/10):size(app.im1,1)];
            app.LBYSlider.Value=1;
            app.UBYSlider.Limits = [1 size(app.im1,1)];
            app.UBYSlider.MajorTicks = [1:ceil(size(app.im1,1)/10):size(app.im1,1)];
            app.UBYSlider.Value=size(app.im1,1);
            app.LBX=uint16(1);
            app.UBX=uint16(size(app.im1,2));
            app.LBY=uint16(1);
            app.UBY=uint16(size(app.im1,1));
            app.crop1=app.im1;
            app.crop2=app.im2;
            app.TempAlign=0;
            app.imShape=squeeze(max(app.crop1,[],3));
            app.bw1=[];
            app.thresh1=0;
            app.LBcellArea=0.1;
            app.bw2=ones(size(app.im1,1),size(app.im1,2));
            app.timeDelay=0;
            app.tauLimit=10;
            app.filtering='none';
            app.MoveAverage=20;
            app.fitRadius=5;
            app.TauFitLimit=3;
            app.threshVector=2;
            app.ROIsize=16;
            app.ROIshift=4;
            app.TOIsize=10;
            app.TOIshift=5;
            app.ChSTICS=1;
            app.beadsChID=1;
            app.E=1670;             % Young modulus in Pa
            app.nu=0.5;             % Poisson ratio
            app.alphadef=1e-19;     % coef of regularization should vary with the young modulus
            %initial window size
            app.window=128 ;
            %number of iterations
            app.iter=0;             % after each interation the window size is divided by 2
            app.overlapPIV=64;      % in pixels (applied to first window)               %32  %64
            app.overlapTrack=32;    % in pixels (applied to last window befor tracking)  %16  %32
            app.interval=8;
            app.pas=app.interval*app.pix;
            %Tracking parameters
            app.featsize=6;         % minimal particle size in pixel
            app.barrg=15;           % maximal particle size in pixel ?
            app.barcc=0.3;          % eccentricity (0 = round 1 = not round at all)
            app.IdivRg=0;         % minimal ratio intensity/radius
            app.masscut=3000;     % minimal intensity: the higher, the fewer particles are detected
            app.maxd=5;             % maximal displacement of one particle between the 2 images %5
            app.r_neighbor = 0;     % minimum distance to the nearest neighbor in pixel
            app.corr_th = 0.5;
            app.goodenough=2;
            app.memory=0;
            app.wsmoothing=2;
            app.CtoCdist=50;
            app.MinInt=3000;
            app.SzFeat=10;
            app.axisTitle=['Velocity Map STICCS;TOIsize' num2str(app.TOIsize) 'TOIShift' num2str(app.TOIshift)];
            if length(app.file)>2
                app.outputName=[app.file(1:end-4) '_STICS'];
            else
                app.outputName=[app.file{1}(1:end-4) '_STICCS'];
            end
            app.OutputFileNameEditField.Value=app.outputName;
            mkdir([app.path 'STICCSOutputs'])
            app.path2=[app.path 'STICCSOutputs/'];
            app.OuputDirectoryEditField.Value=app.path2;
            app.exportimages='y';
            app.imagesformat='png';
            app.movieformat='avi';
            app.shownow=1;
            % compim
            if ~isempty(app.im2)
                clear compim
                compim(:,:,1)=imadjust(normalisation(app.im1(:,:,app.sliderFrame)));
                compim(:,:,2)=imadjust(normalisation(app.im2(:,:,app.sliderFrame)));
                compim(:,:,3)=imadjust(normalisation(app.im1(:,:,app.sliderFrame)+app.im2(:,:,app.sliderFrame)));
            else
                compim=app.im1;
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,compim);
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.im1,2)])
            ylim(app.UIAxes,[1 size(app.im1,1)])
            set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            app.showNow=1;
            
        end

        % Button pushed function: LoadUnstressedBeadsOMEImageButton
        function LoadUnstressedBeadsOMEImageButtonPushed(app, event)
            [app.fileunstressed,app.path,indx] = uigetfile([app.path '*.tif;*.czi;*.nd2;*lif;*oir;*.lsm'],'Please select the unstressed beads file to import data');
            extension=app.fileunstressed(end-2:end);
            if strcmp(extension,'tif')
                app.nonstressed=rd_img16([app.path app.fileunstressed]);
            elseif strcmp(extension,'czi') || strcmp(extension,'nd2') || strcmp(extension,'lsm') || strcmp(extension,'lif') || strcmp(extension,'oir')
                [app.nonstressed,specs]=readOMEAlternative([app.path app.fileunstressed]);
            end
            %size(app.nonstressed)
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,app.nonstressed(:,:,app.beadsChID));
            app.UIAxes.YDir='reverse';
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            xlim(app.UIAxes,[1 size(app.nonstressed,2)])
            ylim(app.UIAxes,[1 size(app.nonstressed,1)])
            set(app.UIAxes,'CLim',[min(app.nonstressed(:)) max(app.nonstressed(:))])
        end

        % Value changed function: BeadsChIDEditField
        function BeadsChIDEditFieldValueChanged(app, event)
            value = app.BeadsChIDEditField.Value;
            app.beadsChID=value;
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sliderFrame=ceil(changingValue);
            if app.showNow==1
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                % compim
                if ~isempty(app.im2)
                    clear compim
                    compim(:,:,1)=imadjust(normalisation(app.im1(:,:,app.sliderFrame)));
                    compim(:,:,2)=imadjust(normalisation(app.im2(:,:,app.sliderFrame)));
                    compim(:,:,3)=imadjust(normalisation(app.im1(:,:,app.sliderFrame)+app.im2(:,:,app.sliderFrame)));
                else
                    compim=app.im1;
                end
                cla(app.UIAxes)
                colorbar(app.UIAxes,'off')
                imagesc(app.UIAxes,compim);
                app.UIAxes.YDir='reverse';
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                xlim(app.UIAxes,[1 size(app.im1,2)])
                ylim(app.UIAxes,[1 size(app.im1,1)])
                set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            elseif app.showNow==2
                cla(app.UIAxes)
                app.UIAxes.YDir='reverse';
                title(app.UIAxes,'')
                xlabel(app.UIAxes,'')
                ylabel(app.UIAxes,'')
                app.UIAxes.XTick = '';
                app.UIAxes.YTick = '';
                % compim
                if ~isempty(app.im2)
                    app.crop2=[];
                    app.crop2=squeeze(app.im2(app.LBY:app.UBY,app.LBX:app.UBX,:));
                    clear compim
                    compim(:,:,1)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)));
                    compim(:,:,2)=imadjust(normalisation(app.crop2(:,:,app.sliderFrame)));
                    compim(:,:,3)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)+app.crop2(:,:,app.sliderFrame)));
                else
                    compim=app.crop1;
                end
                cla(app.UIAxes)
                colorbar(app.UIAxes,'off')
                imagesc(app.UIAxes,compim);
                xlim(app.UIAxes,[1 size(app.crop1,2)])
                ylim(app.UIAxes,[1 size(app.crop1,1)])
                set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
                hold(app.UIAxes,'on')
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-200,['LBX=' num2str(app.LBX)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-150,['UBX=' num2str(size(app.im1,2)-app.UBX)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-100,['LBY=' num2str(app.LBY)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-50,['UBY=' num2str(size(app.im1,1)-app.UBY)],'Color',[1 1 1])
            elseif app.showNow==3
                fixed=app.nonstressed(app.LBY:app.UBY,app.LBX:app.UBX,app.beadsChID);
               if app.beadsChID==1
                aligndata=app.crop1;
               elseif app.beadsChID==2
                aligndata=app.crop2;
               end
            out=pkfnd(fixed,app.MinInt,app.SzFeat);
            out2=pkfnd(aligndata(:,:,app.sliderFrame),app.MinInt,app.SzFeat);
            clear compim
                compim(:,:,1)=imadjust(normalisation(fixed));
                compim(:,:,2)=imadjust(normalisation(aligndata(:,:,app.sliderFrame)));
                compim(:,:,3)=NaN;
                cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,compim);
            xlim(app.UIAxes,[1 size(app.crop1,2)])
            ylim(app.UIAxes,[1 size(app.crop1,1)])
            set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            hold(app.UIAxes,'on')
            plot(app.UIAxes,out(:,1),out(:,2),'g*')
            plot(app.UIAxes,out2(:,1),out2(:,2),'r*')
            elseif app.showNow==4
                fixed=app.nonstressed(app.LBY:app.UBY,app.LBX:app.UBX,app.beadsChID);
               if app.beadsChID==1
                aligndata=app.crop1;
               elseif app.beadsChID==2
                aligndata=app.crop2;
               end
            clear compim
                compim(:,:,1)=imadjust(normalisation(fixed));
                compim(:,:,2)=imadjust(normalisation(aligndata(:,:,app.sliderFrame)));
                compim(:,:,3)=NaN;
                cla(app.UIAxes)
                colorbar(app.UIAxes,'off')
                imagesc(app.UIAxes,compim);
                xlim(app.UIAxes,[1 size(app.crop1,2)])
                ylim(app.UIAxes,[1 size(app.crop1,1)])
                set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            end
        end

        % Value changing function: LBXSlider
        function LBXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBX=uint16(round(changingValue));
            app.crop1=[];
            app.crop1=squeeze(app.im1(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            % compim
            if ~isempty(app.im2)
                app.crop2=[];
                app.crop2=squeeze(app.im2(app.LBY:app.UBY,app.LBX:app.UBX,:));
                clear compim
                compim(:,:,1)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)));
                compim(:,:,2)=imadjust(normalisation(app.crop2(:,:,app.sliderFrame)));
                compim(:,:,3)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)+app.crop2(:,:,app.sliderFrame)));
            else
                compim=app.crop1;
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,compim);
            xlim(app.UIAxes,[1 size(app.crop1,2)])
            ylim(app.UIAxes,[1 size(app.crop1,1)])
            set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            hold(app.UIAxes,'on')
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-200,['LBX=' num2str(app.LBX)],'Color',[1 1 1])
           text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-150,['UBX=' num2str(size(app.im1,2)-app.UBX)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-100,['LBY=' num2str(app.LBY)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-50,['UBY=' num2str(size(app.im1,1)-app.UBY)],'Color',[1 1 1])
            app.showNow=2;
        end

        % Value changing function: UBXSlider
        function UBXSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UBX=uint16(round(changingValue));
            app.crop1=[];
            app.crop1=squeeze(app.im1(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            % compim
            if ~isempty(app.im2)
                app.crop2=[];
                app.crop2=squeeze(app.im2(app.LBY:app.UBY,app.LBX:app.UBX,:));
                clear compim
                compim(:,:,1)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)));
                compim(:,:,2)=imadjust(normalisation(app.crop2(:,:,app.sliderFrame)));
                compim(:,:,3)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)+app.crop2(:,:,app.sliderFrame)));
            else
                compim=app.crop1;
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,compim);
            xlim(app.UIAxes,[1 size(app.crop1,2)])
            ylim(app.UIAxes,[1 size(app.crop1,1)])
            set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            hold(app.UIAxes,'on')
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-200,['LBX=' num2str(app.LBX)],'Color',[1 1 1])
          text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-150,['UBX=' num2str(size(app.im1,2)-app.UBX)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-100,['LBY=' num2str(app.LBY)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-50,['UBY=' num2str(size(app.im1,1)-app.UBY)],'Color',[1 1 1])
            app.showNow=2;
        end

        % Value changing function: LBYSlider
        function LBYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBY=uint16(round(changingValue));
            app.crop1=[];
            app.crop1=squeeze(app.im1(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            % compim
            if ~isempty(app.im2)
                app.crop2=[];
                app.crop2=squeeze(app.im2(app.LBY:app.UBY,app.LBX:app.UBX,:));
                clear compim
                compim(:,:,1)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)));
                compim(:,:,2)=imadjust(normalisation(app.crop2(:,:,app.sliderFrame)));
                compim(:,:,3)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)+app.crop2(:,:,app.sliderFrame)));
            else
                compim=app.crop1;
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,compim);
            xlim(app.UIAxes,[1 size(app.crop1,2)])
            ylim(app.UIAxes,[1 size(app.crop1,1)])
            set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            hold(app.UIAxes,'on')
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-200,['LBX=' num2str(app.LBX)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-150,['UBX=' num2str(size(app.im1,2)-app.UBX)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-100,['LBY=' num2str(app.LBY)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-50,['UBY=' num2str(size(app.im1,1)-app.UBY)],'Color',[1 1 1])
            app.showNow=2;
        end

        % Value changing function: UBYSlider
        function UBYSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UBY=uint16(round(changingValue));
            app.crop1=[];
            app.crop1=squeeze(app.im1(app.LBY:app.UBY,app.LBX:app.UBX,:));
            cla(app.UIAxes)
            app.UIAxes.YDir='reverse';
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            % compim
            if ~isempty(app.im2)
                app.crop2=[];
                app.crop2=squeeze(app.im2(app.LBY:app.UBY,app.LBX:app.UBX,:));
                clear compim
                compim(:,:,1)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)));
                compim(:,:,2)=imadjust(normalisation(app.crop2(:,:,app.sliderFrame)));
                compim(:,:,3)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)+app.crop2(:,:,app.sliderFrame)));
            else
                compim=app.crop1;
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,compim);
            xlim(app.UIAxes,[1 size(app.crop1,2)])
            ylim(app.UIAxes,[1 size(app.crop1,1)])
            set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            hold(app.UIAxes,'on')
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-200,['LBX=' num2str(app.LBX)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-150,['UBX=' num2str(size(app.im1,2)-app.UBX)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-100,['LBY=' num2str(app.LBY)],'Color',[1 1 1])
            text(app.UIAxes,size(app.crop1,2)-150,size(app.crop1,1)-50,['UBY=' num2str(size(app.im1,1)-app.UBY)],'Color',[1 1 1])
            app.showNow=2;
        end

        % Value changed function: LBXSlider
        function LBXSliderValueChanged(app, event)
            value = app.LBXSlider.Value;
            app.LBX=uint16(round(value));
        end

        % Value changed function: UBXSlider
        function UBXSliderValueChanged(app, event)
            value = app.UBXSlider.Value;
            app.UBX=uint16(round(value));
        end

        % Value changed function: LBYSlider
        function LBYSliderValueChanged(app, event)
            value = app.LBYSlider.Value;
            app.LBY=uint16(round(value));
        end

        % Value changed function: UBYSlider
        function UBYSliderValueChanged(app, event)
            value = app.UBYSlider.Value;
            app.UBY=uint16(round(value));
        end

        % Button pushed function: FindBeadsButton
        function FindBeadsButtonPushed(app, event)
            fixed=app.nonstressed(app.LBY:app.UBY,app.LBX:app.UBX,app.beadsChID);
            if app.beadsChID==1
                aligndata=app.crop1;
            elseif app.beadsChID==2
                aligndata=app.crop2;
            end
            out=pkfnd(fixed,app.MinInt,app.SzFeat);
            out2=pkfnd(aligndata(:,:,app.sliderFrame),app.MinInt,app.SzFeat);
            clear compim
                compim(:,:,1)=imadjust(normalisation(fixed));
                compim(:,:,2)=imadjust(normalisation(aligndata(:,:,app.sliderFrame)));
                compim(:,:,3)=NaN;
                cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,compim);
            xlim(app.UIAxes,[1 size(app.crop1,2)])
            ylim(app.UIAxes,[1 size(app.crop1,1)])
            set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
            hold(app.UIAxes,'on')
            plot(app.UIAxes,out(:,1),out(:,2),'g*')
            plot(app.UIAxes,out2(:,1),out2(:,2),'r*')
            app.showNow=3;
        end

        % Value changed function: AligntoUnstressedBeadsButton
        function AligntoUnstressedBeadsButtonValueChanged(app, event)
            value = app.AligntoUnstressedBeadsButton.Value;
            app.TempAlign=value;
            if app.TempAlign==1
                %    app.images2=[];
                scale=0.1;
                [optimizer, metric] = imregconfig('multimodal');
                optimizer.InitialRadius = 0.009;
                optimizer.Epsilon = 1.5e-4;
                optimizer.GrowthFactor = 1.01;
                optimizer.MaximumIterations = 300;
                if app.beadsChID==1
                aligndata=app.crop1;
                elseif app.beadsChID==2
                aligndata=app.crop2;
                end
                fixed=app.nonstressed(app.LBY:app.UBY,app.LBX:app.UBX,app.beadsChID);
                %[fixed,h2]=smoothSeries(fixed,10,'g','p');

                
                [alignedSeries,displaceX,displaceY]=AlignBeadsImagesToReferenceImageGUI(app.UIAxes2,fixed,aligndata,app.bw2,app.CtoCdist,app.MinInt,app.SzFeat);
                cla(app.UIAxes2)
                ylim(app.UIAxes2,[0,1])
                xlim(app.UIAxes2,[0,1])
                ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
                th = text(app.UIAxes2,1,1,'Aligning Both Channels To Unstressed Beads...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
                for i=1:size(app.crop1,3)
                    trans=[-displaceX(i) -displaceY(i)];
                    tforms{i} = rigid2d([1 0 ; 0 1],trans);
                    Rmoving1 = imref2d(size(squeeze(app.crop1(:,:,i))),1,1);
                    RegisteredVolume = imwarp(squeeze(app.crop1(:,:,i)),Rmoving1,tforms{i},'bicubic','OutputView',Rmoving1);
                    app.crop1(:,:,i)=RegisteredVolume;
                    if ~isempty(app.im2)
                        Rmoving1 = imref2d(size(squeeze(app.crop2(:,:,i))),1,1);
                        RegisteredVolume = imwarp(squeeze(app.crop2(:,:,i)),Rmoving1,tforms{i},'bicubic','OutputView',Rmoving1);
                        app.crop2(:,:,i)=RegisteredVolume;
                    end
                    ph.XData = [0 i/size(app.crop1,3)  i/size(app.crop1,3) 0];
                    th.String = sprintf('Aligning Both Channels To Unstressed Beads...%.0f%%',round(i/size(app.crop1,3)*100));
                    drawnow %update graphics
                end
                
                if app.beadsChID==1
                 aligndata=app.crop1;
                elseif app.beadsChID==2
                 aligndata=app.crop2;
                end
                clear compim
                compim(:,:,1)=imadjust(normalisation(fixed));
                compim(:,:,2)=imadjust(normalisation(app.crop1(:,:,app.sliderFrame)));
                compim(:,:,3)=NaN;
                cla(app.UIAxes)
                colorbar(app.UIAxes,'off')
                imagesc(app.UIAxes,compim);
                xlim(app.UIAxes,[1 size(app.crop1,2)])
                ylim(app.UIAxes,[1 size(app.crop1,1)])
                set(app.UIAxes,'CLim',[min(compim(:)) max(compim(:))])
                app.showNow=4;


            end %end if

        end

        % Value changed function: ImageforoverallcellshapeDropDown
        function ImageforoverallcellshapeDropDownValueChanged(app, event)
            value = app.ImageforoverallcellshapeDropDown.Value;
            app.choice1=value;
            if strcmp(value,'max. int proj. Ch1')
                app.imShape=squeeze(max(app.crop1,[],3));
            elseif strcmp(value,'<I(Ch1)>')
                app.imShape=squeeze(mean(app.crop1,3));
            elseif strcmp(value,'max. int proj. Ch2')
                app.imShape=squeeze(max(app.crop2,[],3));
            elseif strcmp(value,'<I(Ch2)>')
                app.imShape=squeeze(mean(app.crop2,3));
            elseif strcmp(value,'max. int proj. All Ch')
                app.imShape=(squeeze(max(app.crop1,[],3))+squeeze(max(app.crop2,[],3)))/2;
            elseif strcmp(value,'<I>')
                app.imShape=(squeeze(mean(app.crop1,3))+squeeze(mean(app.crop2,3)))/2;;
%             elseif strcmp(value,'Moving Average Mask')
%                 along_t = floor((size(app.crop1,3)-app.TOIsize)/app.TOIshift)+1;
%                 fracTOIshift=app.TOIsize/app.TOIshift;
%                 for k=1:along_t % loop along time
%                 % define whole FOV TOI
%                 TOIFOV1=app.crop2(:,:,floor(1+(k-1)/fracTOIshift*app.TOIsize):floor(app.TOIsize*(1+((k-1)/fracTOIshift))));
%                 % define the average FOV TOI image that will be used in the display of the vector maps
%                 app.imshape(:,:,k)=mean(TOIFOV1,3);
%                 end
            end
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,(app.imShape));
            colormap(app.UIAxes,'gray')
            xlim(app.UIAxes,[1 size(app.crop1,2)])
            ylim(app.UIAxes,[1 size(app.crop1,1)])
            set(app.UIAxes,'CLim',[min(app.imShape(:)) max(app.imShape(:))])
        end

        % Value changing function: BinariseCellShapeSlider
        function BinariseCellShapeSliderValueChanging(app, event)
            changingValue = event.Value;
            norm2=app.imShape/max(app.imShape(:));
            bw2=norm2>changingValue/10;
            % filter binary
            bw2=bwmorph(bw2,'clean');
            bw2=imfill(bw2,'holes');
            %bw2=bwmorph(bw2,'close');
            se=strel('disk',5);
            app.bw1=imclose(bw2,se);;
            normim1=uint8(255*norm2);
            B=labeloverlay(normim1,app.bw1);
            cla(app.UIAxes);
            colorbar(app.UIAxes,'off')
            imagesc(app.UIAxes,B);
            set(app.UIAxes,'CLim',[min(normim1(:)) max(normim1(:))])
        end

        % Value changed function: BinariseCellShapeSlider
        function BinariseCellShapeSliderValueChanged(app, event)
            value = app.BinariseCellShapeSlider.Value;
            app.thresh1=value/10;
        end

        % Value changing function: LBCellAreaSlider
        function LBCellAreaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBcellArea=changingValue;
            stats=regionprops(app.bw1,'Area');
            maxArea=max([stats.Area]);
            idx = find([stats.Area]>app.LBcellArea*maxArea);
            cc = bwconncomp(app.bw1);
            BW2 = ismember(labelmatrix(cc),idx);
            normim1=app.imShape/max(app.imShape(:));
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,BW2);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(BW2,2)])
            ylim(app.UIAxes,[1 size(BW2,1)])
            set(app.UIAxes,'CLim',[min(normim1(:)) max(normim1(:))])
        end

        % Value changed function: LBCellAreaSlider
        function LBCellAreaSliderValueChanged(app, event)
            value = app.LBCellAreaSlider.Value;
            app.LBcellArea=value;
        end

        % Button pushed function: GenerateOverallCellsMaskButton
        function GenerateOverallCellsMaskButtonPushed(app, event)
            stats=regionprops(app.bw1,'Area');
            maxArea=max([stats.Area]);
            idx = find([stats.Area]>app.LBcellArea*maxArea);
            cc = bwconncomp(app.bw1);
            BW2 = ismember(labelmatrix(cc),idx);
            app.bw2=BW2>0;
            app.bw2=bwmorph(app.bw2,'clean');
            app.bw2=imfill(app.bw2,'holes');
            app.bw2=bwmorph(app.bw2,'close');
            app.bw2=bwmorph(app.bw2,'clean');
            normim1=app.imShape/max(app.imShape(:));
            normim1=uint8(255*normim1);
            B=labeloverlay(normim1,BW2);
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,B)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(BW2,2)])
            ylim(app.UIAxes,[1 size(BW2,1)])
            set(app.UIAxes,'CLim',[min(normim1(:)) max(normim1(:))])
        end

        % Button pushed function: orSelectCellContourManuallyButton
        function orSelectCellContourManuallyButtonPushed(app, event)
            cla(app.UIAxes)
            colorbar(app.UIAxes,'off')
            title(app.UIAxes,'')
            xlabel(app.UIAxes,'')
            ylabel(app.UIAxes,'')
            app.UIAxes.XTick = '';
            app.UIAxes.YTick = '';
            imagesc(app.UIAxes,app.imShape)
            app.UIAxes.YDir='reverse';
            xlim(app.UIAxes,[1 size(app.imShape,2)])
            ylim(app.UIAxes,[1 size(app.imShape,1)])
            roi=images.roi.Polygon(app.UIAxes,'Color','c','StripeColor','r');
            draw(roi)
            app.bw2=createMask(roi);
            set(app.UIAxes,'CLim',[min(app.imShape(:)) max(app.imShape(:))])
        end

        % Value changed function: PixelSizemicrometersEditField
        function PixelSizemicrometersEditFieldValueChanged(app, event)
            value = app.PixelSizemicrometersEditField.Value;
            app.pixelSize=value;
        end

        % Value changed function: FrameTimesecondsEditField
        function FrameTimesecondsEditFieldValueChanged(app, event)
            value = app.FrameTimesecondsEditField.Value;
            app.timeFrame=value;
        end

        % Value changed function: TimeDelay2ChsecEditField
        function TimeDelay2ChsecEditFieldValueChanged(app, event)
            value = app.TimeDelay2ChsecEditField.Value;
            app.timeDelay=value;
        end

        % Value changed function: TauLimitframesEditField
        function TauLimitframesEditFieldValueChanged(app, event)
            value = app.TauLimitframesEditField.Value;
            app.tauLimit=value;
        end

        % Value changed function: FilteringChoiceDropDown
        function FilteringChoiceDropDownValueChanged(app, event)
            value = app.FilteringChoiceDropDown.Value;
            if strcmp(value,'none')
                app.filtering='none';
            elseif strcmp(value,'Fourier Whole')
                app.filtering='FourierWhole';
            elseif strcmp(value,'Moving Average')
                app.filtering='MovingAverage';
            elseif strcmp(value,'Butterworth IIR')
                app.filtering=butterIIR;
            end
        end

        % Value changed function: MovAveRadiusframesEditField
        function MovAveRadiusframesEditFieldValueChanged(app, event)
            value = app.MovAveRadiusframesEditField.Value;
            app.MoveAverage=value;
        end

        % Value changed function: FitRadiuspixelsEditField
        function FitRadiuspixelsEditFieldValueChanged(app, event)
            value = app.FitRadiuspixelsEditField.Value;
            app.fitRadius=value;
        end

        % Value changed function: TauFitLimitframeEditField
        function TauFitLimitframeEditFieldValueChanged(app, event)
            value = app.TauFitLimitframeEditField.Value;
            app.TauFitLimit=value;
        end

        % Value changed function: stdspeedthresholdEditField
        function stdspeedthresholdEditFieldValueChanged(app, event)
            value = app.stdspeedthresholdEditField.Value;
            app.threshVector=value;
        end

        % Value changed function: ROIsizepixelsEditField
        function ROIsizepixelsEditFieldValueChanged(app, event)
            value = app.ROIsizepixelsEditField.Value;
            app.ROIsize=value;
        end

        % Value changed function: ROIshiftpixelsEditField
        function ROIshiftpixelsEditFieldValueChanged(app, event)
            value = app.ROIshiftpixelsEditField.Value;
            app.ROIshift=value;
        end

        % Value changed function: TOIsizeframesEditField
        function TOIsizeframesEditFieldValueChanged(app, event)
            value = app.TOIsizeframesEditField.Value;
            app.TOIsize=value;
        end

        % Value changed function: TOIshiftframesEditField
        function TOIshiftframesEditFieldValueChanged(app, event)
            value = app.TOIshiftframesEditField.Value;
            app.TOIshift=value;
        end

        % Value changed function: AxisTitleEditField
        function AxisTitleEditFieldValueChanged(app, event)
            value = app.AxisTitleEditField.Value;
            app.axisTitle=value;
        end

        % Value changed function: OutputFileNameEditField
        function OutputFileNameEditFieldValueChanged(app, event)
            value = app.OutputFileNameEditField.Value;
            app.outputName=value;
        end

        % Value changed function: OuputDirectoryEditField
        function OuputDirectoryEditFieldValueChanged(app, event)
            value = app.OuputDirectoryEditField.Value;
            app.path2=value;
        end

        % Value changed function: ExportImagesEditField
        function ExportImagesEditFieldValueChanged(app, event)
            value = app.ExportImagesEditField.Value;
            app.exportimages=value;
        end

        % Value changed function: ImagesFormatpngpdfjpgEditField
        function ImagesFormatpngpdfjpgEditFieldValueChanged(app, event)
            value = app.ImagesFormatpngpdfjpgEditField.Value;
            app.imagesformat=value;
        end

        % Value changed function: MovieFormatavimp4jpegEditField
        function MovieFormatavimp4jpegEditFieldValueChanged(app, event)
            value = app.MovieFormatavimp4jpegEditField.Value;
            app.movieformat=value;
        end

        % Button pushed function: WriteAlignedandCropedImagestoTIFButton
        function WriteAlignedandCropedImagestoTIFButtonPushed(app, event)
            app.nonstressed=app.nonstressed(app.LBY:app.UBY,app.LBX:app.UBX,app.beadsChID);
            if app.beadsChID==1
                app.stressed=app.crop1;
                app.cellule=app.crop2;
            elseif app.beadsChID==2
                app.stressed=app.crop2;
                app.cellule=app.crop1;
            end
            app.mask1=uint8(255*app.bw2);
            norm1=app.nonstressed/max(app.nonstressed(:));
            app.nonstressed=uint16(65535*norm1);
            app.nonstressed=imadjust(app.nonstressed,[0 10000/65535],[0 1]);
            norm1=app.stressed/max(app.stressed(:));
            app.stressed=uint16(65535*norm1);
            app.stressed=imadjustn(app.stressed,[0 10000/65535],[0 1]);
            norm1=app.cellule/max(app.cellule(:));
            app.cellule=uint16(65535*norm1);
            app.cellule=imadjustn(app.cellule,[0 40000/65535],[0 1]);
            % write tif files
            mask1=app.mask1;
            nonstressed=app.nonstressed;
            stressed=app.stressed;
            cellule=app.cellule;
            imwrite(mask1,[app.path 'Mask.tif']);
            imwrite(nonstressed,[app.path 'Beads_ak.tif']);
            imwrite(stressed(:,:,1),[app.path 'Beads_bk.tif']);
            imwrite(cellule(:,:,1),[app.path 'CellsCh_ak.tif']);
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'Writing Modified Series to TIF files...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=2:size(stressed,3)
                imwrite(stressed(:,:,i),[app.path 'Beads_bk.tif'],'WriteMode','append');
                imwrite(cellule(:,:,i),[app.path 'CellsCh_ak.tif'],'WriteMode','append');
                h.XData = [0 i/size(stressed,3) i/size(stressed,3) 0];
                th.String = sprintf('Writing Modified Series to TIF files...%.0f%%',round(i/size(stressed,3)*100));
                drawnow %update graphics
            end
        end

        % Button pushed function: SaveParamsButton
        function SaveParamsButtonPushed(app, event)
            timeDelay=app.timeDelay;
            tauLimit=app.tauLimit;
            filtering=app.filtering;
            MoveAverage=app.MoveAverage;
            fitRadius=app.fitRadius;
            TauFitLimit=app.TauFitLimit;
            threshVector=app.threshVector;
            ROIsize=app.ROIsize;
            ROIshift=app.ROIshift;
            TOIsize=app.TOIsize;
            TOIshift=app.TOIshift;
            axisTitle=app.axisTitle;
            outputName=app.outputName;
            path2=app.path2;
            exportimages=app.exportimages;
            imagesformat=app.imagesformat;
            movieformat=app.movieformat;
            LBX=app.LBX;
            LBY=app.LBY;
            UBX=app.UBX;
            UBY=app.UBY;
            TempAlign=app.TempAlign;
            choice1=app.choice1;
            thresh1=app.thresh1;
            LBcellArea=app.LBcellArea;
            pixelSize=app.pixelSize;
            timeFrame=app.timeFrame;
            save([app.path2 'TwoColorVelocityMap' app.outputName '.mat'],'pixelSize','timeFrame','TempAlign','choice1','thresh1','LBcellArea','timeDelay','tauLimit','filtering','MoveAverage','fitRadius','TauFitLimit','threshVector','ROIsize','ROIshift','TOIsize','TOIshift','axisTitle','outputName','path2','exportimages','imagesformat','movieformat','LBX','LBX','UBX','UBY','-append');
        end

        % Button pushed function: LoadParamsButton
        function LoadParamsButtonPushed(app, event)
            filterspec = {'*.mat','Select Parameter file to load'};
            [f, p] = uigetfile(filterspec);
            load([p f]);
            app.TimeDelay2ChsecEditField.Value=timeDelay;
            app.timeDelay=timeDelay;
            app.TauLimitframesEditField.Value=tauLimit;
            app.tauLimit=tauLimit;
            if strcmp(filtering,'none')
                app.FilteringChoiceDropDown.Value='none';
            elseif strcmp(filtering,'FourierWhole')
                app.FilteringChoiceDropDown.Value='Fourier Whole';
            elseif strcmp(filtering,'MovingAverage')
                app.FilteringChoiceDropDown.Value='Moving Average';
            elseif strcmp(filtering,'butterIIR')
                app.FilteringChoiceDropDown.Value='Butterworth IIR';
            end
            app.filtering=filtering;
            app.MovAveRadiusframesEditField.Value=MoveAverage;
            app.MoveAverage=MoveAverage;
            app.FitRadiuspixelsEditField.Value=fitRadius;
            app.fitRadius=fitRadius;
            app.TauFitLimitframeEditField.Value=TauFitLimit;
            app.TauFitLimit=TauFitLimit;
            app.stdspeedthresholdEditField.Value=threshVector;
            app.threshVector=threshVector;
            app.ROIsizepixelsEditField.Value=ROIsize;
            app.ROIsize=ROIsize;
            app.ROIshiftpixelsEditField.Value=ROIshift;
            app.ROIshift=ROIshift;
            app.TOIsizeframesEditField.Value=TOIsize;
            app.TOIsize=TOIsize;
            app.TOIshiftframesEditField.Value=TOIshift;
            app.TOIshift=TOIshift;
            app.AxisTitleEditField.Value=axisTitle;
            app.axisTitle=axisTitle;
            app.OutputFileNameEditField.Value=outputName;
            app.outputName=outputName;
            app.OuputDirectoryEditField.Value=path2;
            app.path2=path2;
            app.ExportImagesEditField.Valu=exportimages;
            app.exportimages=exportimages;
            app.ImagesFormatpngpdfjpgEditField.Value=imagesformat;
            app.imagesformat=imagesformat;
            app.MovieFormatavimp4jpegEditField.Value=movieformat;
            app.movieformat=movieformat;
            app.LBXSlider.Value=LBX;
            app.LBX=LBX;
            app.LBYSlider.Value=LBY;
            app.LBY=LBY;
            app.UBXSlider.Value=UBX;
            app.UBX=UBX;
            app.UBYSlider.Value=UBY;
            app.UBY=UBY;
            app.AligntoUnstressedBeadsButton.Value=TempAlign;
            app.TempAlign=TempAlign;
            app.choice=choice1;
            app.ImageforoverallcellshapeDropDown.Value=choice1;
            app.BinariseCellShapeSlider.Value=thresh1*10;
            thresh1=app.thresh1;
            app.LBCellAreaSlider.Value=LBcellArea;
            app.LBcellArea=LBcellArea;
            app.PixelSizemicrometersEditField.Value=pixelSize;
            app.pixelSize=pixelSize;
            app.FrameTimesecondsEditField.Value=timeFrame;
            app.timeFrame=timeFrame;
            app.TauFitLimit=TauFitLimit;
            app.TauFitLimitframeEditField=TauFitLimit;
        end

        % Value changed function: ChaneltowhichapplySTICSEditField
        function ChaneltowhichapplySTICSEditFieldValueChanged(app, event)
            value = app.ChaneltowhichapplySTICSEditField.Value;
            app.ChSTICS=uint8(value);
        end

        % Button pushed function: RunSTICSButton
        function RunSTICSButtonPushed(app, event)
            if ~isempty(app.crop2)
                if app.ChSTICS==1
                    datSTICS=app.crop1;
                elseif app.ChSTICS==2
                    datSTICS=app.crop2;
                end
            else
                datSTICS=app.crop1;
            end
            
            % define # of ROIs and TOIs
            along_y = floor((size(datSTICS,2)-app.ROIsize)/app.ROIshift)+1;
            along_x = floor((size(datSTICS,1)-app.ROIsize)/app.ROIshift)+1;
            along_t = floor((size(datSTICS,3)-app.TOIsize)/app.TOIshift)+1;
            
            % shift of ROI's amount
            fracROIshift=app.ROIsize/app.ROIshift;
            fracTOIshift=app.TOIsize/app.TOIshift;
            position_x  =   zeros(1,along_x);
            position_y  =   zeros(1,along_y);
            position_t  =   zeros(1,along_t);
            
            % defining all of the ROI centers positons in x and y
            for i=1:along_x
                position_x(i) = 1/2 + (i-1)/fracROIshift*app.ROIsize +  app.ROIsize/2;
            end
            position_x=repmat(position_x,along_y,1);
            position_x=position_x';
            for j=1:along_y
                position_y(j) = 1/2 + (j-1)/fracROIshift*app.ROIsize + app.ROIsize/2;
            end
            position_y=repmat(position_y,along_x,1);
            %define the ROI position according to binary mask selected...
            postouse=zeros(along_x,along_y);
            for w=1:along_x
                for s=1:along_y
                    indx=1+(w-1)/fracROIshift*app.ROIsize:(app.ROIsize*(1+((w-1)/fracROIshift)));
                    indy=1+(s-1)/fracROIshift*app.ROIsize:(app.ROIsize*(1+((s-1)/fracROIshift)));
                    indx = floor(indx);
                    indy = floor(indy);
                    if(app.bw2(indx,indy)==1)
                        postouse(w,s)=1;
                    end
                end
            end
            app.postouse=postouse;
            %immobile filter data
            if strcmp(app.filtering,'FourierWhole')
                datSTICS= immfilterGUI(datSTICS,'F','Fourier Filtering Ch1',app.UIAxes2);
            elseif strcmp(app.filtering,'MovingAverage')
                datSTICS= immfilterGUI(datSTICS,app.MoveAverage,'Movinag Average Filtering Ch1',app.UIAxes2);
            elseif strcmp(app.filtering,'butterIIR')
                %set the cutoff frequency to be the 2*sampling frequency / size of the series
                % this way we will certainly removed the immobile component
                datSTICS= butterIIR(datSTICS, 2/(app.timeFrame*size(datSTICS,3)),'none');
            end
            %% broadcast variables
            timeDelay=(app.timeDelay);
            tauLimit=uint16(app.tauLimit);
            ROIsize=uint16(app.ROIsize);
            ROIshift=uint16(app.ROIshift);
            TOIsize=uint16(app.TOIsize);
            TOIshift=uint16(app.TOIshift);
            pixelSize=app.pixelSize;
            timeFrame=app.timeFrame;
            fitRadius=uint16(app.fitRadius);
            ChSTICS=uint16(app.ChSTICS);
            TauFitLimit=uint16(app.TauFitLimit);
            % %indexes of ROI within the mask of interest (polygon)
            [iroi jroi]=find(postouse);
            delete(gcp('nocreate'))
            parpool
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,['Running STICS Analysis on Ch' num2str(ChSTICS) '...0%'],'VerticalAlignment','bottom','HorizontalAlignment','right');
            for k=1:along_t % loop along time
                % position of TOI centers in time
                position_t(k) = 1/2 + (k-1)/fracTOIshift*app.TOIsize + app.TOIsize/2;
                % define whole FOV TOI
                TOIFOV1=datSTICS(:,:,floor(1+(k-1)/fracTOIshift*app.TOIsize):floor(app.TOIsize*(1+((k-1)/fracTOIshift))));
                % define the average FOV TOI image that will be used in the display of the vector maps
                velocityMap{k}.data_raw=mean(TOIFOV1,3);
                
                clear vx1 vy1
                parfor u=1:length(iroi) %accessing the data of only
                    %the ROI that are defined within the selected polygon
                    i=iroi(u);
                    j=jroi(u);
                    %define regions (x,y,t) on which sticcs will be applied
                    regionanalyse1=TOIFOV1((1+(i-1)/fracROIshift*ROIsize):(ROIsize*(1+((i-1)/fracROIshift))),(1+(j-1)/fracROIshift*ROIsize):(ROIsize*(1+((j-1)/fracROIshift))),:);
                    %apply regular sticcs
                    %[corrfn12 corrfn21 corrfn1 corrfn2]=stics(regionanalyse1,regionanalyse2,app.tauLimit);
                    [corrfn1] = stics_byfft(regionanalyse1,mean(regionanalyse1(:)),tauLimit);
                    
                    try
                        %do plain symmetric gaussian fit to data
                        [coeffGtime1] = gaussfit(corrfn1,'time',pixelSize,'n',fitRadius);
                        % Discards some lags of the correlation functions if they have
                        % fitted omegas larger than a threshold
                        %cutOff1 =max(1,min([find(coeffGtime1(:,2)>omegaThreshold,1,'first') find(coeffGtime1(:,3)>omegaThreshold,1,'first') ]));
                        
                        % see where it got cut off
                        
                        fit1 = polyfit((timeFrame+timeDelay)*double(0:TauFitLimit-1),squeeze(coeffGtime1(1:TauFitLimit,5))',1);
                        vx1(u)=fit1(1);
                        fit2 = polyfit((timeFrame+timeDelay)*double(0:TauFitLimit-1),squeeze(coeffGtime1(1:TauFitLimit,6))',1);
                        vy1(u)=fit2(1);
                    catch
                        vx1(u)=NaN;
                        vy1(u)=NaN;
                    end
                    
                end % end 'u' loop
                
                velocityMap{k}.vx=vx1;
                velocityMap{k}.vy=vy1;
                
                % % % remove bad vectors and throw out vectors whose magnitude is greater than 'app.threshVector'
                % std devs away from the mean of the neigbouring vectors
                
                posx=squeeze(position_x);
                posx=posx(1:size(posx,1)*size(posx,2));
                posy=squeeze(position_y);
                posy=posy(1:size(posy,1)*size(posy,2));
                vx=nan(size(position_x));
                vy=nan(size(position_y));
                ind=sub2ind(size(postouse),iroi,jroi);
                vx(ind)=vx1;
                vy(ind)=vy1;
                %                                     for u=1:length(iroi)
                %                                         i=iroi(u);
                %                                         j=jroi(u);
                %                                         vy(i,j)=squeeze(velocityMap{m,k}.vy(u));
                %                                         vx(i,j)=squeeze(velocityMap{m,k}.vx(u));
                %                                     end
                velocityMap{k}.Vx=vx;
                velocityMap{k}.Vy=vy;
                vy=vy(1:size(vy,1)*size(vy,2));
                vx=vx(1:size(vx,1)*size(vx,2));
                goodVectorsx = vectorOutlier(posx',posy',vx,app.ROIshift,app.threshVector);
                goodVectorsy = vectorOutlier(posx',posy',vy,app.ROIshift,app.threshVector);
                velocityMap{k}.goodVectors = (goodVectorsx & goodVectorsy)' & (~isnan(vx) & ~isnan(vy)); clear goodVectorsx goodVectorsy
                
                
                ph.XData = [0 k/along_t  k/along_t 0];
                th.String = sprintf(['Running STICS Analysis on Ch' num2str(ChSTICS) '...%.0f%%'],round(k/along_t*100));
                drawnow %update graphics
                
                
            end %end 'k' loop
            
            % output data to file
            save([app.path2 'VelocityMap' app.outputName '.mat'],'velocityMap');
            save([app.path2 'VelocityMap' app.outputName '.mat'],'position_x','-append');
            save([app.path2 'VelocityMap' app.outputName '.mat'],'position_y','-append');
            save([app.path2 'VelocityMap' app.outputName '.mat'],'position_t','-append');
            save([app.path2 'VelocityMap' app.outputName '.mat'],'postouse','-append');
            
            timeDelay=app.timeDelay;
            tauLimit=app.tauLimit;
            filtering=app.filtering;
            MoveAverage=app.MoveAverage;
            fitRadius=app.fitRadius;
            threshVector=app.threshVector;
            ROIsize=app.ROIsize;
            ROIshift=app.ROIshift;
            TOIsize=app.TOIsize;
            TOIshift=app.TOIshift;
            axisTitle=app.axisTitle;
            outputName=app.outputName;
            path2=app.path2;
            exportimages=app.exportimages;
            imagesformat=app.imagesformat;
            movieformat=app.movieformat;
            bw2=app.bw2;
            LBX=app.LBX;
            LBY=app.LBY;
            UBX=app.UBX;
            UBY=app.UBY;
            pixelSize=app.pixelSize;
            timeFrame=app.timeFrame;
            save([app.path2 'VelocityMap' app.outputName '.mat'],'pixelSize','timeFrame','timeDelay','tauLimit','filtering','MoveAverage','fitRadius','threshVector','ROIsize','ROIshift','TOIsize','TOIshift','axisTitle','outputName','path2','exportimages','imagesformat','movieformat','bw2','LBX','LBX','UBX','UBY','-append');
        end

        % Button pushed function: RunSTICCSButton
        function RunSTICCSButtonPushed(app, event)
            warning('off')
            % define # of ROIs and TOIs
            along_y = floor((size(app.crop1,2)-app.ROIsize)/app.ROIshift)+1;
            along_x = floor((size(app.crop1,1)-app.ROIsize)/app.ROIshift)+1;
            along_t = floor((size(app.crop1,3)-app.TOIsize)/app.TOIshift)+1;
            
            % shift of ROI's amount
            fracROIshift=app.ROIsize/app.ROIshift;
            fracTOIshift=app.TOIsize/app.TOIshift;
            position_x  =   zeros(1,along_x);
            position_y  =   zeros(1,along_y);
            position_t  =   zeros(1,along_t);
            
            % defining all of the ROI centers positons in x and y
            for i=1:along_x
                position_x(i) = 1/2 + (i-1)/fracROIshift*app.ROIsize +  app.ROIsize/2;
            end
            position_x=repmat(position_x,along_y,1);
            position_x=position_x';
            for j=1:along_y
                position_y(j) = 1/2 + (j-1)/fracROIshift*app.ROIsize + app.ROIsize/2;
            end
            position_y=repmat(position_y,along_x,1);
            %define the ROI position according to binary mask selected...
            postouse=zeros(along_x,along_y);
            for w=1:along_x
                for s=1:along_y
                    indx=1+(w-1)/fracROIshift*app.ROIsize:(app.ROIsize*(1+((w-1)/fracROIshift)));
                    indy=1+(s-1)/fracROIshift*app.ROIsize:(app.ROIsize*(1+((s-1)/fracROIshift)));
                    indx = floor(indx);
                    indy = floor(indy);
                    if(app.bw2(indx,indy)==1)
                        postouse(w,s)=1;
                    end
                end
            end
            app.postouse=postouse;
            %immobile filter data
            if strcmp(app.filtering,'FourierWhole')
                app.crop1= immfilterGUI(app.crop1,'F','Fourier Filtering Ch1',app.UIAxes2);
                app.crop2= immfilterGUI(app.crop2,'F','Fourier Filtering Ch2',app.UIAxes2);
            elseif strcmp(app.filtering,'MovingAverage')
                app.crop1= immfilterGUI(app.crop1,app.MoveAverage,'Movinag Average Filtering Ch1',app.UIAxes2);
                app.crop2= immfilterGUI(app.crop2,app.MoveAverage,'Movinag Average Filtering Ch2',app.UIAxes2);
            elseif strcmp(app.filtering,'butterIIR')
                %set the cutoff frequency to be the 2*sampling frequency / size of the series
                % this way we will certainly removed the immobile component
                app.crop1= butterIIR(app.crop1, 2/(app.timeFrame*size(app.crop1,3)),'none');
                app.crop2= butterIIR(app.crop2, 2/(app.timeFrame*size(app.crop2,3)),'none');
            end
            %% broadcast variables
            timeDelay=app.timeDelay;
            tauLimit=uint16(app.tauLimit);
            ROIsize=uint16(app.ROIsize);
            ROIshift=uint16(app.ROIshift);
            TOIsize=uint16(app.TOIsize);
            TOIshift=uint16(app.TOIshift);
            pixelSize=app.pixelSize;
            timeFrame=app.timeFrame;
            fitRadius=uint16(app.fitRadius);
            TauFitLimit=uint16(app.TauFitLimit);
            % %indexes of ROI within the mask of interest (polygon)
            [iroi jroi]=find(postouse);
            delete(gcp('nocreate'))
            parpool
            cla(app.UIAxes2)
            ylim(app.UIAxes2,[0,1])
            xlim(app.UIAxes2,[0,1])
            ph = patch(app.UIAxes2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2,1,1,'Running STICCS Analysis...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for k=1:along_t % loop along time
                % position of TOI centers in time
                position_t(k) = 1/2 + (k-1)/fracTOIshift*app.TOIsize + app.TOIsize/2;
                % define whole FOV TOI
                TOIFOV1=app.crop1(:,:,floor(1+(k-1)/fracTOIshift*app.TOIsize):floor(app.TOIsize*(1+((k-1)/fracTOIshift))));
                TOIFOV2=app.crop2(:,:,floor(1+(k-1)/fracTOIshift*app.TOIsize):floor(app.TOIsize*(1+((k-1)/fracTOIshift))));
                % define the average FOV TOI image that will be used in the display of the vector maps
                velocityMap{1,k}.data_raw=mean(TOIFOV1,3);
                velocityMap{2,k}.data_raw=mean(TOIFOV2,3);
                
                clear vx1 vy1 vx2 vy2 vx12 vy12 vx21 vy21
                parfor u=1:length(iroi) %accessing the data of only
                    %the ROI that are defined within the selected polygon
                    i=iroi(u);
                    j=jroi(u);
                    %define regions (x,y,t) on which sticcs will be applied
                    regionanalyse1=TOIFOV1((1+(i-1)/fracROIshift*ROIsize):(ROIsize*(1+((i-1)/fracROIshift))),(1+(j-1)/fracROIshift*ROIsize):(ROIsize*(1+((j-1)/fracROIshift))),:);
                    regionanalyse2=TOIFOV2((1+(i-1)/fracROIshift*ROIsize):(ROIsize*(1+((i-1)/fracROIshift))),(1+(j-1)/fracROIshift*ROIsize):(ROIsize*(1+((j-1)/fracROIshift))),:);
                    
                    %apply regular sticcs
                    % [corrfn12 corrfn21 corrfn1 corrfn2]=stics(regionanalyse1,regionanalyse2,tauLimit);
                    [timecorrnorm] = sticcs_byfft(regionanalyse1,regionanalyse2,mean(regionanalyse1(:)),mean(regionanalyse2(:)),tauLimit);
                    corrfn1=timecorrnorm(:,:,:,1);
                    corrfn2=timecorrnorm(:,:,:,2);
                    corrfn12=timecorrnorm(:,:,:,3);
                    corrfn21=timecorrnorm(:,:,:,4);
                    %size(timecorrnorm)
                    try
                        %do plain symmetric gaussian fit to data
                        [coeffGtime1] = gaussfit(corrfn1,'time',pixelSize,'n',fitRadius);
                        % Discards some lags of the correlation functions if they have
                        % fitted omegas larger than a threshold
                        %cutOff1 =max(1,min([find(coeffGtime1(:,2)>omegaThreshold,1,'first') find(coeffGtime1(:,3)>omegaThreshold,1,'first') ]));
                        fit1 = polyfit((timeFrame+timeDelay)*double(0:TauFitLimit-1),squeeze(coeffGtime1(1:TauFitLimit,5))',1);
                        fit2 = polyfit((timeFrame+timeDelay)*double(0:TauFitLimit-1),squeeze(coeffGtime1(1:TauFitLimit,6))',1);
                        %
                        %                         fit1 = polyfit((timeFrame+timeDelay)*(0:size(corrfn1,3)-1),squeeze(coeffGtime1(1:size(corrfn1,3),5))',1);
                        %                         fit2 = polyfit((timeFrame+timeDelay)*(0:size(corrfn1,3)-1),squeeze(coeffGtime1(1:size(corrfn1,3),6))',1);
                        vx1(u)=fit1(1);
                        vy1(u)=fit2(1);
                    catch
                        vx1(u)=NaN;
                        vy1(u)=NaN;
                        [i j]
                    end
                    % do it for channel 2
                    try
                        %do plain symmetric gaussian fit to data
                        [coeffGtime2] = gaussfit(corrfn2,'time',pixelSize,'n',fitRadius);
                        % Discards some lags of the correlation functions if they have
                        % fitted omegas larger than a threshold
                        %cutOff2 =max(1,min([find(coeffGtime2(:,2)>omegaThreshold,1,'first') find(coeffGtime2(:,3)>omegaThreshold,1,'first') ]));
                        %set beam waists above cutoff to zero so you can
                        % see where it got cut off
                        %                         fit1 = polyfit((timeFrame+timeDelay)*(0:size(corrfn2,3)-1),squeeze(coeffGtime2(1:size(corrfn2,3),5))',1);
                        %                         fit2 = polyfit((timeFrame+timeDelay)*(0:size(corrfn2,3)-1),squeeze(coeffGtime2(1:size(corrfn2,3),6))',1);
                        fit1 = polyfit((timeFrame+timeDelay)*double(0:TauFitLimit-1),squeeze(coeffGtime2(1:TauFitLimit,5))',1);
                        fit2 = polyfit((timeFrame+timeDelay)*double(0:TauFitLimit-1),squeeze(coeffGtime2(1:TauFitLimit,6))',1);
                        vx2(u)=fit1(1);
                        vy2(u)=fit2(1);
                    catch
                        vx2(u)=NaN;
                        vy2(u)=NaN;
                    end
                    % do it for cross-corr 12
                    try
                        %do plain symmetric gaussian fit to data
                        [coeffGtime12] = gaussfit(corrfn12,'time',pixelSize,'n',fitRadius);
                        % Discards some lags of the correlation functions if they have
                        % fitted omegas larger than a threshold
                        %cutOff12 =max(1,min([find(coeffGtime12(:,2)>omegaThreshold,1,'first') find(coeffGtime12(:,3)>omegaThreshold,1,'first') ]));
                        %coeffGtime12(cutOff12:end,2:3) = 0;
                        %set beam waists above cutoff to zero so you can
                        % see where it got cut off
                        %                         fit1 = polyfit(timeDelay+((timeFrame+timeDelay)*(0:size(corrfn12,3)-1)),squeeze(coeffGtime12(1:size(corrfn12,3),5))',1);
                        %                         fit2 = polyfit(timeDelay+((timeFrame+timeDelay)*(0:size(corrfn12,3)-1)),squeeze(coeffGtime12(1:size(corrfn12,3),6))',1);
                        fit1 = polyfit(timeDelay+((timeFrame+timeDelay)*double(0:TauFitLimit-1)),squeeze(coeffGtime12(1:TauFitLimit,5))',1);
                        fit2 = polyfit(timeDelay+((timeFrame+timeDelay)*double(0:TauFitLimit-1)),squeeze(coeffGtime12(1:TauFitLimit,6))',1);
                        vx12(u)=fit1(1);
                        vy12(u)=fit2(1);
                    catch
                        vx12(u)=NaN;
                        vy12(u)=NaN;
                    end
                    % do it for cross-corr 21
                    try
                        %do plain symmetric gaussian fit to data
                        [coeffGtime21] = gaussfit(corrfn21,'time',pixelSize,'n',fitRadius);
                        % Discards some lags of the correlation functions if they have
                        % fitted omegas larger than a threshold
                        % set beam waists above cutoff to zero so you can
                        % see where it got cut off
                        %cutOff21 =max(1,min([find(coeffGtime21(:,2)>omegaThreshold,1,'first') find(coeffGtime21(:,3)>omegaThreshold,1,'first') ]));
                        %                         fit1 = polyfit(timeDelay+((timeFrame+timeDelay)*(0:size(corrfn21,3)-1)),squeeze(coeffGtime21(1:size(corrfn21,3),5))',1);
                        %                         fit2 = polyfit(timeDelay+((timeFrame+timeDelay)*(0:size(corrfn21,3)-1)),squeeze(coeffGtime21(1:size(corrfn21,3),6))',1);
                        fit1 = polyfit(timeDelay+((timeFrame+timeDelay)*double(0:TauFitLimit-1)),squeeze(coeffGtime21(1:TauFitLimit,5))',1);
                        fit2 = polyfit(timeDelay+((timeFrame+timeDelay)*double(0:TauFitLimit-1)),squeeze(coeffGtime21(1:TauFitLimit,6))',1);
                        vx21(u)=fit1(1);
                        vy21(u)=fit2(1);
                    catch
                        vx21(u)=NaN;
                        vy21(u)=NaN;
                    end
                    
                end % end 'u' loop
                
                velocityMap{1,k}.vx=vx1;
                velocityMap{1,k}.vy=vy1;
                velocityMap{2,k}.vx=vx2;
                velocityMap{2,k}.vy=vy2;
                velocityMap{3,k}.vx=vx12;
                velocityMap{3,k}.vy=vy12;
                velocityMap{4,k}.vx=vx21;
                velocityMap{4,k}.vy=vy21;
                % % % remove bad vectors for each channel and
                % cross-corrs...% Throw out vectors whose magnitude is greater than 'app.threshVector'
                % std devs away from the mean of the neigbouring vectors
                ind=sub2ind(size(postouse),iroi,jroi);
                
                for m=1:4
                    clear vx vy
                    posx=squeeze(position_x);
                    posx=posx(1:size(posx,1)*size(posx,2));
                    posy=squeeze(position_y);
                    posy=posy(1:size(posy,1)*size(posy,2));
                    vx=nan(size(position_x));
                    vy=nan(size(position_y));
                    vx(ind)=squeeze(velocityMap{m,k}.vx);
                    vy(ind)=squeeze(velocityMap{m,k}.vy);
                    velocityMap{m,k}.Vx=vx;
                    velocityMap{m,k}.Vy=vy;
                    vy=vy(1:size(vy,1)*size(vy,2));
                    vx=vx(1:size(vx,1)*size(vx,2));
                    goodVectorsx = vectorOutlier(posx',posy',vx,app.ROIshift,app.threshVector);
                    goodVectorsy = vectorOutlier(posx',posy',vy,app.ROIshift,app.threshVector);
                    velocityMap{m,k}.goodVectors = (goodVectorsx & goodVectorsy)' & (~isnan(vx) & ~isnan(vy)); clear goodVectorsx goodVectorsy
                end
                
                ph.XData = [0 k/along_t  k/along_t 0];
                th.String = sprintf('Running STICCS Analysis...%.0f%%',round(k/along_t*100));
                drawnow %update graphics
                
                
            end %end 'k' loop
            
            % output data to file
            save([app.path2 'TwoColorVelocityMap' app.outputName '.mat'],'velocityMap');
            save([app.path2 'TwoColorVelocityMap' app.outputName '.mat'],'position_x','-append');
            save([app.path2 'TwoColorVelocityMap' app.outputName '.mat'],'position_y','-append');
            save([app.path2 'TwoColorVelocityMap' app.outputName '.mat'],'position_t','-append');
            save([app.path2 'TwoColorVelocityMap' app.outputName '.mat'],'postouse','-append');
            
            timeDelay=app.timeDelay;
            tauLimit=app.tauLimit;
            filtering=app.filtering;
            MoveAverage=app.MoveAverage;
            fitRadius=app.fitRadius;
            threshVector=app.threshVector;
            ROIsize=app.ROIsize;
            ROIshift=app.ROIshift;
            TOIsize=app.TOIsize;
            TOIshift=app.TOIshift;
            axisTitle=app.axisTitle;
            outputName=app.outputName;
            path2=app.path2;
            exportimages=app.exportimages;
            imagesformat=app.imagesformat;
            movieformat=app.movieformat;
            bw2=app.bw2;
            LBX=app.LBX;
            LBY=app.LBY;
            UBX=app.UBX;
            UBY=app.UBY;
            pixelSize=app.pixelSize;
            timeFrame=app.timeFrame;
            TauFitLimit=app.TauFitLimit;
            save([app.path2 'TwoColorVelocityMap' app.outputName '.mat'],'TauFitLimit','pixelSize','timeFrame','timeDelay','tauLimit','filtering','MoveAverage','fitRadius','threshVector','ROIsize','ROIshift','TOIsize','TOIshift','axisTitle','outputName','path2','exportimages','imagesformat','movieformat','bw2','LBX','LBX','UBX','UBY','-append');
        end

        % Button pushed function: LoadBeadsTIFButton
        function LoadBeadsTIFButtonPushed(app, event)
            app.pix=4.26e-8;
            app.PixelSizemEditField.Value=app.pix;
            app.E=1670;             % Young modulus in Pa
            app.YoungModulusPaEditField.Value=app.E;
            app.nu=0.5;             % Poisson ratio
            app.PoissonratioEditField.Value=app.nu;
            app.alphadef=1e-19;     % coef of regularization should vary with the young modulus
            app.CoefofRegularisationdependonYoungModulusEditField.Value=app.alphadef;
            %initial window size
            app.window=128 ;
            app.WindowSizepixelsEditField.Value=app.window;
            %number of iterations
            app.iter=0;             % after each interation the window size is divided by 2
            app.NumberofIterationsEditField.Value=app.iter;
            app.overlapPIV=64;      % in pixels (applied to first window)               %32  %64
            app.OverlapPIVpixelsEditField.Value=app.overlapPIV;
            app.overlapTrack=32;    % in pixels (applied to last window befor tracking)  %16  %32
            app.OverlapTrackpixelsEditField.Value=app.overlapTrack;
            app.interval=8;
            app.IntervalpixelsEditField.Value=app.interval;
            app.pas=app.interval*app.pix;
            app.StepSizeMultipleofPixelEditField.Value=app.pas;
            %Tracking parameters
            app.featsize=6;         % minimal particle size in pixel
            app.MinimalparticlesizepixelsEditField.Value=app.featsize;
            app.barrg=15;           % maximal particle size in pixel ?
            app.MaximumradiusofgyrationofparticlespixelsEditField.Value=app.barrg;
            app.barcc=0.3;          % eccentricity (0 = round 1 = not round at all)
            app.Particleseccentricity0roundEditField.Value=app.barcc;
            app.IdivRg=0;         % minimal ratio intensity/radius
            app.MinimalRatioIntensitytoradiusEditField.Value=app.IdivRg;
            app.masscut=350000;     % minimal intensity: the higher, the fewer particles are detected
            app.MinimalintegratedintensityEditField.Value=app.masscut;
            app.maxd=5;             % maximal displacement of one particle between the 2 images %5
            app.MaximaldisplacementbetweentwoimagespixelsEditField.Value=app.maxd;
            app.r_neighbor = 0;     % minimum distance to the nearest neighbor in pixel
            app.MinimumdistancetonearestneighbourpixelsEditField.Value=app.r_neighbor;
            app.corr_th = 0.5;
            app.CorrelationThresholdEditField.Value=app.corr_th;
            app.goodenough=2;
            app.TheminimumlengthoftrajectoryEditField.Value=app.goodenough;
            app.memory=0;
            app.MemoryframesparticlecandisappeartemporarilyEditField.Value=app.memory;
            app.wsmoothing=2;
            app.DisplacementfieldsmoothingEditField.Value=app.wsmoothing;
            [app.filebeads,app.path3,indx] = uigetfile('*.tif;*.czi;*.nd2;*lif;*oir;*.lsm','Please select beads file to import data','MultiSelect','on');
            info = imfinfo(fullfile(app.path3,app.filebeads),'tif');
            Nb_frames=numel(info);
            I=imread(fullfile(app.path3,app.filebeads),'tif');
            app.cl=class(I);
            app.stressed=zeros([size(I) Nb_frames], app.cl);
            app.stressed(:,:,1)=I;
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2_2,1,1,'Loading Beads Image Series...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for frame=2:Nb_frames
                app.stressed(:,:,frame)=imread(fullfile(app.path3,app.filebeads),frame);
                ph.XData = [0 frame/Nb_frames  frame/Nb_frames 0];
                th.String = sprintf('Loading Beads Image Series...%.0f%%',round(frame/Nb_frames*100));
                drawnow %update graphics
            end
            app.FramesSlider_2.Limits = [1 size(app.stressed,3)];
            app.FramesSlider_2.MajorTicks = [1:round(size(app.stressed,3)/10):size(app.stressed,3)];
            cla(app.UIAxes_2)
            imagesc(app.UIAxes_2,double(app.stressed(:,:,1)));
            app.UIAxes_2.YDir='reverse';
            xlabel(app.UIAxes_2,'')
            ylabel(app.UIAxes_2,'')
            xlim(app.UIAxes_2,[1 size(app.stressed,2)])
            ylim(app.UIAxes_2,[1 size(app.stressed,1)])
            %set(app.UIAxes_2,'CLim',[min(app.stressed(:)) max(app.stressed(:))])
            app.shownow=1;
        end

        % Button pushed function: LoadCellChTIFButton
        function LoadCellChTIFButtonPushed(app, event)
            [app.filecell,app.path3,indx] = uigetfile([app.path3 '*.tif;*.czi;*.nd2;*lif;*oir;*.lsm'],'Please select cell file to import data','MultiSelect','on');
            info = imfinfo(fullfile(app.path3,app.filecell),'tif');
            Nb_frames=numel(info);
            I=imread(fullfile(app.path3,app.filecell),'tif');
            cl=class(I);
            app.cellule=zeros([size(I) Nb_frames], cl);
            app.cellule(:,:,1)=I;
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2_2,1,1,'Loading Cell Image Series...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for frame=2:Nb_frames
                app.cellule(:,:,frame)=imread(fullfile(app.path3,app.filecell),frame);
                ph.XData = [0 frame/Nb_frames  frame/Nb_frames 0];
                th.String = sprintf('Loading Cell Image Series...%.0f%%',round(frame/Nb_frames*100));
                drawnow %update graphics
            end
            app.FramesSlider_2.Limits = [1 size(app.cellule,3)];
            app.FramesSlider_2.MajorTicks = [1:round(size(app.cellule,3)/10):size(app.cellule,3)];
            cla(app.UIAxes_2)
            imagesc(app.UIAxes_2,app.cellule(:,:,1));
            app.UIAxes_2.YDir='reverse';
            xlabel(app.UIAxes_2,'')
            ylabel(app.UIAxes_2,'')
            xlim(app.UIAxes_2,[1 size(app.cellule,2)])
            ylim(app.UIAxes_2,[1 size(app.cellule,1)])
            %set(app.UIAxes_2,'CLim',[min(app.cellule(:)) max(app.cellule(:))])
            app.shownow=2;
        end

        % Button pushed function: LoadMasksTIFButton
        function LoadMasksTIFButtonPushed(app, event)
            [app.filemask,app.path3,indx] = uigetfile([app.path3 '*.tif;*.czi;*.nd2;*lif;*oir;*.lsm'],'Please select mask file to import data','MultiSelect','on');
            info = imfinfo(fullfile(app.path3,app.filemask),'tif');
            Nb_frames=numel(info);
            I=imread(fullfile(app.path3,app.filemask),'tif');
            cl=class(I);
            app.mask1=zeros([size(I) Nb_frames], cl);
            app.mask1(:,:,1)=I;
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyellow
            th = text(app.UIAxes2_2,1,1,'Loading Mask Image Series...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            
            if Nb_frames==1
                app.mask1=repmat(I,[1 1 size(app.cellule,3)]);
            else
                for frame=2:Nb_frames
                    app.mask1(:,:,frame)=imread(fullfile(app.path3,app.filemask),frame);
                    ph.XData = [0 frame/Nb_frames  frame/Nb_frames 0];
                    th.String = sprintf('Loading Mask Image Series...%.0f%%',round(frame/Nb_frames*100));
                    drawnow %update graphics
                end
            end
            
            app.FramesSlider_2.Limits = [1 size(app.mask1,3)];
            app.FramesSlider_2.MajorTicks = [1:round(size(app.mask1,3)/10):size(app.mask1,3)];
            cla(app.UIAxes_2)
            imagesc(app.UIAxes_2,app.mask1(:,:,1));
            app.UIAxes_2.YDir='reverse';
            xlabel(app.UIAxes_2,'')
            ylabel(app.UIAxes_2,'')
            xlim(app.UIAxes_2,[1 size(app.mask1,2)])
            ylim(app.UIAxes_2,[1 size(app.mask1,1)])
            %set(app.UIAxes_2,'CLim',[0 1])
            app.shownow=3;
        end

        % Button pushed function: LoadAKBeadsImageButton
        function LoadAKBeadsImageButtonPushed(app, event)
            [app.fileunstressed,app.path3,indx] = uigetfile([app.path3 '*.tif;*.czi;*.nd2;*lif;*oir;*.lsm'],'Please select unstressed beads file to import','MultiSelect','on');
            app.nonstressed=imread(fullfile(app.path3,app.fileunstressed),'tif');
            cla(app.UIAxes_2)
            imagesc(app.UIAxes_2,app.nonstressed);
            app.UIAxes_2.YDir='reverse';
            xlabel(app.UIAxes_2,'')
            ylabel(app.UIAxes_2,'')
            xlim(app.UIAxes_2,[1 size(app.nonstressed,2)])
            ylim(app.UIAxes_2,[1 size(app.nonstressed,1)])
            %set(app.UIAxes_2,'CLim',[min(app.nonstressed(:)) max(app.nonstressed(:))])
        end

        % Button pushed function: UseImagesandMasksDefinedinSTICCSTabButton
        function UseImagesandMasksDefinedinSTICCSTabButtonPushed(app, event)
            if ~exist(fullfile(app.path,'figureTFM'),'dir')
                mkdir(fullfile(app.path,'figureTFM'))
            end
            app.pix=4.26e-8;
            app.PixelSizemEditField.Value=app.pix;
            app.E=1670;             % Young modulus in Pa
            app.YoungModulusPaEditField.Value=app.E;
            app.nu=0.5;             % Poisson ratio
            app.PoissonratioEditField.Value=app.nu;
            app.alphadef=1e-19;     % coef of regularization should vary with the young modulus
            app.CoefofRegularisationdependonYoungModulusEditField.Value=app.alphadef;
            %initial window size
            app.window=128 ;
            app.WindowSizepixelsEditField.Value=app.window;
            %number of iterations
            app.iter=0;             % after each interation the window size is divided by 2
            app.NumberofIterationsEditField.Value=app.iter;
            app.overlapPIV=64;      % in pixels (applied to first window)               %32  %64
            app.OverlapPIVpixelsEditField.Value=app.overlapPIV;
            app.overlapTrack=32;    % in pixels (applied to last window befor tracking)  %16  %32
            app.OverlapTrackpixelsEditField.Value=app.overlapTrack;
            app.interval=8;
            app.IntervalpixelsEditField.Value=app.interval;
            app.pas=app.interval*app.pix;
            app.StepSizeMultipleofPixelEditField.Value=app.pas;
            %Tracking parameters
            app.featsize=6;         % minimal particle size in pixel
            app.MinimalparticlesizepixelsEditField.Value=app.featsize;
            app.barrg=15;           % maximal particle size in pixel ?
            app.MaximumradiusofgyrationofparticlespixelsEditField.Value=app.barrg;
            app.barcc=0.3;          % eccentricity (0 = round 1 = not round at all)
            app.Particleseccentricity0roundEditField.Value=app.barcc;
            app.IdivRg=0;         % minimal ratio intensity/radius
            app.MinimalRatioIntensitytoradiusEditField.Value=app.IdivRg;
            app.masscut=3000;     % minimal intensity: the higher, the fewer particles are detected
            app.MinimalintegratedintensityEditField.Value=app.masscut;
            app.maxd=5;             % maximal displacement of one particle between the 2 images %5
            app.MaximaldisplacementbetweentwoimagespixelsEditField.Value=app.maxd;
            app.r_neighbor = 0;     % minimum distance to the nearest neighbor in pixel
            app.MinimumdistancetonearestneighbourpixelsEditField.Value=app.r_neighbor;
            app.corr_th = 0.5;
            app.CorrelationThresholdEditField.Value=app.corr_th;
            app.goodenough=2;
            app.TheminimumlengthoftrajectoryEditField.Value=app.goodenough;
            app.memory=0;
            app.MemoryframesparticlecandisappeartemporarilyEditField.Value=app.memory;
            app.wsmoothing=2;
            app.DisplacementfieldsmoothingEditField.Value=app.wsmoothing;
            app.nonstressed=app.nonstressed(app.LBY:app.UBY,app.LBX:app.UBX,app.beadsChID);
            if app.beadsChID==1
                app.stressed=app.crop1;
                app.cellule=app.crop2;
            elseif app.beadsChID==2
                app.stressed=app.crop2;
                app.cellule=app.crop1;
            end
            app.mask1=uint8(repmat((255*app.bw2), [1 1 size(app.crop1,3)]));
            app.path3=app.path2;
            app.FramesSlider_2.Limits = [1 size(app.mask1,3)];
            app.FramesSlider_2.MajorTicks = [1:round(size(app.mask1,3)/10):size(app.mask1,3)];
            cla(app.UIAxes_2)
            imagesc(app.UIAxes_2,app.cellule(:,:,1));
            app.UIAxes_2.YDir='reverse';
            xlabel(app.UIAxes_2,'')
            ylabel(app.UIAxes_2,'')
            xlim(app.UIAxes_2,[1 size(app.cellule,2)])
            ylim(app.UIAxes_2,[1 size(app.cellule,1)])
            %set(app.UIAxes_2,'CLim',[min(app.cellule(:)) max(app.cellule(:))])
            app.shownow=2;
            norm1=app.nonstressed/max(app.nonstressed(:));
            app.nonstressed=uint16(65535*norm1);
            app.nonstressed=imadjust(app.nonstressed,[0 10000/65535],[0 1]);
            norm1=app.stressed/max(app.stressed(:));
            app.stressed=uint16(65535*norm1);
            app.stressed=imadjustn(app.stressed,[0 10000/65535],[0 1]);
            norm1=app.cellule/max(app.cellule(:));
            app.cellule=uint16(65535*norm1);
            app.cellule=imadjustn(app.cellule,[0 40000/65535],[0 1]);
        end

        % Value changed function: PixelSizemEditField
        function PixelSizemEditFieldValueChanged(app, event)
            value = app.PixelSizemEditField.Value;
            app.pix=value;
        end

        % Value changed function: YoungModulusPaEditField
        function YoungModulusPaEditFieldValueChanged(app, event)
            value = app.YoungModulusPaEditField.Value;
            app.E=value;
        end

        % Value changed function: PoissonratioEditField
        function PoissonratioEditFieldValueChanged(app, event)
            value = app.PoissonratioEditField.Value;
            app.nu=value;
        end

        % Value changed function: 
        % CoefofRegularisationdependonYoungModulusEditField
        function CoefofRegularisationdependonYoungModulusEditFieldValueChanged(app, event)
            value = app.CoefofRegularisationdependonYoungModulusEditField.Value;
            app.alphadef=value;
        end

        % Value changed function: NumberofIterationsEditField
        function NumberofIterationsEditFieldValueChanged(app, event)
            value = app.NumberofIterationsEditField.Value;
            app.iter=value;
        end

        % Value changed function: OverlapPIVpixelsEditField
        function OverlapPIVpixelsEditFieldValueChanged(app, event)
            value = app.OverlapPIVpixelsEditField.Value;
            app.overlapPIV=value;
        end

        % Value changed function: OverlapTrackpixelsEditField
        function OverlapTrackpixelsEditFieldValueChanged(app, event)
            value = app.OverlapTrackpixelsEditField.Value;
            app.overlapTrack=value;
        end

        % Value changed function: IntervalpixelsEditField
        function IntervalpixelsEditFieldValueChanged(app, event)
            value = app.IntervalpixelsEditField.Value;
            app.interval=value;
        end

        % Value changed function: StepSizeMultipleofPixelEditField
        function StepSizeMultipleofPixelEditFieldValueChanged(app, event)
            value = app.StepSizeMultipleofPixelEditField.Value;
            app.pas=value;
        end

        % Value changed function: WindowSizepixelsEditField
        function WindowSizepixelsEditFieldValueChanged(app, event)
            value = app.WindowSizepixelsEditField.Value;
            app.window=value;
        end

        % Value changed function: DisplacementfieldsmoothingEditField
        function DisplacementfieldsmoothingEditFieldValueChanged(app, event)
            value = app.DisplacementfieldsmoothingEditField.Value;
            app.wsmoothing=value;
        end

        % Value changed function: MinimalparticlesizepixelsEditField
        function MinimalparticlesizepixelsEditFieldValueChanged(app, event)
            value = app.MinimalparticlesizepixelsEditField.Value;
            app.featsize=value;
        end

        % Value changed function: 
        % MaximumradiusofgyrationofparticlespixelsEditField
        function MaximumradiusofgyrationofparticlespixelsEditFieldValueChanged(app, event)
            value = app.MaximumradiusofgyrationofparticlespixelsEditField.Value;
            app.barrg=value;
        end

        % Value changed function: Particleseccentricity0roundEditField
        function Particleseccentricity0roundEditFieldValueChanged(app, event)
            value = app.Particleseccentricity0roundEditField.Value;
            app.barcc=value;
        end

        % Value changed function: MinimalRatioIntensitytoradiusEditField
        function MinimalRatioIntensitytoradiusEditFieldValueChanged(app, event)
            value = app.MinimalRatioIntensitytoradiusEditField.Value;
            app.IdivRg=value;
        end

        % Value changed function: MinimalintegratedintensityEditField
        function MinimalintegratedintensityEditFieldValueChanged(app, event)
            value = app.MinimalintegratedintensityEditField.Value;
            app.masscut=value;
        end

        % Value changed function: 
        % MaximaldisplacementbetweentwoimagespixelsEditField
        function MaximaldisplacementbetweentwoimagespixelsEditFieldValueChanged(app, event)
            value = app.MaximaldisplacementbetweentwoimagespixelsEditField.Value;
            app.maxd=value;
        end

        % Value changed function: TheminimumlengthoftrajectoryEditField
        function TheminimumlengthoftrajectoryEditFieldValueChanged(app, event)
            value = app.TheminimumlengthoftrajectoryEditField.Value;
            app.goodenough=value;
        end

        % Value changed function: 
        % MemoryframesparticlecandisappeartemporarilyEditField
        function MemoryframesparticlecandisappeartemporarilyEditFieldValueChanged(app, event)
            value = app.MemoryframesparticlecandisappeartemporarilyEditField.Value;
            app.memory=value;
        end

        % Value changed function: 
        % MinimumdistancetonearestneighbourpixelsEditField
        function MinimumdistancetonearestneighbourpixelsEditFieldValueChanged(app, event)
            value = app.MinimumdistancetonearestneighbourpixelsEditField.Value;
            app.r_neighbor=value;
        end

        % Value changed function: CorrelationThresholdEditField
        function CorrelationThresholdEditFieldValueChanged(app, event)
            value = app.CorrelationThresholdEditField.Value;
            app.corr_th=value;
        end

        % Button pushed function: LoadBlackColormapFileButton
        function LoadBlackColormapFileButtonPushed(app, event)
            [colormapfile,path,indx] = uigetfile('*.mat','Select comlormap file to load','MultiSelect','on');
            load([path colormapfile])
            app.blackcmap=blackcmap;
            load([path 'RedBlueColormaps.mat'])
            app.redbluecmap=redbluecmap;
        end

        % Button pushed function: RunTFMButton
        function RunTFMButtonPushed(app, event)
            Nb_frames=size(app.cellule,3);
            [n,s,p_corr,regx,regy]=registration_ssinterp(app.nonstressed,app.stressed(:,:,1),app.cellule(:,:,1));
            [dimy,dimx]=size(n);
            period=app.window-app.overlapPIV;
            nbx=floor((dimx-app.window)/period)+1;
            nby=floor((dimy-app.window)/period)+1;
            [posx,posy]=meshgrid((0:nbx-1)*period+floor((dimx-(nbx-1)*period)/2)+1,(0:nby-1)*period+floor((dimy-(nby-1)*period)/2)+1);
            nodeX=floor(period*nbx/app.interval);
            nodeY=floor(period*nby/app.interval);
            [gridX,gridY]=meshgrid((0:nodeX-1)*app.interval+floor((dimx-nbx*period)/2)+1+app.interval/2,(0:nodeY-1)*app.interval+floor((dimy-nby*period)/2)+1+app.interval/2);
            app.gridX=gridX;
            app.gridY=gridY;
            app.TractionX=[];
            app.TractionY=[];
            app.bf=[];
            app.Mask1=false([size(n) Nb_frames]);
            % Mask2=false([size(n) Nb_frames]);
            app.Dx=zeros([size(gridX) Nb_frames]);
            app.Dy=zeros([size(gridX) Nb_frames]);
            app.Tx=zeros([size(gridX) Nb_frames]);
            app.Ty=zeros([size(gridX) Nb_frames]);
            app.Brightfield=uint8(zeros([size(n) Nb_frames]));
            %Calculated global parameters
            % Pour le doublet de cellules
            MOY2cells=zeros(1,Nb_frames);% contrainte moyenne
            Total2cells=zeros(1,Nb_frames);% Somme en norme des tractions cellules-substrat
            Vect2cells=zeros(1,Nb_frames);% somme vectorielle des tractions cellules-subtrat: a l'�quilibre, devrait etre egale a zero (systeme isole)
            Ec2cells=zeros(1,Nb_frames);%?nergie contractile
            %Fcellcell=zeros(1,Nb_frames);% force cellule-cellule
            %DFcellcell=zeros(1,Nb_frames);% estimation de l'incertitude sur force cellule-cellule
            Moment=zeros(1,Nb_frames);%1st order moment of the traction distribution
            Angle=zeros(1,Nb_frames);% angle d'orientation principale par rapport � la verticale
            Polar=zeros(1,Nb_frames);%polarization degree, calculated from the eigenvalues of traction 1st order moment (l1-l2)/(l1+l2)
            %Pour chaque cellule du doublet
            MOYcell1=zeros(1,Nb_frames);% contrainte moyenne
            %  MOYcell2=zeros(1,Nb_frames);
            Tmaxcell1=zeros(1,Nb_frames);%maximum stress
            %  Tmaxcell2=zeros(1,Nb_frames);%maximum stress
            Totalcell1=zeros(1,Nb_frames);% Somme en norme des tractions cellule1-substrat
            %  Totalcell2=zeros(1,Nb_frames);% Somme en norme des tractions cellule2-substrat
            Cell_ECMratio1=zeros(1,Nb_frames);%rapport Fcell-cell/Fcell-ECM pour cellule 1 =Totalcell1/Fcellcell
            %   Cell_ECMratio2=zeros(1,Nb_frames);%rapport Fcell-cell/Fcell-ECM pour cellule 2
            Momentcell1=zeros(1,Nb_frames);% moment contractile net cell1
            %  Momentcell2=zeros(1,Nb_frames);% moment contractile net cell2
            %Parametres rajoutes (version Artur)
            Forcex_cell1=zeros(1,Nb_frames);%sum of x component of forces (in magnitude) for cell 1
            %  Forcey_cell1=zeros(1,Nb_frames);%sum of y component of forces (in magnitude) for cell 1
            Forcex_cell2=zeros(1,Nb_frames);%sum of x component of forces (in magnitude) for cell 2
            %  Forcey_cell2=zeros(1,Nb_frames);%sum of y component of forces (in magnitude) for cell 2
            Deplx_cell1=zeros(1,Nb_frames);%sum of x component of displacements (in magnitude) for cell 1
            Deply_cell1=zeros(1,Nb_frames);%sum of x component of displacements (in magnitude) for cell 1
            % Deplx_cell2=zeros(1,Nb_frames);%sum of x component of displacements (in magnitude) for cell 1
            % Deply_cell2=zeros(1,Nb_frames);%sum of x component of displacements (in magnitude) for cell 1
            
            %% initilise some files
            if ~exist(fullfile(app.path3,'figureTFM'),'dir')
                mkdir(fullfile(app.path3,'figureTFM'))
            end
            
            %% do TFM
            for ff=1:Nb_frames
                disp(['Analyse: image ',num2str(ff)]);
                if app.stressed(:,:,ff)==0
                    disp('Null image!!!')
                    return
                end
                [n,s,p_corr,m1_corr,regx,regy]=registration_ssinterp_pattern(app.nonstressed,app.stressed(:,:,ff),app.cellule(:,:,ff),app.mask1(:,:,ff));
                restex=regx-round(regx);
                restey=regy-round(regy);
                if size(n,1)<dimy
                    temp=zeros(dimy-size(n,1),size(n,2));
                    n=[n;temp];
                    s=[s;temp];
                    mask1_corr=logical([m1_corr;temp]);
                    bf=[p_corr;temp];
                elseif size(n,1)>dimy
                    mask1_corr=logical(m1_corr(1:dimy,:));
                    bf=p_corr(1:dimy,:);
                else
                    mask1_corr=logical(m1_corr);
                    bf=p_corr;
                end
                if size(n,2)<dimx
                    temp=zeros(size(n,1),dimx-size(n,2));
                    n=[n temp];
                    s=[s temp];
                    tempmask=false(size(mask1_corr,1),dimx-size(mask1_corr,2));
                    mask1_corr=[mask1_corr tempmask];
                    bf=[bf uint16(tempmask)];
                elseif size(n,2)>dimx
                    mask1_corr=mask1_corr(:,1:dimx);
                    bf=bf(:,1:dimx);
                end
                app.bf(:,:,ff)=bf;
                mask_corr=mask1_corr;
                deplaceX=zeros(10000,1);
                deplaceY=zeros(10000,1);
                positionX=zeros(10000,1);
                positionY=zeros(10000,1);
                compteur=0;
                mauvaises_fenetres=0;
                [M,N]=size(s);
                
                disp(['Number of ROIs: ',num2str(nby*nbx)]);
                for i=1:nbx
                    for j=1:nby
                        mauvais_track=false;
                        %nombre de fen�tres
                        Nwin=2.^(2*(0:app.iter));
                        %tailles des fen�tres
                        mi=app.window./(2.^(0:app.iter));
                        %tableau de positions (centre de fen�tres): � chaque �tape le nb de positions est
                        %multipli� par 4
                        posix=cell(1,app.iter+1);
                        posiy=cell(1,app.iter+1);
                        posix{1}=posx(1,i);
                        posiy{1}=posy(j,1);
                        % tableau d'offsets
                        offsetx=cell(1,app.iter+1);
                        offsety=cell(1,app.iter+1);
                        offsetx{1}=0;
                        offsety{1}=0;
                        for k=0:app.iter
                            %d�finit les offsets et les positions pour chaque it�ration
                            offsetx{k+1}=zeros(2^k);
                            offsety{k+1}=zeros(2^k);
                            %recopie les valeurs d'avant
                            for l=1:2^(k-1)
                                for m=1:2^(k-1)
                                    offsetx{k+1}((l-1)*2+1:l*2,(m-1)*2+1:m*2)=offsetx{k}(l,m)*ones(2);
                                    offsety{k+1}((l-1)*2+1:l*2,(m-1)*2+1:m*2)=offsety{k}(l,m)*ones(2);
                                    
                                end
                            end
                            [px,py]=meshgrid((-mi(k+1)/2*(2^k-1):mi(k+1):mi(k+1)/2*(2^k-1)),(-mi(k+1)/2*(2^k-1):mi(k+1):mi(k+1)/2*(2^k-1)));
                            posix{k+1}=posx(1,i)+px;
                            posiy{k+1}=posy(j,1)+py;
                            for nw=1:Nwin(k+1)
                                [sub1,sub2]=ind2sub([2^k 2^k],nw);
                                %1. interpolation de n
                                if k>0
                                    xi=(1:mi(k+1))-offsetx{k+1}(sub1,sub2)+posix{k+1}(sub1,sub2)-mi(k+1)/2-1;
                                    yi=(1:mi(k+1))-offsety{k+1}(sub1,sub2)+posiy{k+1}(sub1,sub2)-mi(k+1)/2-1;
                                    n_win = interp2(double(n),xi',yi);
                                else
                                    n_win=n(posiy{k+1}(sub1,sub2)-mi(k+1)/2:posiy{k+1}(sub1,sub2)+mi(k+1)/2-1,posix{k+1}(sub1,sub2)-mi(k+1)/2:posix{k+1}(sub1,sub2)+mi(k+1)/2-1);
                                end
                                %2. mesure du d�calage
                                s_win=s(posiy{k+1}(sub1,sub2)-mi(k+1)/2:posiy{k+1}(sub1,sub2)+mi(k+1)/2-1,posix{k+1}(sub1,sub2)-mi(k+1)/2:posix{k+1}(sub1,sub2)+mi(k+1)/2-1);
                                [offx,offy,Cmax]=decalage(s_win,n_win);
                                deltaI=min((max(n_win(:))-min(n_win(:)))/mean(n_win(:)),(max(s_win(:))-min(s_win(:)))/mean(s_win(:)));
                                if deltaI<5||Cmax<0.5%||sqrt(offx^2+offy^2)>mi(k+1)/3
                                    offx=0;
                                    offy=0;
                                end
                                %remplissage du tableau de valeurs
                                if k==0
                                    offsetx{1}=offx;
                                    offsety{1}=offy;
                                else
                                    offsetx{k+1}(sub1,sub2)=offx+ offsetx{k}(ceil(sub1/2),ceil(sub2/2));
                                    offsety{k+1}(sub1,sub2)=offy+ offsety{k}(ceil(sub1/2),ceil(sub2/2));
                                end
                                %apr�s la derni�re it�ration, faire le tracking pour chaque
                                %fenetre �largie
                                if k==app.iter
                                    % interpolation de n
                                    win=mi(k+1)+app.overlapTrack;
                                    ystart=posiy{k+1}(sub1,sub2)-win/2;
                                    xstart=posix{k+1}(sub1,sub2)-win/2;
                                    yend=posiy{k+1}(sub1,sub2)+win/2-1;
                                    xend=posix{k+1}(sub1,sub2)+win/2-1;
                                    if ystart<1, ystart=1;end
                                    if xstart<1, xstart=1;end
                                    if yend>M, yend=M;end
                                    if xend>N, xend=N;end
                                    xi=(1:win)-offsetx{k+1}(sub1,sub2)+xstart-1;
                                    yi=(1:win)-offsety{k+1}(sub1,sub2)+ystart-1;
                                    n_interp = interp2(double(n),xi',yi);
                                    s_win=s(ystart:yend,xstart:xend);
                                    %tracking
                                    mauvais_track=0;
                                    if strcmp(app.cl,'uint16')
                                        %n_win=uint16(n_interp);
                                        n_win= uint16(65535/(max(n_interp(:))-min(n_interp(:)))*(n_interp-min(n_interp(:))));
                                        s_win= uint16(65535/double(max(s_win(:))-min(s_win(:)))*double(s_win-min(s_win(:))));
                                    elseif strcmp(app.cl,'uint8')
                                        n_win= uint8(255/(max(n_interp(:))-min(n_interp(:)))*(n_interp-min(n_interp(:))));
                                        s_win= uint8(255/double(max(s_win(:))-min(s_win(:)))*double(s_win-min(s_win(:))));%n_win=uint8(n_interp);
                                    end
                                    %Tracking sur les petites fen?tres
                                    nM = feature2DTFM(double(n_win),1,app.featsize,app.masscut);
                                    %figure(5),imshow(n_win,[]), hold on, plot(nM(:,1),nM(:,2),'go'), hold off
                                    if numel(nM)==1||isempty(nM)
                                        %                                         figure(16)
                                        %                                         imshow(n_win,[])
                                        mauvais_track=true;
                                    else
                                        %Rejection process
                                        X= nM(:,5)>app.barcc;
                                        nM(X,1:5)=0;
                                        X= nM(:,4)>app.barrg;
                                        nM(X,1:5)=0;
                                        X= nM(:,3)./nM(:,4)<app.IdivRg;
                                        nM(X,1:5)=0;
                                        nM=nM(nM(:,1)~=0,:);
                                        if numel(nM)==1||isempty(nM)
                                            %                                             figure(16)
                                            %                                             imshow(n_win,[])
                                            mauvais_track=true;
                                        end
                                    end
                                    sM = feature2DTFM(double(s_win),1,app.featsize,app.masscut);
                                    if numel(sM)==1||isempty(sM)
                                        %                                         figure(16)
                                        %                                         imshow(s_win,[])
                                        mauvais_track=true;
                                    else
                                        %Rejection process
                                        X= sM(:,5)>app.barcc;
                                        sM(X,1:5)=0;
                                        X= sM(:,4)>app.barrg;
                                        sM(X,1:5)=0;
                                        X= sM(:,3)./sM(:,4)<app.IdivRg;
                                        sM(X,1:5)=0;
                                        sM=sM(sM(:,1)~=0,:);
                                        if numel(sM)==1||isempty(sM)
                                            %                                             figure(16)
                                            %                                             imshow(s_win,[])
                                            mauvais_track=true;
                                        end
                                    end
                                    if not(mauvais_track)
                                        Mtrack=[nM(:,1:2),ones(size(nM,1),1),ones(size(nM,1),1); sM(:,1:2),2*ones(size(sM,1),1),2*ones(size(sM,1),1)];
                                        [lub] = trackmem(Mtrack,app.maxd,2,app.goodenough,app.memory);
                                        if lub==-1
                                            %                                             figure(15)
                                            %                                             ax2(1,2,1), imshow(s_win,[]), hold on, plot(sM(:,1),sM(:,2),'go'), hold off
                                            %                                             ax2(1,2,2), imshow(n_win,[]), hold on, plot(nM(:,1),nM(:,2),'go'), hold off
                                            mauvais_track=true;
                                        end
                                    end
                                    if mauvais_track
                                        mauvaises_fenetres=mauvaises_fenetres+1;
                                    else
                                        nb_part=max(lub(:,5));
                                        
                                        deplaceX(compteur+1:compteur+nb_part)=lub((lub(:,4)==2),1)-lub((lub(:,4)==1),1)+offsetx{k+1}(sub1,sub2)+restex;
                                        deplaceY(compteur+1:compteur+nb_part)=lub((lub(:,4)==2),2)-lub((lub(:,4)==1),2)+offsety{k+1}(sub1,sub2)+restey;
                                        positionX(compteur+1:compteur+nb_part)=lub((lub(:,4)==2),1)+(posix{k+1}(sub1,sub2)-win/2)-1;
                                        positionY(compteur+1:compteur+nb_part)=lub((lub(:,4)==2),2)+(posiy{k+1}(sub1,sub2)-win/2)-1;
                                        compteur=compteur+nb_part;
                                    end
                                end
                            end
                        end
                    end
                end
                disp([num2str(compteur-mauvaises_fenetres) ' features tracked']);
                disp([num2str(mauvaises_fenetres) ' bad ROIs']);
                deplaceX(compteur+1:10000)=[];
                deplaceY(compteur+1:10000)=[];
                positionX(compteur+1:10000)=[];
                positionY(compteur+1:10000)=[];
                %remove duplicates
                Mpos=[positionX positionY];
                [A,ind]=sortrows(Mpos);
                deplaceX=deplaceX(ind);
                deplaceY=deplaceY(ind);
                tolerance=2;
                diff=A(1:end-1,:)-A(2:end,:);
                duplicate=find((diff(:,1).^2+diff(:,2).^2)<tolerance);
                A(duplicate,:)=[];
                positionX=A(:,1);
                positionY=A(:,2);
                deplaceX(duplicate)=[];
                deplaceY(duplicate)=[];
                disp(['Tracked Particles (non duplicated): ', num2str(length(deplaceX))])
                nb_beads = size(positionX);
                deplaceXh = deplaceX;
                deplaceYh = deplaceY;
                for i = 1:nb_beads
                    index_neighbors = find(sqrt((positionX-positionX(i)).^2+(positionY-positionY(i)).^2)<app.r_neighbor);
                    allbutone = find(index_neighbors~=i);
                    index_neighbors = index_neighbors(allbutone);
                    r_corr = mean((deplaceXh(i)*deplaceXh(index_neighbors)+deplaceYh(i)*deplaceYh(index_neighbors))./(sqrt(deplaceXh(i)^2+deplaceYh(i)^2)*sqrt(deplaceXh(index_neighbors).^2+deplaceYh(index_neighbors).^2)));
                    if r_corr < app.corr_th
                        deplaceX(i) = mean(deplaceX(index_neighbors));
                        deplaceY(i) = mean(deplaceY(index_neighbors));
                    end
                end
                % Plot and save Tracking
                Idisp=zeros([size(s) 3]);
                sn=double(s)/double(max(s(:)));
                nn=double(n)/double(max(n(:)));
                Idisp(:,:,1)=nn;
                Idisp(:,:,2)=sn;
                Idisp(:,:,3)=nn;
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                imagesc(app.UIAxes_2,Idisp),colormap(app.UIAxes_2,gray)
                title(app.UIAxes_2,['Image  ',num2str(ff),' out of ', num2str(Nb_frames)]);
                hold(app.UIAxes_2,'on')
                quiver(app.UIAxes_2,positionX,positionY,deplaceX,deplaceY,'r');
                xlim(app.UIAxes_2,[1 size(Idisp,2)])
                ylim(app.UIAxes_2,[1 size(Idisp,1)])
                %quiver(app.UIAxes_2,positionY,positionX,deplaceY,deplaceX,'r');
                hold(app.UIAxes_2,'off')
                drawnow
                figurepath=cat(2,app.path3,'\figureTFM\Tracking',num2str(ff),'.tif');
                %                 f = figure('Visible', false);
                %                 axNew = axes(f);
                %                 myCopyObj(app.UIAxes_2, axNew);
                %                 print(f, fullfile(figurepath),'-dtiff','-r100');
                %                 delete(f);
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\figureTFM\Tracking.tif'),'WriteMode','append');
                delete (figurepath)
                %% Displacements
                deplacementXa=app.pix*griddata(positionX,positionY,deplaceX,app.gridX,app.gridY);
                deplacementYa=app.pix*griddata(positionX,positionY,deplaceY,app.gridX,app.gridY);
                deplacementXa(isnan(deplacementXa))=0;
                deplacementYa(isnan(deplacementYa))=0;
                %smoothing
                %wsmoothing=2;
                [x,y]=meshgrid((-4*app.wsmoothing:4*app.wsmoothing),(-4*app.wsmoothing:4*app.wsmoothing));
                Gauss=exp(-2*(x.^2+y.^2)/app.wsmoothing^2);
                h=Gauss/sum(Gauss(:));
                deplacementX=imfilter(deplacementXa,h);
                deplacementY=imfilter(deplacementYa,h);
                deplacement = sqrt(deplacementX.^2+deplacementY.^2);
                %% Traction
                [Tractionx,Tractiony,mu,theta]=regularized(deplacementX,deplacementY,app.E,app.nu,app.pas,app.alphadef);
                app.TractionX{ff}=Tractionx;
                app.TractionY{ff}=Tractiony;
                Tmagn=sqrt(Tractionx.^2+Tractiony.^2);
                %% Calculs sous le doublet de cellules
                disp('---- Computation for cell pair -----')
                SE = strel('disk',1);
                BWpattern=imdilate(mask_corr,SE);
                app.BWgrid=logical(BWpattern(app.gridY(:,1),app.gridX(1,:)));
                Txcrop=Tractionx.*app.BWgrid;
                Tycrop=Tractiony.*app.BWgrid;
                %calcul de l'?nergie contractile
                u=Txcrop.*deplacementX+Tycrop.*deplacementY;  %produit scalaire traction par app.deplacement
                U=app.pas^2/2*sum(u(:));
                disp(['contractile energy: ',num2str(U), ' J']);
                Ec2cells(ff)=U;
                % image de traction map avec decoupe du pattern
                Tmagn2=sqrt(Txcrop.^2+Tycrop.^2);
                % contrainte moyenne sous le doublet
                TmagnCROPMOY=sum(Tmagn2(:))/bwarea(app.BWgrid);
                disp(['mean stress: ',  num2str(TmagnCROPMOY),' Pa']);
                MOY2cells(ff)=TmagnCROPMOY;
                %Traction totale sous les 2 cellules
                Sgrid = app.pas^2;%surface d'une maille en metre au carr?
                TmagnCROPTOT=sum(Tmagn2(:))*Sgrid;% somme des forces de traction en norme
                disp(['Total of magnitude of tractions: ',  num2str(TmagnCROPTOT),' N']);
                Total2cells(ff)=TmagnCROPTOT;
                %Somme vectorielle (syst?me isol? ? l'?quilibre: devrait =0)
                Vect2cells(ff)=sqrt((sum(Txcrop(:)))^2+(sum(Tycrop(:)))^2)*Sgrid;
                unbalance=Vect2cells(ff)/TmagnCROPTOT;
                disp(['Percentage of non-equilibrium forces: ',  num2str(unbalance*100),' %']);
                %Main contraction direction for the cell doublet (it is not valid to
                %calculat it on individual cells since they are not at equilibrium)
                %1.clean the mask
                s=regionprops(mask_corr,'Area','Centroid');
                [~,ind]=max([s.Area]);
                x0=s(ind).Centroid(:,1);
                y0=s(ind).Centroid(:,2);
                Mxx=sum(sum((app.gridX-x0).*Txcrop))*app.pix*Sgrid^2;
                Mxy=sum(sum((app.gridX-x0).*Tycrop))*app.pix*Sgrid^2;
                Myx=sum(sum((app.gridY-y0).*Txcrop))*app.pix*Sgrid^2;
                Myy=sum(sum((app.gridY-y0).*Tycrop))*app.pix*Sgrid^2;
                M=[Mxx -Mxy;-Myx Myy];
                Moment(ff)=trace(M);
                [V,D]=eig(M);
                if isreal(D)
                    DD=max(-D);
                    %on identifie la direction principale (indice ind)
                    [Dmax, ind]=max(DD);
                    ang=180/pi*atan(real(V(2,ind))/real(V(1,ind)));
                    %angle par rapport la verticale
                    if ang>=0
                        Angle(ff)=90-ang;
                    else
                        Angle(ff)= -90-ang;
                    end
                    L1=D(ind,ind);
                    %autre direction
                    autre=mod(ind,2)+1;
                    L2=D(autre, autre);
                else% non diagonalizable
                    Mxyn=(Mxy+Myx)/2;
                    Myxn=Mxyn;
                    M=[Mxx -Mxyn;-Myxn Myy];
                    [V,D]=eig(M);
                    DD=max(-D);
                    %on identifie la direction principale (indice ind)
                    [Dmax, ind]=max(DD);
                    ang=180/pi*atan(real(V(2,ind))/real(V(1,ind)));
                    %angle par rapport la verticale
                    if ang>=0
                        Angle(ff)=90-ang;
                    else
                        Angle(ff)= -90-ang;
                    end
                    L1=DD(ind);
                    %autre direction
                    autre=mod(ind,2)+1;
                    L2=DD(autre);
                end
                Polar(ff)=(L1-L2)/(L1+L2);
                disp(['Angle of main contraction compared to the vertical: ',  num2str(Angle(ff))]);
                disp(['Polarisation degree of the cell doublet (0=isotropic,1=uniaxial): ',  num2str(Polar(ff))]);
                %                 %% Calculs pour chaque cellule du doublet et force cell-cell
                disp('---- Computation over individual cells-----')
                BW=mask1_corr;
                BWc1=imdilate(BW,SE);
                BWgrid1=logical(BWc1(app.gridY(:,1),app.gridX(1,:)));
                %                 BW=mask2_corr;
                %                 BWc2=imdilate(BW,SE);
                %                 BWgrid2=logical(BWc2(gridY(:,1),gridX(1,:)));
                Txcrop1=Tractionx.*BWgrid1;
                Tycrop1=Tractiony.*BWgrid1;
                %                 Txcrop2=Tractionx.*BWgrid2;
                %                 Tycrop2=Tractiony.*BWgrid2;
                %
                %
                Tnorm=sqrt(Txcrop1.^2+Tycrop1.^2);
                MOYcell1(ff)=sum(Tnorm(:))/bwarea(BWgrid1);% contrainte moyenne
                Totalcell1(ff)=sum(Tnorm(:))*Sgrid;% somme des forces de traction en norme
                %Maximum stress
                ntot=bwarea(BWgrid1);
                thres=max(Tnorm(:));
                while numel(find(Tnorm>=thres))<0.05*ntot
                    thres=thres-20;
                end
                Tmaxcell1(ff)=mean(Tnorm(find(Tnorm>=thres)));
                %
                %                 Tnorm=sqrt(Txcrop2.^2+Tycrop2.^2);
                %                 MOYcell2(ff)=sum(Tnorm(:))/bwarea(BWgrid2);
                %                 Totalcell2(ff)=sum(Tnorm(:))*Sgrid;% somme des forces de traction en norme
                %                 %Maximum stress
                %                 ntot=bwarea(BWgrid2);
                %                 thres=max(Tnorm(:));
                %                 while numel(find(Tnorm>=thres))<0.05*ntot
                %                     thres=thres-20;
                %                 end
                %                 Tmaxcell2(ff)=mean(Tnorm(find(Tnorm>=thres)));
                %
                disp(['Mean constraint under cell 1: ',  num2str(MOYcell1(ff)),' Pa']);
                %                 disp(['Contrainte moyenne sous cellule 2: ',  num2str(MOYcell2(ff)),' Pa']);
                %
                disp(['Max constraint under cell 1: ',  num2str(Tmaxcell1(ff)),' Pa']);
                %                 disp(['Contrainte max sous cellule 2: ',  num2str(Tmaxcell2(ff)),' Pa']);
                %
                disp(['Total of magnitude of tration under cell 1: ',  num2str(Totalcell1(ff)),' N']);
                %                 disp(['Total des tractions en norme sous cellule 2: ',  num2str(Totalcell2(ff)),' N']);
                %
                %                 disp('---- Force cellule-cellule -----')
                %                 Fcellcell(ff)=sqrt((sum(Txcrop1(:))-sum(Txcrop2(:)))^2+(sum(Tycrop1(:))-sum(Tycrop2(:)))^2)*Sgrid/2;
                %                 disp(['Force cellule-cellule : ',  num2str(Fcellcell(ff)),' N']);
                %                 DFcellcell(ff)=sqrt((sum(Txcrop1(:))+sum(Txcrop2(:)))^2+(sum(Tycrop1(:))+sum(Tycrop2(:)))^2)*Sgrid/2;
                %                 disp(['Incertitude : ',  num2str(DFcellcell(ff)),' N']);
                Cell_ECMratio1(ff)=sqrt((sum(Txcrop1(:)))^2+(sum(Tycrop1(:)))^2)*Sgrid/Totalcell1(ff);
                %                 Cell_ECMratio2(ff)=sqrt((sum(Txcrop2(:)))^2+(sum(Tycrop2(:)))^2)*Sgrid/Totalcell2(ff);
                disp(['Rapport F cell-cell/F cell-ECM pour cellule 1: ',  num2str(Cell_ECMratio1(ff)*100),' %']);
                %                 disp(['Rapport F cell-cell/F cell-ECM pour cellule 2: ',  num2str(Cell_ECMratio2(ff)*100),' %']);
                %                 %Calcul des moments
                s=regionprops(mask1_corr,'Area','Centroid');
                [~,ind]=max([s.Area]);
                x1=s(ind).Centroid(:,1);
                y1=s(ind).Centroid(:,2);
                %                 s=regionprops(mask2_corr,'Area','Centroid');
                %                 [~,ind]=max([s.Area]);
                %                 x2=s(ind).Centroid(:,1);
                %                 y2=s(ind).Centroid(:,2);
                Mxx1=sum(sum((app.gridX-x1).*Txcrop1))*app.pix*Sgrid;
                %                 Mxx2=sum(sum((gridX-x2).*Txcrop2))*pix*Sgrid;
                Myy1=sum(sum((app.gridY-y1).*Tycrop1))*app.pix*Sgrid;
                %                 Myy2=sum(sum((gridY-y2).*Tycrop2))*pix*Sgrid;
                Mxy1=sum(sum((app.gridX-x1).*Tycrop1))*app.pix*Sgrid;
                %                 Mxy2=sum(sum((gridX-x1).*Tycrop2))*pix*Sgrid;
                Myx1=sum(sum((app.gridY-y1).*Txcrop1))*app.pix*Sgrid;
                %                 Myx2=sum(sum((gridY-y2).*Txcrop2))*pix*Sgrid;
                M1=[Mxx1 -Mxy1;-Myx1 Myy1];
                %                 M2=[Mxx2 -Mxy2;-Myx2 Myy2];
                %
                %                 %TBD
                Momentcell1(ff)=trace(M1);
                %                 Momentcell2(ff)=trace(M2);
                disp(['contractile moment cell 1: ',  num2str(Momentcell1(ff)),' N.m']);
                %                 disp(['contractile moment cell 2: ',  num2str(Momentcell2(ff)),' N.m']);
                %
                Forcex_cell1(ff)=sum(abs(Txcrop1(:)))*Sgrid;%sum of x component of forces (in magnitude) for cell 1
                Forcey_cell1(ff)=sum(abs(Tycrop1(:)))*Sgrid;%sum of y component of forces (in magnitude) for cell 1
                %                 Forcex_cell2(ff)=sum(abs(Txcrop2(:)))*Sgrid;%sum of x component of forces (in magnitude) for cell 2
                %                 Forcey_cell2(ff)=sum(abs(Tycrop2(:)))*Sgrid;%sum of y component of forces (in magnitude) for cell 2
                Deplcropx1=deplacementX.*BWgrid1;
                Deplcropy1=deplacementY.*BWgrid1;
                %                 Deplcropx2=app.deplacementX.*BWgrid2;
                %                 Deplcropy2=app.deplacementY.*BWgrid2;
                Deplx_cell1(ff)=sum(abs(Deplcropx1(:)));%sum of x component of displacements (in magnitude) for cell 1
                Deply_cell1(ff)=sum(abs(Deplcropy1(:)));%sum of x component of displacements (in magnitude) for cell 1
                %                 Deplx_cell2(ff)=sum(abs(Deplcropx2(:)));%sum of x component of displacements (in magnitude) for cell 1
                %                 Deply_cell2(ff)=sum(abs(Deplcropy2(:)));%sum of x component of displacements (in magnitude) for cell 1
                
                %%
                %save (fullfile(path,[filename(1:end-4),'_results', num2str(ff),'.mat']),'p_corr','BW','BWgrid','app.deplacement*','Traction*','eigenvalue*');
                %cellule(1:size(p_corr,1),1:size(p_corr,2),ff)=p_corr;
                app.Brightfield(:,:,ff)=uint8(round(double(bf-min(bf(:)))/double(max(bf(:))-min(bf(:)))*255));
                app.Mask1(:,:,ff)=mask1_corr;
                % app.Mask2(:,:,ff)=mask2_corr;
                app.Dx(:,:,ff)=deplacementX;
                app.Dy(:,:,ff)=deplacementY;
                app.Tx(:,:,ff)=Tractionx;
                app.Ty(:,:,ff)=Tractiony;
                
                
                %caculate contractile Energy in fJ
                EcfJ= Ec2cells.*10^15;
                %calculate mean values
                meanEc = mean(Ec2cells, 'all');
                meanEcfJ = mean(EcfJ, 'all');
                meanPolar = mean(Polar, 'all');
                meanMOY2cells = mean(MOY2cells, 'all');
                meanMoment = mean(Moment, 'all');
            end
            
            % export data
            Dx=app.Dx;
            Dy=app.Dy;
            Tx=app.Tx;
            Ty=app.Ty;
            Mask1=app.Mask1;
            Brightfield=app.Brightfield;
            save (fullfile(app.path3,'Allresults2.mat'),'Mask*','Dx','Dy','Tx','Ty','grid*','Brightfield');
            
            %Save calculated parameters
            fid=fopen(fullfile(app.path3,'Resultats2cells.txt'),'w+');
            fprintf(fid,['Ec \t Pmoy \t Ttot \t  T_imbalance \t Moment \t Angle \t Polar degree \t Pmoy-cell1 \t Pmax_cell1 \t' ...
                'Pmoy-cell2 \t T-cell1  \t Rcell1-ECM  \t Mmt-cell1  \t Force x cell1 \t Force y cell1' ...
                '\t Displ x cell1 \t Displ y cell1  \n']);
            fclose(fid);
            dlmwrite(fullfile(app.path3,'Results2cells.txt'),[Ec2cells',MOY2cells',Total2cells',Vect2cells',Moment',Angle',Polar', ...
                MOYcell1',Tmaxcell1',Totalcell1',Cell_ECMratio1',Momentcell1',Forcex_cell1',...
                Forcey_cell1',Deplx_cell1',Deply_cell1'],'-append','delimiter','\t')
            disp('Results saved in: ')
            disp(fullfile(app.path3,'Results2cells.txt'))
            
            
            % Export to Excel
            T = table(Ec2cells', EcfJ', MOY2cells', Moment', Polar', (Vect2cells./Total2cells*100)', 'VariableNames', {'contractile energy (J)','contractile energy (fJ)','average stress (Pa)','Moment','Polar','Out of Equilibrium'});
            X = table(meanEc, meanEcfJ, meanMOY2cells ,meanMoment, meanPolar );
            writetable(T,fullfile(app.path3,'results.xlsx'),'Sheet',1,'Range','A1','WriteVariableNames', true);
            writetable(X,fullfile(app.path3,'results.xlsx'),'Sheet',2,'Range','A1');
            
            
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            
            %graphs
            pos=(1:Nb_frames);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'average stress (Pa)','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(MOY2cells) nanmax(MOY2cells)])
            delX=max(pos)-min(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(MOY2cells))*(nanmax(MOY2cells)-nanmin(MOY2cells));
            app.UIAxes_2.YTick = [nanmin(MOY2cells):round(delY/3,1):nanmax(MOY2cells)];
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,MOY2cells,'o-'),title(app.UIAxes_2,'average stress (Pa)');
            exportgraphics(app.UIAxes_2,[app.path3 'AverageStress.png']);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'Contractile Moment (Nm)','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(Momentcell1) nanmax(Momentcell1)])
            delX=nanmax(pos)-nanmin(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(Momentcell1))*(nanmax(Momentcell1)-nanmin(Momentcell1));
            if sign(nanmin(Momentcell1))==1
            app.UIAxes_2.YTick = [nanmin(Momentcell1):delY/3:nanmax(Momentcell1)];
            elseif sign(nanmin(Momentcell1))==-1
            app.UIAxes_2.YTick = sort([nanmax(Momentcell1):delY/3:nanmin(Momentcell1)]);
            end
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,Momentcell1,'-om'), hold(app.UIAxes_2,"on"),hold(app.UIAxes_2,"off");,title(app.UIAxes_2,'contractile moment (Nm)')
            exportgraphics(app.UIAxes_2,[app.path3 'ContractileMoment.png']);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            y=Vect2cells./Total2cells*100;
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'Out Of Equilibrium','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(y) nanmax(y)])
            delX=max(pos)-min(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(y))*(nanmax(y)-nanmin(y));
            app.UIAxes_2.YTick = [nanmin(y):round(delY/3,1):nanmax(y)];
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,Vect2cells./Total2cells*100,'d-g'),title(app.UIAxes_2,'Out of equilibrium');
            exportgraphics(app.UIAxes_2,[app.path3 'OutOfEquilibrium.png']);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            y=Ec2cells;
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'Contractile Energy (J)','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(y) nanmax(y)])
            delX=max(pos)-min(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(y))*(nanmax(y)-nanmin(y));
            app.UIAxes_2.YTick = [nanmin(y):delY/3:nanmax(y)];
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,Ec2cells, '--o'),title(app.UIAxes_2,'contractile energy (J)');
            exportgraphics(app.UIAxes_2,[app.path3 'ContractileEnergy.png']);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            y=Polar;
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'Degree Of Polarisation','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(y) nanmax(y)])
            delX=max(pos)-min(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(y))*(nanmax(y)-nanmin(y));
            app.UIAxes_2.YTick = [nanmin(y):round(delY/3,1):nanmax(y)];
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,Polar,'-sc'),  title(app.UIAxes_2,'Degre of polarisation')
            exportgraphics(app.UIAxes_2,[app.path3 'DegreeOfPolarisation.png']);


            fid=fopen(fullfile(app.path3,[app.filebeads(1:end-4) 'Param.txt']),'w');
            fprintf(fid,'Registration (x direction): %g \n',regx);
            fprintf(fid,'Registration (y direction): %g \n',regy);
            fprintf(fid,'PIV window size: %g \n \n',app.window);
            fprintf(fid,'Number of PIV iterations (added to the 1st): %g \n \n',app.iter);
            fprintf(fid,'Overlap PIV: %g \n \n',app.overlapPIV);
            fprintf(fid,'Overlap tracking: %g \n \n',app.overlapTrack);
            
            fprintf(fid,'Tracking parameters: \n');
            fprintf(fid,'Feature size: %g \n',app.featsize);
            fprintf(fid,'Min intensity(masscut): %g\n',app.masscut);
            fprintf(fid,'Max radius: %g\n',app.barrg);
            fprintf(fid,'Max eccentricity: %g\n',app.barcc);
            fprintf(fid,'Min ratio intensity/radius: %4.1f\n',app.IdivRg);
            fprintf(fid,'Max displacement in pixels: %g\n\n',app.maxd);
            fprintf(fid,'Pixel size (in meter): %g\n',app.pix);
            fprintf(fid,'Data interval (for displacement and force) in pixels: %g\n',app.interval);
            fprintf(fid,'Young modulus (in Pa): %g\n',app.E);
            fprintf(fid,'Poisson ratio: %g\n',app.nu);
            fprintf(fid,'Regularization parameter: %g\n',app.alphadef);
            fclose(fid);
            
            
        end

        % Button pushed function: 
        % ShowBeadsDisplacementVectorMapandSaveinTIFButton
        function ShowBeadsDisplacementVectorMapandSaveinTIFButtonPushed(app, event)
            %% Display
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            app.UIAxes_2.YDir='reverse';
            colorbar(app.UIAxes_2,'off')
            for ff=1:size(app.Dx,3)
                deplacementX=app.Dx(:,:,ff);
                deplacementY=app.Dy(:,:,ff);
                deplacement = sqrt(deplacementX.^2+deplacementY.^2);
                imagesc(app.UIAxes_2,deplacement),colormap(app.UIAxes_2,app.blackcmap),colorbar(app.UIAxes_2),hold(app.UIAxes_2,"off"), daspect(app.UIAxes_2,[1 1 1]),
                xlim(app.UIAxes_2,[1 size(deplacement,2)])
                ylim(app.UIAxes_2,[1 size(deplacement,1)])
                title(app.UIAxes_2,['Displacement - Frame ',num2str(ff)]);
                figurepath=cat(2,app.path3,'\figureTFM\Displacement',num2str(ff),'.tif')
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\figureTFM\Displacement.tif'),'WriteMode','append');
                delete (figurepath)
            end
        end

        % Button pushed function: 
        % ShowForcesExertedbyCellOnSubstrateAndSaveinTIFButton
        function ShowForcesExertedbyCellOnSubstrateAndSaveinTIFButtonPushed(app, event)
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            app.UIAxes_2.YDir='reverse';
            colorbar(app.UIAxes_2,'off')
            for ff=1:size(app.Dx,3)
                imagesc(app.UIAxes_2,app.bf(:,:,ff));colormap(app.UIAxes_2,'gray')
                title(app.UIAxes_2,['Image ', num2str(ff)])
                hold(app.UIAxes_2,"on");
                quiver(app.UIAxes_2,app.gridX(1:2:end,1:2:end),app.gridY(1:2:end,1:2:end),8*app.TractionX{ff}(1:2:end,1:2:end),8*app.TractionY{ff}(1:2:end,1:2:end),2,'r','AutoScale','off')
                hold(app.UIAxes_2,"off");
                xlim(app.UIAxes_2,[1 size(app.bf,2)])
                ylim(app.UIAxes_2,[1 size(app.bf,1)])
                figurepath=cat(2,app.path3,'\figureTFM\BF_polar_degree',num2str(ff),'.tif');
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\figureTFM\BF_polar_degree.tif'),'WriteMode','append');
                delete (figurepath)
            end
        end

        % Value changing function: FramesSlider_2
        function FramesSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.sliderFrame2=uint16(changingValue);
            if app.shownow==1
                cla(app.UIAxes_2)
                imagesc(app.UIAxes_2,app.stressed(:,:,app.sliderFrame2));
                app.UIAxes_2.YDir='reverse';
                xlabel(app.UIAxes_2,'')
                ylabel(app.UIAxes_2,'')
                xlim(app.UIAxes_2,[1 size(app.stressed,2)])
                ylim(app.UIAxes_2,[1 size(app.stressed,1)])
            elseif app.shownow==2
                cla(app.UIAxes_2)
                imagesc(app.UIAxes_2,app.cellule(:,:,app.sliderFrame2));
                app.UIAxes_2.YDir='reverse';
                xlabel(app.UIAxes_2,'')
                ylabel(app.UIAxes_2,'')
                xlim(app.UIAxes_2,[1 size(app.cellule,2)])
                ylim(app.UIAxes_2,[1 size(app.cellule,1)])
            elseif app.shownow==3
                cla(app.UIAxes_2)
                imagesc(app.UIAxes_2,app.mask1(:,:,app.sliderFrame2));
                app.UIAxes_2.YDir='reverse';
                xlabel(app.UIAxes_2,'')
                ylabel(app.UIAxes_2,'')
                xlim(app.UIAxes_2,[1 size(app.mask1,2)])
                ylim(app.UIAxes_2,[1 size(app.mask1,1)])
            elseif app.shownow==4
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                colorbar(app.UIAxes_2,'off')
                deplacementX=app.Dx(:,:,app.sliderFrame2);
                deplacementY=app.Dy(:,:,app.sliderFrame2);
                deplacement = sqrt(deplacementX.^2+deplacementY.^2);
                imagesc(app.UIAxes_2,deplacement),colormap(app.UIAxes_2,app.blackcmap),colorbar(app.UIAxes_2),hold(app.UIAxes_2,"off"), daspect(app.UIAxes_2,[1 1 1]),
                xlim(app.UIAxes_2,[1 size(deplacement,2)])
                ylim(app.UIAxes_2,[1 size(deplacement,1)])
                title(app.UIAxes_2,['Displacement - Frame ',num2str(app.sliderFrame2)]);
            elseif app.shownow==5
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                colorbar(app.UIAxes_2,'off')
                imagesc(app.UIAxes_2,app.bf(:,:,app.sliderFrame2));colormap(app.UIAxes_2,'gray')
                title(app.UIAxes_2,['Image ', num2str(app.sliderFrame2)])
                hold(app.UIAxes_2,"on");
                quiver(app.UIAxes_2,app.gridX(1:2:end,1:2:end),app.gridY(1:2:end,1:2:end),8*app.TractionX{app.sliderFrame2}(1:2:end,1:2:end),8*app.TractionY{app.sliderFrame2}(1:2:end,1:2:end),2,'r','AutoScale','off')
                hold(app.UIAxes_2,"off");
                xlim(app.UIAxes_2,[1 size(app.bf,2)])
                ylim(app.UIAxes_2,[1 size(app.bf,1)])
            elseif app.shownow==6
                max_traction=max(app.Tx,[],'all');
                length_x = size(app.Dx,1);
                length_y = size(app.Dx,2);
                [PosX,PosY]=meshgrid(1:length_y,1:length_x);
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                colorbar(app.UIAxes_2,'off')
                traction = sqrt(app.Tx(:,:,app.sliderFrame2).^2+app.Ty(:,:,app.sliderFrame2).^2);
                imagesc(app.UIAxes_2,traction),colormap(app.UIAxes_2,jet),colorbar(app.UIAxes_2), caxis(app.UIAxes_2,[0 max_traction]), set(app.UIAxes_2,'Visible','off');daspect(app.UIAxes_2,[1 1 1]), %.*BWgrid
                hold(app.UIAxes_2,"on");
                xlim(app.UIAxes_2,[1 size(traction,2)])
                ylim(app.UIAxes_2,[1 size(traction,1)])
                quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),2e-2*app.Tx(1:4:end,1:4:end,app.sliderFrame2),2e-2*app.Ty(1:4:end,1:4:end,app.sliderFrame2),'r','AutoScale','off');
            elseif app.shownow==7
                app.UIAxes_2.YDir='reverse';
                length_x = size(app.Dx,1);
                length_y = size(app.Dx,2);
                [PosX,PosY]=meshgrid(1:length_y,1:length_x);
                %% Displacement video
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                colorbar(app.UIAxes_2,'off')
                max_disp=max(app.Dx,[],'all');
                displacement = sqrt(app.Dx(:,:,app.sliderFrame2).^2+app.Dy(:,:,app.sliderFrame2).^2);
                imagesc(app.UIAxes_2,displacement),colormap(app.UIAxes_2,app.blackcmap),colorbar(app.UIAxes_2), caxis(app.UIAxes_2,[0 max_disp]), set(app.UIAxes_2,'Visible','off'), daspect(app.UIAxes_2,[1 1 1]), %.*BWgrid
                hold(app.UIAxes_2,"on");
                xlim(app.UIAxes_2,[1 size(displacement,2)])
                ylim(app.UIAxes_2,[1 size(displacement,1)])
                quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),20e6*app.Dx(1:4:end,1:4:end,app.sliderFrame2),20e6*app.Dy(1:4:end,1:4:end,app.sliderFrame2),'r','AutoScale','off');
                
            end
        end

        % Button pushed function: 
        % MakeStressMagnitudeandDisplacementsMovieButton
        function MakeStressMagnitudeandDisplacementsMovieButtonPushed(app, event)
            app.UIAxes_2.YDir='reverse';
            %% define video parameters
            length_video = size(app.Dx,3);
            length_x = size(app.Dx,1);
            length_y = size(app.Dx,2);
            [PosX,PosY]=meshgrid(1:length_y,1:length_x);
            %% Displacement video
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            app.UIAxes_2.YDir='reverse';
            colorbar(app.UIAxes_2,'off')
            max_disp=max(app.Dx,[],'all');
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_2,1,1,'Exporting Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:length_video
                displacement = sqrt(app.Dx(:,:,i).^2+app.Dy(:,:,i).^2);
                imagesc(app.UIAxes_2,displacement),colormap(app.UIAxes_2,app.blackcmap),colorbar(app.UIAxes_2), caxis(app.UIAxes_2,[0 max_disp]), set(app.UIAxes_2,'Visible','off'), daspect(app.UIAxes_2,[1 1 1]), %.*BWgrid
                hold(app.UIAxes_2,"on");
                xlim(app.UIAxes_2,[1 size(displacement,2)])
                ylim(app.UIAxes_2,[1 size(displacement,1)])
                if i == 1
                    quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),20e6*app.Dx(1:4:end,1:4:end,i),20e6*app.Dy(1:4:end,1:4:end,i),'r','AutoScale','off');
                else
                    quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),20e6*app.Dx(1:4:end,1:4:end,i),20e6*app.Dy(1:4:end,1:4:end,i),'r','AutoScale','off');
                end
                
                if ~exist(fullfile(app.path3,'movies'),'dir')
                    mkdir(fullfile(app.path3,'movies'))
                end
                
                figurepath=cat(2,app.path3,'\movies\displacement',num2str(i),'.tif');
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\movies\displacement.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 i/length_video i/length_video 0];
                th.String = sprintf('Exporting Movie...%.0f%%',round(i/length_video*100));
                drawnow %update graphics
            end
            %% Traction force video
            max_traction=max(app.Tx,[],'all');
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            app.UIAxes_2.YDir='reverse';
            colorbar(app.UIAxes_2,'off')
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_2,1,1,'Exporting Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:length_video
                traction = sqrt(app.Tx(:,:,i).^2+app.Ty(:,:,i).^2);
                imagesc(app.UIAxes_2,traction),colormap(app.UIAxes_2,jet),colorbar(app.UIAxes_2), caxis(app.UIAxes_2,[0 max_traction]), set(app.UIAxes_2,'Visible','off');, daspect(app.UIAxes_2,[1 1 1]), %.*BWgrid
                hold(app.UIAxes_2,"on");
                xlim(app.UIAxes_2,[1 size(traction,2)])
                ylim(app.UIAxes_2,[1 size(traction,1)])
                if i == 1
                    quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),2e-2*app.Tx(1:4:end,1:4:end,i),2e-2*app.Ty(1:4:end,1:4:end,i),'r','AutoScale','off');
                else
                    quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),2e-2*app.Tx(1:4:end,1:4:end,i),2e-2*app.Ty(1:4:end,1:4:end,i),'r','AutoScale','off');
                end
                if ~exist(fullfile(app.path3,'movies'),'dir')
                    mkdir(fullfile(app.path3,'movies'))
                end
                
                figurepath=cat(2,app.path3,'\movies\traction',num2str(i),'.tif');
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\movies\traction.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 i/length_video i/length_video 0];
                th.String = sprintf('Exporting Movie...%.0f%%',round(i/length_video*100));
                drawnow %update graphics
            end
            
        end

        % Button pushed function: RunTFMandSaveAllDataButton
        function RunTFMandSaveAllDataButtonPushed(app, event)
            Nb_frames=size(app.cellule,3);
            [n,s,p_corr,regx,regy]=registration_ssinterp(app.nonstressed,app.stressed(:,:,1),app.cellule(:,:,1));
            [dimy,dimx]=size(n);
            period=app.window-app.overlapPIV;
            nbx=floor((dimx-app.window)/period)+1;
            nby=floor((dimy-app.window)/period)+1;
            [posx,posy]=meshgrid((0:nbx-1)*period+floor((dimx-(nbx-1)*period)/2)+1,(0:nby-1)*period+floor((dimy-(nby-1)*period)/2)+1);
            nodeX=floor(period*nbx/app.interval);
            nodeY=floor(period*nby/app.interval);
            [gridX,gridY]=meshgrid((0:nodeX-1)*app.interval+floor((dimx-nbx*period)/2)+1+app.interval/2,(0:nodeY-1)*app.interval+floor((dimy-nby*period)/2)+1+app.interval/2);
            app.gridX=gridX;
            app.gridY=gridY;
            app.TractionX=[];
            app.TractionY=[];
            app.bf=[];
            app.Mask1=false([size(n) Nb_frames]);
            % Mask2=false([size(n) Nb_frames]);
            app.Dx=zeros([size(gridX) Nb_frames]);
            app.Dy=zeros([size(gridX) Nb_frames]);
            app.Tx=zeros([size(gridX) Nb_frames]);
            app.Ty=zeros([size(gridX) Nb_frames]);
            app.Brightfield=uint8(zeros([size(n) Nb_frames]));
            %Calculated global parameters
            % Pour le doublet de cellules
            MOY2cells=zeros(1,Nb_frames);% contrainte moyenne
            Total2cells=zeros(1,Nb_frames);% Somme en norme des tractions cellules-substrat
            Vect2cells=zeros(1,Nb_frames);% somme vectorielle des tractions cellules-subtrat: a l'�quilibre, devrait etre egale a zero (systeme isole)
            Ec2cells=zeros(1,Nb_frames);%?nergie contractile
            %Fcellcell=zeros(1,Nb_frames);% force cellule-cellule
            %DFcellcell=zeros(1,Nb_frames);% estimation de l'incertitude sur force cellule-cellule
            Moment=zeros(1,Nb_frames);%1st order moment of the traction distribution
            Angle=zeros(1,Nb_frames);% angle d'orientation principale par rapport � la verticale
            Polar=zeros(1,Nb_frames);%polarization degree, calculated from the eigenvalues of traction 1st order moment (l1-l2)/(l1+l2)
            %Pour chaque cellule du doublet
            MOYcell1=zeros(1,Nb_frames);% contrainte moyenne
            %  MOYcell2=zeros(1,Nb_frames);
            Tmaxcell1=zeros(1,Nb_frames);%maximum stress
            %  Tmaxcell2=zeros(1,Nb_frames);%maximum stress
            Totalcell1=zeros(1,Nb_frames);% Somme en norme des tractions cellule1-substrat
            %  Totalcell2=zeros(1,Nb_frames);% Somme en norme des tractions cellule2-substrat
            Cell_ECMratio1=zeros(1,Nb_frames);%rapport Fcell-cell/Fcell-ECM pour cellule 1 =Totalcell1/Fcellcell
            %   Cell_ECMratio2=zeros(1,Nb_frames);%rapport Fcell-cell/Fcell-ECM pour cellule 2
            Momentcell1=zeros(1,Nb_frames);% moment contractile net cell1
            %  Momentcell2=zeros(1,Nb_frames);% moment contractile net cell2
            %Parametres rajoutes (version Artur)
            Forcex_cell1=zeros(1,Nb_frames);%sum of x component of forces (in magnitude) for cell 1
            %  Forcey_cell1=zeros(1,Nb_frames);%sum of y component of forces (in magnitude) for cell 1
            Forcex_cell2=zeros(1,Nb_frames);%sum of x component of forces (in magnitude) for cell 2
            %  Forcey_cell2=zeros(1,Nb_frames);%sum of y component of forces (in magnitude) for cell 2
            Deplx_cell1=zeros(1,Nb_frames);%sum of x component of displacements (in magnitude) for cell 1
            Deply_cell1=zeros(1,Nb_frames);%sum of x component of displacements (in magnitude) for cell 1
            % Deplx_cell2=zeros(1,Nb_frames);%sum of x component of displacements (in magnitude) for cell 1
            % Deply_cell2=zeros(1,Nb_frames);%sum of x component of displacements (in magnitude) for cell 1
            
            %% initilise some files
            if ~exist(fullfile(app.path3,'figureTFM'),'dir')
                mkdir(fullfile(app.path3,'figureTFM'))
            end
            
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_2,1,1,'Performing TFM...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            %% do TFM
            for ff=1:Nb_frames
                disp(['Analyse: image ',num2str(ff)]);
                if app.stressed(:,:,ff)==0
                    disp('Null image!!!')
                    return
                end
                [n,s,p_corr,m1_corr,regx,regy]=registration_ssinterp_pattern(app.nonstressed,app.stressed(:,:,ff),app.cellule(:,:,ff),app.mask1(:,:,ff));
                restex=regx-round(regx);
                restey=regy-round(regy);
                if size(n,1)<dimy
                    temp=zeros(dimy-size(n,1),size(n,2));
                    n=[n;temp];
                    s=[s;temp];
                    mask1_corr=logical([m1_corr;temp]);
                    bf=[p_corr;temp];
                elseif size(n,1)>dimy
                    mask1_corr=logical(m1_corr(1:dimy,:));
                    bf=p_corr(1:dimy,:);
                else
                    mask1_corr=logical(m1_corr);
                    bf=p_corr;
                end
                if size(n,2)<dimx
                    temp=zeros(size(n,1),dimx-size(n,2));
                    n=[n temp];
                    s=[s temp];
                    tempmask=false(size(mask1_corr,1),dimx-size(mask1_corr,2));
                    mask1_corr=[mask1_corr tempmask];
                    bf=[bf uint16(tempmask)];
                elseif size(n,2)>dimx
                    mask1_corr=mask1_corr(:,1:dimx);
                    bf=bf(:,1:dimx);
                end
                app.bf(:,:,ff)=bf;
                mask_corr=mask1_corr;
                deplaceX=zeros(10000,1);
                deplaceY=zeros(10000,1);
                positionX=zeros(10000,1);
                positionY=zeros(10000,1);
                compteur=0;
                mauvaises_fenetres=0;
                [M,N]=size(s);
                
                disp(['Number of ROIs: ',num2str(nby*nbx)]);
                for i=1:nbx
                    for j=1:nby
                        mauvais_track=false;
                        %nombre de fen�tres
                        Nwin=2.^(2*(0:app.iter));
                        %tailles des fen�tres
                        mi=app.window./(2.^(0:app.iter));
                        %tableau de positions (centre de fen�tres): � chaque �tape le nb de positions est
                        %multipli� par 4
                        posix=cell(1,app.iter+1);
                        posiy=cell(1,app.iter+1);
                        posix{1}=posx(1,i);
                        posiy{1}=posy(j,1);
                        % tableau d'offsets
                        offsetx=cell(1,app.iter+1);
                        offsety=cell(1,app.iter+1);
                        offsetx{1}=0;
                        offsety{1}=0;
                        for k=0:app.iter
                            %d�finit les offsets et les positions pour chaque it�ration
                            offsetx{k+1}=zeros(2^k);
                            offsety{k+1}=zeros(2^k);
                            %recopie les valeurs d'avant
                            for l=1:2^(k-1)
                                for m=1:2^(k-1)
                                    offsetx{k+1}((l-1)*2+1:l*2,(m-1)*2+1:m*2)=offsetx{k}(l,m)*ones(2);
                                    offsety{k+1}((l-1)*2+1:l*2,(m-1)*2+1:m*2)=offsety{k}(l,m)*ones(2);
                                    
                                end
                            end
                            [px,py]=meshgrid((-mi(k+1)/2*(2^k-1):mi(k+1):mi(k+1)/2*(2^k-1)),(-mi(k+1)/2*(2^k-1):mi(k+1):mi(k+1)/2*(2^k-1)));
                            posix{k+1}=posx(1,i)+px;
                            posiy{k+1}=posy(j,1)+py;
                            for nw=1:Nwin(k+1)
                                [sub1,sub2]=ind2sub([2^k 2^k],nw);
                                %1. interpolation de n
                                if k>0
                                    xi=(1:mi(k+1))-offsetx{k+1}(sub1,sub2)+posix{k+1}(sub1,sub2)-mi(k+1)/2-1;
                                    yi=(1:mi(k+1))-offsety{k+1}(sub1,sub2)+posiy{k+1}(sub1,sub2)-mi(k+1)/2-1;
                                    n_win = interp2(double(n),xi',yi);
                                else
                                    n_win=n(posiy{k+1}(sub1,sub2)-mi(k+1)/2:posiy{k+1}(sub1,sub2)+mi(k+1)/2-1,posix{k+1}(sub1,sub2)-mi(k+1)/2:posix{k+1}(sub1,sub2)+mi(k+1)/2-1);
                                end
                                %2. mesure du d�calage
                                s_win=s(posiy{k+1}(sub1,sub2)-mi(k+1)/2:posiy{k+1}(sub1,sub2)+mi(k+1)/2-1,posix{k+1}(sub1,sub2)-mi(k+1)/2:posix{k+1}(sub1,sub2)+mi(k+1)/2-1);
                                [offx,offy,Cmax]=decalage(s_win,n_win);
                                deltaI=min((max(n_win(:))-min(n_win(:)))/mean(n_win(:)),(max(s_win(:))-min(s_win(:)))/mean(s_win(:)));
                                if deltaI<5||Cmax<0.5%||sqrt(offx^2+offy^2)>mi(k+1)/3
                                    offx=0;
                                    offy=0;
                                end
                                %remplissage du tableau de valeurs
                                if k==0
                                    offsetx{1}=offx;
                                    offsety{1}=offy;
                                else
                                    offsetx{k+1}(sub1,sub2)=offx+ offsetx{k}(ceil(sub1/2),ceil(sub2/2));
                                    offsety{k+1}(sub1,sub2)=offy+ offsety{k}(ceil(sub1/2),ceil(sub2/2));
                                end
                                %apr�s la derni�re it�ration, faire le tracking pour chaque
                                %fenetre �largie
                                if k==app.iter
                                    % interpolation de n
                                    win=mi(k+1)+app.overlapTrack;
                                    ystart=posiy{k+1}(sub1,sub2)-win/2;
                                    xstart=posix{k+1}(sub1,sub2)-win/2;
                                    yend=posiy{k+1}(sub1,sub2)+win/2-1;
                                    xend=posix{k+1}(sub1,sub2)+win/2-1;
                                    if ystart<1, ystart=1;end
                                    if xstart<1, xstart=1;end
                                    if yend>M, yend=M;end
                                    if xend>N, xend=N;end
                                    xi=(1:win)-offsetx{k+1}(sub1,sub2)+xstart-1;
                                    yi=(1:win)-offsety{k+1}(sub1,sub2)+ystart-1;
                                    n_interp = interp2(double(n),xi',yi);
                                    s_win=s(ystart:yend,xstart:xend);
                                    %tracking
                                    mauvais_track=0;
                                    if strcmp(app.cl,'uint16')
                                        %n_win=uint16(n_interp);
                                        n_win= uint16(65535/(max(n_interp(:))-min(n_interp(:)))*(n_interp-min(n_interp(:))));
                                        s_win= uint16(65535/double(max(s_win(:))-min(s_win(:)))*double(s_win-min(s_win(:))));
                                    elseif strcmp(app.cl,'uint8')
                                        n_win= uint8(255/(max(n_interp(:))-min(n_interp(:)))*(n_interp-min(n_interp(:))));
                                        s_win= uint8(255/double(max(s_win(:))-min(s_win(:)))*double(s_win-min(s_win(:))));%n_win=uint8(n_interp);
                                    end
                                    %Tracking sur les petites fen?tres
                                    nM = feature2DTFM(double(n_win),1,app.featsize,app.masscut);
                                    %figure(5),imshow(n_win,[]), hold on, plot(nM(:,1),nM(:,2),'go'), hold off
                                    if numel(nM)==1||isempty(nM)
                                        %                                         figure(16)
                                        %                                         imshow(n_win,[])
                                        mauvais_track=true;
                                    else
                                        %Rejection process
                                        X= nM(:,5)>app.barcc;
                                        nM(X,1:5)=0;
                                        X= nM(:,4)>app.barrg;
                                        nM(X,1:5)=0;
                                        X= nM(:,3)./nM(:,4)<app.IdivRg;
                                        nM(X,1:5)=0;
                                        nM=nM(nM(:,1)~=0,:);
                                        if numel(nM)==1||isempty(nM)
                                            %                                             figure(16)
                                            %                                             imshow(n_win,[])
                                            mauvais_track=true;
                                        end
                                    end
                                    sM = feature2DTFM(double(s_win),1,app.featsize,app.masscut);
                                    if numel(sM)==1||isempty(sM)
                                        %                                         figure(16)
                                        %                                         imshow(s_win,[])
                                        mauvais_track=true;
                                    else
                                        %Rejection process
                                        X= sM(:,5)>app.barcc;
                                        sM(X,1:5)=0;
                                        X= sM(:,4)>app.barrg;
                                        sM(X,1:5)=0;
                                        X= sM(:,3)./sM(:,4)<app.IdivRg;
                                        sM(X,1:5)=0;
                                        sM=sM(sM(:,1)~=0,:);
                                        if numel(sM)==1||isempty(sM)
                                            %                                             figure(16)
                                            %                                             imshow(s_win,[])
                                            mauvais_track=true;
                                        end
                                    end
                                    if not(mauvais_track)
                                        Mtrack=[nM(:,1:2),ones(size(nM,1),1),ones(size(nM,1),1); sM(:,1:2),2*ones(size(sM,1),1),2*ones(size(sM,1),1)];
                                        [lub] = trackmem(Mtrack,app.maxd,2,app.goodenough,app.memory);
                                        if lub==-1
                                            %                                             figure(15)
                                            %                                             ax2(1,2,1), imshow(s_win,[]), hold on, plot(sM(:,1),sM(:,2),'go'), hold off
                                            %                                             ax2(1,2,2), imshow(n_win,[]), hold on, plot(nM(:,1),nM(:,2),'go'), hold off
                                            mauvais_track=true;
                                        end
                                    end
                                    if mauvais_track
                                        mauvaises_fenetres=mauvaises_fenetres+1;
                                    else
                                        nb_part=max(lub(:,5));
                                        
                                        deplaceX(compteur+1:compteur+nb_part)=lub((lub(:,4)==2),1)-lub((lub(:,4)==1),1)+offsetx{k+1}(sub1,sub2)+restex;
                                        deplaceY(compteur+1:compteur+nb_part)=lub((lub(:,4)==2),2)-lub((lub(:,4)==1),2)+offsety{k+1}(sub1,sub2)+restey;
                                        positionX(compteur+1:compteur+nb_part)=lub((lub(:,4)==2),1)+(posix{k+1}(sub1,sub2)-win/2)-1;
                                        positionY(compteur+1:compteur+nb_part)=lub((lub(:,4)==2),2)+(posiy{k+1}(sub1,sub2)-win/2)-1;
                                        compteur=compteur+nb_part;
                                    end
                                end
                            end
                        end
                    end
                end
                disp([num2str(compteur-mauvaises_fenetres) ' features tracked']);
                disp([num2str(mauvaises_fenetres) ' bad ROIs']);
                deplaceX(compteur+1:10000)=[];
                deplaceY(compteur+1:10000)=[];
                positionX(compteur+1:10000)=[];
                positionY(compteur+1:10000)=[];
                %remove duplicates
                Mpos=[positionX positionY];
                [A,ind]=sortrows(Mpos);
                deplaceX=deplaceX(ind);
                deplaceY=deplaceY(ind);
                tolerance=2;
                diff=A(1:end-1,:)-A(2:end,:);
                duplicate=find((diff(:,1).^2+diff(:,2).^2)<tolerance);
                A(duplicate,:)=[];
                positionX=A(:,1);
                positionY=A(:,2);
                deplaceX(duplicate)=[];
                deplaceY(duplicate)=[];
                disp(['Tracked Particles (non duplicated): ', num2str(length(deplaceX))])
                nb_beads = size(positionX);
                deplaceXh = deplaceX;
                deplaceYh = deplaceY;
                for i = 1:nb_beads
                    index_neighbors = find(sqrt((positionX-positionX(i)).^2+(positionY-positionY(i)).^2)<app.r_neighbor);
                    allbutone = find(index_neighbors~=i);
                    index_neighbors = index_neighbors(allbutone);
                    r_corr = mean((deplaceXh(i)*deplaceXh(index_neighbors)+deplaceYh(i)*deplaceYh(index_neighbors))./(sqrt(deplaceXh(i)^2+deplaceYh(i)^2)*sqrt(deplaceXh(index_neighbors).^2+deplaceYh(index_neighbors).^2)));
                    if r_corr < app.corr_th
                        deplaceX(i) = mean(deplaceX(index_neighbors));
                        deplaceY(i) = mean(deplaceY(index_neighbors));
                    end
                end
                % Plot and save Tracking
                Idisp=zeros([size(s) 3]);
                sn=double(s)/double(max(s(:)));
                nn=double(n)/double(max(n(:)));
                Idisp(:,:,1)=nn;
                Idisp(:,:,2)=sn;
                Idisp(:,:,3)=nn;
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                imagesc(app.UIAxes_2,Idisp),colormap(app.UIAxes_2,gray)
                title(app.UIAxes_2,['Image  ',num2str(ff),' out of ', num2str(Nb_frames)]);
                hold(app.UIAxes_2,'on')
                quiver(app.UIAxes_2,positionX,positionY,deplaceX,deplaceY,'r');
                hold(app.UIAxes_2,'off')
                drawnow
                figurepath=cat(2,app.path3,'\figureTFM\Tracking',num2str(ff),'.tif');
                %                 f = figure('Visible', false);
                %                 axNew = axes(f);
                %                 myCopyObj(app.UIAxes_2, axNew);
                %                 print(f, fullfile(figurepath),'-dtiff','-r100');
                %                 delete(f);
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\figureTFM\Tracking.tif'),'WriteMode','append');
                delete (figurepath)
                %% Displacements
                deplacementXa=app.pix*griddata(positionX,positionY,deplaceX,app.gridX,app.gridY);
                deplacementYa=app.pix*griddata(positionX,positionY,deplaceY,app.gridX,app.gridY);
                deplacementXa(isnan(deplacementXa))=0;
                deplacementYa(isnan(deplacementYa))=0;
                %smoothing
                %wsmoothing=2;
                [x,y]=meshgrid((-4*app.wsmoothing:4*app.wsmoothing),(-4*app.wsmoothing:4*app.wsmoothing));
                Gauss=exp(-2*(x.^2+y.^2)/app.wsmoothing^2);
                h=Gauss/sum(Gauss(:));
                deplacementX=imfilter(deplacementXa,h);
                deplacementY=imfilter(deplacementYa,h);
                deplacement = sqrt(deplacementX.^2+deplacementY.^2);
                %% Traction
                [Tractionx,Tractiony,mu,theta]=regularized(deplacementX,deplacementY,app.E,app.nu,app.pas,app.alphadef);
                app.TractionX{ff}=Tractionx;
                app.TractionY{ff}=Tractiony;
                Tmagn=sqrt(Tractionx.^2+Tractiony.^2);
                %% Calculs sous le doublet de cellules
                disp('---- Computation for cell pair -----')
                SE = strel('disk',1);
                BWpattern=imdilate(mask_corr,SE);
                app.BWgrid=logical(BWpattern(app.gridY(:,1),app.gridX(1,:)));
                Txcrop=Tractionx.*app.BWgrid;
                Tycrop=Tractiony.*app.BWgrid;
                %calcul de l'?nergie contractile
                u=Txcrop.*deplacementX+Tycrop.*deplacementY;  %produit scalaire traction par app.deplacement
                U=app.pas^2/2*sum(u(:));
                disp(['contractile energy: ',num2str(U), ' J']);
                Ec2cells(ff)=U;
                % image de traction map avec decoupe du pattern
                Tmagn2=sqrt(Txcrop.^2+Tycrop.^2);
                % contrainte moyenne sous le doublet
                TmagnCROPMOY=sum(Tmagn2(:))/bwarea(app.BWgrid);
                disp(['mean stress: ',  num2str(TmagnCROPMOY),' Pa']);
                MOY2cells(ff)=TmagnCROPMOY;
                %Traction totale sous les 2 cellules
                Sgrid = app.pas^2;%surface d'une maille en metre au carr?
                TmagnCROPTOT=sum(Tmagn2(:))*Sgrid;% somme des forces de traction en norme
                disp(['Total of magnitude of tractions: ',  num2str(TmagnCROPTOT),' N']);
                Total2cells(ff)=TmagnCROPTOT;
                %Somme vectorielle (syst?me isol? ? l'?quilibre: devrait =0)
                Vect2cells(ff)=sqrt((sum(Txcrop(:)))^2+(sum(Tycrop(:)))^2)*Sgrid;
                unbalance=Vect2cells(ff)/TmagnCROPTOT;
                disp(['Percentage of non-equilibrium forces: ',  num2str(unbalance*100),' %']);
                %Main contraction direction for the cell doublet (it is not valid to
                %calculat it on individual cells since they are not at equilibrium)
                %1.clean the mask
                s=regionprops(mask_corr,'Area','Centroid');
                [~,ind]=max([s.Area]);
                x0=s(ind).Centroid(:,1);
                y0=s(ind).Centroid(:,2);
                Mxx=sum(sum((app.gridX-x0).*Txcrop))*app.pix*Sgrid^2;
                Mxy=sum(sum((app.gridX-x0).*Tycrop))*app.pix*Sgrid^2;
                Myx=sum(sum((app.gridY-y0).*Txcrop))*app.pix*Sgrid^2;
                Myy=sum(sum((app.gridY-y0).*Tycrop))*app.pix*Sgrid^2;
                M=[Mxx -Mxy;-Myx Myy];
                Moment(ff)=trace(M);
                [V,D]=eig(M);
                if isreal(D)
                    DD=max(-D);
                    %on identifie la direction principale (indice ind)
                    [Dmax, ind]=max(DD);
                    ang=180/pi*atan(real(V(2,ind))/real(V(1,ind)));
                    %angle par rapport la verticale
                    if ang>=0
                        Angle(ff)=90-ang;
                    else
                        Angle(ff)= -90-ang;
                    end
                    L1=D(ind,ind);
                    %autre direction
                    autre=mod(ind,2)+1;
                    L2=D(autre, autre);
                else% non diagonalizable
                    Mxyn=(Mxy+Myx)/2;
                    Myxn=Mxyn;
                    M=[Mxx -Mxyn;-Myxn Myy];
                    [V,D]=eig(M);
                    DD=max(-D);
                    %on identifie la direction principale (indice ind)
                    [Dmax, ind]=max(DD);
                    ang=180/pi*atan(real(V(2,ind))/real(V(1,ind)));
                    %angle par rapport la verticale
                    if ang>=0
                        Angle(ff)=90-ang;
                    else
                        Angle(ff)= -90-ang;
                    end
                    L1=DD(ind);
                    %autre direction
                    autre=mod(ind,2)+1;
                    L2=DD(autre);
                end
                Polar(ff)=(L1-L2)/(L1+L2);
                disp(['Angle of main contraction compared to the vertical: ',  num2str(Angle(ff))]);
                disp(['Polarisation degree of the cell doublet (0=isotropic,1=uniaxial): ',  num2str(Polar(ff))]);
                %                 %% Calculs pour chaque cellule du doublet et force cell-cell
                disp('---- Computation over individual cells-----')
                BW=mask1_corr;
                BWc1=imdilate(BW,SE);
                BWgrid1=logical(BWc1(app.gridY(:,1),app.gridX(1,:)));
                %                 BW=mask2_corr;
                %                 BWc2=imdilate(BW,SE);
                %                 BWgrid2=logical(BWc2(gridY(:,1),gridX(1,:)));
                Txcrop1=Tractionx.*BWgrid1;
                Tycrop1=Tractiony.*BWgrid1;
                %                 Txcrop2=Tractionx.*BWgrid2;
                %                 Tycrop2=Tractiony.*BWgrid2;
                %
                %
                Tnorm=sqrt(Txcrop1.^2+Tycrop1.^2);
                MOYcell1(ff)=sum(Tnorm(:))/bwarea(BWgrid1);% contrainte moyenne
                Totalcell1(ff)=sum(Tnorm(:))*Sgrid;% somme des forces de traction en norme
                %Maximum stress
                ntot=bwarea(BWgrid1);
                thres=max(Tnorm(:));
                while numel(find(Tnorm>=thres))<0.05*ntot
                    thres=thres-20;
                end
                Tmaxcell1(ff)=mean(Tnorm(find(Tnorm>=thres)));
                %
                %                 Tnorm=sqrt(Txcrop2.^2+Tycrop2.^2);
                %                 MOYcell2(ff)=sum(Tnorm(:))/bwarea(BWgrid2);
                %                 Totalcell2(ff)=sum(Tnorm(:))*Sgrid;% somme des forces de traction en norme
                %                 %Maximum stress
                %                 ntot=bwarea(BWgrid2);
                %                 thres=max(Tnorm(:));
                %                 while numel(find(Tnorm>=thres))<0.05*ntot
                %                     thres=thres-20;
                %                 end
                %                 Tmaxcell2(ff)=mean(Tnorm(find(Tnorm>=thres)));
                %
                disp(['Mean constraint under cell 1: ',  num2str(MOYcell1(ff)),' Pa']);
                %                 disp(['Contrainte moyenne sous cellule 2: ',  num2str(MOYcell2(ff)),' Pa']);
                %
                disp(['Max constraint under cell 1: ',  num2str(Tmaxcell1(ff)),' Pa']);
                %                 disp(['Contrainte max sous cellule 2: ',  num2str(Tmaxcell2(ff)),' Pa']);
                %
                disp(['Total of magnitude of tration under cell 1: ',  num2str(Totalcell1(ff)),' N']);
                %                 disp(['Total des tractions en norme sous cellule 2: ',  num2str(Totalcell2(ff)),' N']);
                %
                %                 disp('---- Force cellule-cellule -----')
                %                 Fcellcell(ff)=sqrt((sum(Txcrop1(:))-sum(Txcrop2(:)))^2+(sum(Tycrop1(:))-sum(Tycrop2(:)))^2)*Sgrid/2;
                %                 disp(['Force cellule-cellule : ',  num2str(Fcellcell(ff)),' N']);
                %                 DFcellcell(ff)=sqrt((sum(Txcrop1(:))+sum(Txcrop2(:)))^2+(sum(Tycrop1(:))+sum(Tycrop2(:)))^2)*Sgrid/2;
                %                 disp(['Incertitude : ',  num2str(DFcellcell(ff)),' N']);
                Cell_ECMratio1(ff)=sqrt((sum(Txcrop1(:)))^2+(sum(Tycrop1(:)))^2)*Sgrid/Totalcell1(ff);
                %                 Cell_ECMratio2(ff)=sqrt((sum(Txcrop2(:)))^2+(sum(Tycrop2(:)))^2)*Sgrid/Totalcell2(ff);
                disp(['Rapport F cell-cell/F cell-ECM pour cellule 1: ',  num2str(Cell_ECMratio1(ff)*100),' %']);
                %                 disp(['Rapport F cell-cell/F cell-ECM pour cellule 2: ',  num2str(Cell_ECMratio2(ff)*100),' %']);
                %                 %Calcul des moments
                s=regionprops(mask1_corr,'Area','Centroid');
                [~,ind]=max([s.Area]);
                x1=s(ind).Centroid(:,1);
                y1=s(ind).Centroid(:,2);
                %                 s=regionprops(mask2_corr,'Area','Centroid');
                %                 [~,ind]=max([s.Area]);
                %                 x2=s(ind).Centroid(:,1);
                %                 y2=s(ind).Centroid(:,2);
                Mxx1=sum(sum((app.gridX-x1).*Txcrop1))*app.pix*Sgrid;
                %                 Mxx2=sum(sum((gridX-x2).*Txcrop2))*pix*Sgrid;
                Myy1=sum(sum((app.gridY-y1).*Tycrop1))*app.pix*Sgrid;
                %                 Myy2=sum(sum((gridY-y2).*Tycrop2))*pix*Sgrid;
                Mxy1=sum(sum((app.gridX-x1).*Tycrop1))*app.pix*Sgrid;
                %                 Mxy2=sum(sum((gridX-x1).*Tycrop2))*pix*Sgrid;
                Myx1=sum(sum((app.gridY-y1).*Txcrop1))*app.pix*Sgrid;
                %                 Myx2=sum(sum((gridY-y2).*Txcrop2))*pix*Sgrid;
                M1=[Mxx1 -Mxy1;-Myx1 Myy1];
                %                 M2=[Mxx2 -Mxy2;-Myx2 Myy2];
                %
                %                 %TBD
                Momentcell1(ff)=trace(M1);
                %                 Momentcell2(ff)=trace(M2);
                disp(['contractile moment cell 1: ',  num2str(Momentcell1(ff)),' N.m']);
                %                 disp(['contractile moment cell 2: ',  num2str(Momentcell2(ff)),' N.m']);
                %
                Forcex_cell1(ff)=sum(abs(Txcrop1(:)))*Sgrid;%sum of x component of forces (in magnitude) for cell 1
                Forcey_cell1(ff)=sum(abs(Tycrop1(:)))*Sgrid;%sum of y component of forces (in magnitude) for cell 1
                %                 Forcex_cell2(ff)=sum(abs(Txcrop2(:)))*Sgrid;%sum of x component of forces (in magnitude) for cell 2
                %                 Forcey_cell2(ff)=sum(abs(Tycrop2(:)))*Sgrid;%sum of y component of forces (in magnitude) for cell 2
                Deplcropx1=deplacementX.*BWgrid1;
                Deplcropy1=deplacementY.*BWgrid1;
                %                 Deplcropx2=app.deplacementX.*BWgrid2;
                %                 Deplcropy2=app.deplacementY.*BWgrid2;
                Deplx_cell1(ff)=sum(abs(Deplcropx1(:)));%sum of x component of displacements (in magnitude) for cell 1
                Deply_cell1(ff)=sum(abs(Deplcropy1(:)));%sum of x component of displacements (in magnitude) for cell 1
                %                 Deplx_cell2(ff)=sum(abs(Deplcropx2(:)));%sum of x component of displacements (in magnitude) for cell 1
                %                 Deply_cell2(ff)=sum(abs(Deplcropy2(:)));%sum of x component of displacements (in magnitude) for cell 1
                
                %%
                %save (fullfile(path,[filename(1:end-4),'_results', num2str(ff),'.mat']),'p_corr','BW','BWgrid','app.deplacement*','Traction*','eigenvalue*');
                %cellule(1:size(p_corr,1),1:size(p_corr,2),ff)=p_corr;
                app.Brightfield(:,:,ff)=uint8(round(double(bf-min(bf(:)))/double(max(bf(:))-min(bf(:)))*255));
                app.Mask1(:,:,ff)=mask1_corr;
                % app.Mask2(:,:,ff)=mask2_corr;
                app.Dx(:,:,ff)=deplacementX;
                app.Dy(:,:,ff)=deplacementY;
                app.Tx(:,:,ff)=Tractionx;
                app.Ty(:,:,ff)=Tractiony;
                
                
                %caculate contractile Energy in fJ
                EcfJ= Ec2cells.*10^15;
                %calculate mean values
                meanEc = mean(Ec2cells, 'all');
                meanEcfJ = mean(EcfJ, 'all');
                meanPolar = mean(Polar, 'all');
                meanMOY2cells = mean(MOY2cells, 'all');
                meanMoment = mean(Moment, 'all');
                
                ph.XData = [0 ff/Nb_frames ff/Nb_frames 0];
                th.String = sprintf('Performing TFM...%.0f%%',round(ff/Nb_frames*100));
                drawnow %update graphics
            end
            
            % export data
            Dx=app.Dx;
            Dy=app.Dy;
            Tx=app.Tx;
            Ty=app.Ty;
            Mask1=app.Mask1;
            Brightfield=app.Brightfield;
            save (fullfile(app.path3,'Allresults2.mat'),'Mask*','Dx','Dy','Tx','Ty','grid*','Brightfield');
            
            %Save calculated parameters
            fid=fopen(fullfile(app.path3,'Resultats2cells.txt'),'w+');
            fprintf(fid,['Ec \t Pmoy \t Ttot \t  T_imbalance \t Moment \t Angle \t Polar degree \t Pmoy-cell1 \t Pmax_cell1 \t' ...
                'Pmoy-cell2 \t T-cell1  \t Rcell1-ECM  \t Mmt-cell1  \t Force x cell1 \t Force y cell1' ...
                '\t Displ x cell1 \t Displ y cell1  \n']);
            fclose(fid);
            dlmwrite(fullfile(app.path3,'Results2cells.txt'),[Ec2cells',MOY2cells',Total2cells',Vect2cells',Moment',Angle',Polar', ...
                MOYcell1',Tmaxcell1',Totalcell1',Cell_ECMratio1',Momentcell1',Forcex_cell1',...
                Forcey_cell1',Deplx_cell1',Deply_cell1'],'-append','delimiter','\t')
            disp('Results saved in: ')
            disp(fullfile(app.path3,'Results2cells.txt'))
            % Export to Excel
            T = table(Ec2cells', EcfJ', MOY2cells', Moment', Polar', (Vect2cells./Total2cells*100)', 'VariableNames', {'contractile energy (J)','contractile energy (fJ)','average stress (Pa)','Moment','Polar','Out of Equilibrium'});
            X = table(meanEc, meanEcfJ, meanMOY2cells ,meanMoment, meanPolar );
            writetable(T,fullfile(app.path3,'results.xlsx'),'Sheet',1,'Range','A1','WriteVariableNames', true);
            writetable(X,fullfile(app.path3,'results.xlsx'),'Sheet',2,'Range','A1');
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            %graphs
            pos=(1:Nb_frames);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'average stress (Pa)','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(MOY2cells) nanmax(MOY2cells)])
            delX=max(pos)-min(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(MOY2cells))*(nanmax(MOY2cells)-nanmin(MOY2cells));
            app.UIAxes_2.YTick = [nanmin(MOY2cells):round(delY/3,1):nanmax(MOY2cells)];
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,MOY2cells,'o-'),title(app.UIAxes_2,'average stress (Pa)');
            exportgraphics(app.UIAxes_2,[app.path3 'AverageStress.png']);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'Contractile Moment (Nm)','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(Momentcell1) nanmax(Momentcell1)])
            delX=nanmax(pos)-nanmin(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(Momentcell1))*(nanmax(Momentcell1)-nanmin(Momentcell1));
            if sign(nanmin(Momentcell1))==1
            app.UIAxes_2.YTick = [nanmin(Momentcell1):delY/3:nanmax(Momentcell1)];
            elseif sign(nanmin(Momentcell1))==-1
            app.UIAxes_2.YTick = [nanmax(Momentcell1):delY/3:nanmin(Momentcell1)];
            end
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,Momentcell1,'-om'), hold(app.UIAxes_2,"on"),hold(app.UIAxes_2,"off");,title(app.UIAxes_2,'contractile moment (Nm)')
            exportgraphics(app.UIAxes_2,[app.path3 'ContractileMoment.png']);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            y=Vect2cells./Total2cells*100;
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'Out Of Equilibrium','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(y) nanmax(y)])
            delX=max(pos)-min(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(y))*(nanmax(y)-nanmin(y));
            app.UIAxes_2.YTick = [nanmin(y):round(delY/3,1):nanmax(y)];
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,Vect2cells./Total2cells*100,'d-g'),title(app.UIAxes_2,'Out of equilibrium');
            exportgraphics(app.UIAxes_2,[app.path3 'OutOfEquilibrium.png']);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            y=Ec2cells;
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'Contractile Energy (J)','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(y) nanmax(y)])
            delX=max(pos)-min(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(y))*(nanmax(y)-nanmin(y));
            app.UIAxes_2.YTick = [nanmin(y):delY/3:nanmax(y)];
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,Ec2cells, '--o'),title(app.UIAxes_2,'contractile energy (J)');
            exportgraphics(app.UIAxes_2,[app.path3 'ContractileEnergy.png']);
            cla(app.UIAxes_2)
            app.UIAxes_2.YDir='normal';
            y=Polar;
            colorbar(app.UIAxes_2,'off')
            xlabel(app.UIAxes_2,'Frames','FontSize',16)
            ylabel(app.UIAxes_2,'Degree Of Polarisation','FontSize',16)
            xlim(app.UIAxes_2,[min(pos) max(pos)])
            ylim(app.UIAxes_2,[nanmin(y) nanmax(y)])
            delX=max(pos)-min(pos);
            app.UIAxes_2.XTick = [min(pos):round(delX/3,1):max(pos)];
            delY=sign(nanmin(y))*(nanmax(y)-nanmin(y));
            app.UIAxes_2.YTick = [nanmin(y):round(delY/3,1):nanmax(y)];
            set(app.UIAxes_2,'FontSize',16)
            plot(app.UIAxes_2,pos,Polar,'-sc'),  title(app.UIAxes_2,'Degre of polarisation')
            exportgraphics(app.UIAxes_2,[app.path3 'DegreeOfPolarisation.png']);


            fid=fopen(fullfile(app.path3,[app.filebeads(1:end-4) 'Param.txt']),'w');
            fprintf(fid,'Registration (x direction): %g \n',regx);
            fprintf(fid,'Registration (y direction): %g \n',regy);
            fprintf(fid,'PIV window size: %g \n \n',app.window);
            fprintf(fid,'Number of PIV iterations (added to the 1st): %g \n \n',app.iter);
            fprintf(fid,'Overlap PIV: %g \n \n',app.overlapPIV);
            fprintf(fid,'Overlap tracking: %g \n \n',app.overlapTrack);
            
            fprintf(fid,'Tracking parameters: \n');
            fprintf(fid,'Feature size: %g \n',app.featsize);
            fprintf(fid,'Min intensity(masscut): %g\n',app.masscut);
            fprintf(fid,'Max radius: %g\n',app.barrg);
            fprintf(fid,'Max eccentricity: %g\n',app.barcc);
            fprintf(fid,'Min ratio intensity/radius: %4.1f\n',app.IdivRg);
            fprintf(fid,'Max displacement in pixels: %g\n\n',app.maxd);
            fprintf(fid,'Pixel size (in meter): %g\n',app.pix);
            fprintf(fid,'Data interval (for displacement and force) in pixels: %g\n',app.interval);
            fprintf(fid,'Young modulus (in Pa): %g\n',app.E);
            fprintf(fid,'Poisson ratio: %g\n',app.nu);
            fprintf(fid,'Regularization parameter: %g\n',app.alphadef);
            fclose(fid);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            app.UIAxes_2.YDir='reverse';
            colorbar(app.UIAxes_2,'off')
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_2,1,1,'Exporting Displacement Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for ff=1:size(app.Dx,3)
                deplacementX=app.Dx(:,:,ff);
                deplacementY=app.Dy(:,:,ff);
                deplacement = sqrt(deplacementX.^2+deplacementY.^2);
                imagesc(app.UIAxes_2,deplacement),colormap(app.UIAxes_2,app.blackcmap),colorbar(app.UIAxes_2),hold(app.UIAxes_2,"off"), daspect(app.UIAxes_2,[1 1 1]),
                xlim(app.UIAxes_2,[1 size(deplacement,2)])
                ylim(app.UIAxes_2,[1 size(deplacement,1)])
                title(app.UIAxes_2,['Displacement - Frame ',num2str(ff)]);
                figurepath=cat(2,app.path3,'\figureTFM\Displacement',num2str(ff),'.tif')
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\figureTFM\Displacement.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 ff/size(app.Dx,3) ff/size(app.Dx,3) 0];
                th.String = sprintf('Exporting Displacement Movie...%.0f%%',round(ff/size(app.Dx,3)*100));
                drawnow %update graphics
            end
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            app.UIAxes_2.YDir='reverse';
            colorbar(app.UIAxes_2,'off')
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_2,1,1,'Exporting BF Polar Degree Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for ff=1:size(app.Dx,3)
                imagesc(app.UIAxes_2,app.bf(:,:,ff));colormap(app.UIAxes_2,'gray')
                title(app.UIAxes_2,['Image ', num2str(ff)])
                hold(app.UIAxes_2,"on");
                quiver(app.UIAxes_2,app.gridX(1:2:end,1:2:end),app.gridY(1:2:end,1:2:end),8*app.TractionX{ff}(1:2:end,1:2:end),8*app.TractionY{ff}(1:2:end,1:2:end),2,'r','AutoScale','off')
                hold(app.UIAxes_2,"off");
                xlim(app.UIAxes_2,[1 size(app.bf,2)])
                ylim(app.UIAxes_2,[1 size(app.bf,1)])
                figurepath=cat(2,app.path3,'\figureTFM\BF_polar_degree',num2str(ff),'.tif');
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\figureTFM\BF_polar_degree.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 ff/size(app.Dx,3) ff/size(app.Dx,3) 0];
                th.String = sprintf('Exporting BF Polar Degree Movie...%.0f%%',round(ff/size(app.Dx,3)*100));
                drawnow %update graphics
            end
            app.UIAxes_2.YDir='reverse';
            %% define video parameters
            length_video = size(app.Dx,3);
            length_x = size(app.Dx,1);
            length_y = size(app.Dx,2);
            [PosX,PosY]=meshgrid(1:length_y,1:length_x);
            %% Displacement video
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            app.UIAxes_2.YDir='reverse';
            colorbar(app.UIAxes_2,'off')
            max_disp=max(app.Dx,[],'all');
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_2,1,1,'Exporting Displacement Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:length_video
                displacement = sqrt(app.Dx(:,:,i).^2+app.Dy(:,:,i).^2);
                imagesc(app.UIAxes_2,displacement),colormap(app.UIAxes_2,app.blackcmap),colorbar(app.UIAxes_2), caxis(app.UIAxes_2,[0 max_disp]), set(app.UIAxes_2,'Visible','off'), daspect(app.UIAxes_2,[1 1 1]), %.*BWgrid
                hold(app.UIAxes_2,"on");
                xlim(app.UIAxes_2,[1 size(displacement,2)])
                ylim(app.UIAxes_2,[1 size(displacement,1)])
                if i == 1
                    quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),20e6*app.Dx(1:4:end,1:4:end,i),20e6*app.Dy(1:4:end,1:4:end,i),'r','AutoScale','off');
                else
                    quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),20e6*app.Dx(1:4:end,1:4:end,i),20e6*app.Dy(1:4:end,1:4:end,i),'r','AutoScale','off');
                end
                
                if ~exist(fullfile(app.path3,'movies'),'dir')
                    mkdir(fullfile(app.path3,'movies'))
                end
                
                figurepath=cat(2,app.path3,'\movies\displacement',num2str(i),'.tif');
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\movies\displacement.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 i/length_video i/length_video 0];
                th.String = sprintf('Exporting Displacement Movie...%.0f%%',round(i/length_video*100));
                drawnow %update graphics
            end
            %% Traction force video
            max_traction=max(app.Tx,[],'all');
            cla(app.UIAxes_2)
            title(app.UIAxes_2,'');
            xlabel(app.UIAxes_2,'');
            ylabel(app.UIAxes_2,'');
            app.UIAxes_2.XTick = '';
            app.UIAxes_2.YTick = '';
            app.UIAxes_2.YDir='reverse';
            colorbar(app.UIAxes_2,'off')
            cla(app.UIAxes2_2)
            ylim(app.UIAxes2_2,[0,1])
            xlim(app.UIAxes2_2,[0,1])
            ph = patch(app.UIAxes2_2,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_2,1,1,'Exporting Traction Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:length_video
                traction = sqrt(app.Tx(:,:,i).^2+app.Ty(:,:,i).^2);
                imagesc(app.UIAxes_2,traction),colormap(app.UIAxes_2,jet),colorbar(app.UIAxes_2), caxis(app.UIAxes_2,[0 max_traction]), set(app.UIAxes_2,'Visible','off');, daspect(app.UIAxes_2,[1 1 1]), %.*BWgrid
                hold(app.UIAxes_2,"on");
                xlim(app.UIAxes_2,[1 size(traction,2)])
                ylim(app.UIAxes_2,[1 size(traction,1)])
                if i == 1
                    quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),2e-2*app.Tx(1:4:end,1:4:end,i),2e-2*app.Ty(1:4:end,1:4:end,i),'r','AutoScale','off');
                else
                    quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),2e-2*app.Tx(1:4:end,1:4:end,i),2e-2*app.Ty(1:4:end,1:4:end,i),'r','AutoScale','off');
                end
                if ~exist(fullfile(app.path3,'movies'),'dir')
                    mkdir(fullfile(app.path3,'movies'))
                end
                
                figurepath=cat(2,app.path3,'\movies\traction',num2str(i),'.tif');
                exportgraphics(app.UIAxes_2,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.path3,'\movies\traction.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 i/length_video i/length_video 0];
                th.String = sprintf('Exporting Traction Movie...%.0f%%',round(i/length_video*100));
                drawnow %update graphics
            end
        end

        % Value changed function: DisplayTFMdataDropDown
        function DisplayTFMdataDropDownValueChanged(app, event)
            value = app.DisplayTFMdataDropDown.Value;
            app.DisplayTFMResults=value;
            daspect(app.UIAxes_3,'auto')
            if strcmp(value,'Displacement vector map')
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                colorbar(app.UIAxes_2,'off')
                deplacementX=app.Dx(:,:,app.sliderFrame2);
                deplacementY=app.Dy(:,:,app.sliderFrame2);
                deplacement = sqrt(deplacementX.^2+deplacementY.^2);
                imagesc(app.UIAxes_2,deplacement),colormap(app.UIAxes_2,app.blackcmap),colorbar(app.UIAxes_2),hold(app.UIAxes_2,"off"), daspect(app.UIAxes_2,[1 1 1]),
                xlim(app.UIAxes_2,[1 size(deplacement,2)])
                ylim(app.UIAxes_2,[1 size(deplacement,1)])
                title(app.UIAxes_2,['Displacement - Frame ',num2str(app.sliderFrame2)]);
                app.shownow=4;
            elseif strcmp(value,'Forces exerted by cell')
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                colorbar(app.UIAxes_2,'off')
                imagesc(app.UIAxes_2,app.bf(:,:,app.sliderFrame2));colormap(app.UIAxes_2,'gray')
                title(app.UIAxes_2,['Image ', num2str(app.sliderFrame2)])
                hold(app.UIAxes_2,"on");
                quiver(app.UIAxes_2,app.gridX(1:2:end,1:2:end),app.gridY(1:2:end,1:2:end),8*app.TractionX{app.sliderFrame2}(1:2:end,1:2:end),8*app.TractionY{app.sliderFrame2}(1:2:end,1:2:end),2,'r','AutoScale','off')
                hold(app.UIAxes_2,"off");
                xlim(app.UIAxes_2,[1 size(app.bf,2)])
                ylim(app.UIAxes_2,[1 size(app.bf,1)])
                app.shownow=5;
            elseif strcmp(value,'Stress magnitude')
                max_traction=max(app.Tx,[],'all');
                length_x = size(app.Dx,1);
                length_y = size(app.Dx,2);
                [PosX,PosY]=meshgrid(1:length_y,1:length_x);
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                colorbar(app.UIAxes_2,'off')
                traction = sqrt(app.Tx(:,:,app.sliderFrame2).^2+app.Ty(:,:,app.sliderFrame2).^2);
                imagesc(app.UIAxes_2,traction),colormap(app.UIAxes_2,jet),colorbar(app.UIAxes_2), caxis(app.UIAxes_2,[0 max_traction]), set(app.UIAxes_2,'Visible','off');daspect(app.UIAxes_2,[1 1 1]), %.*BWgrid
                hold(app.UIAxes_2,"on");
                xlim(app.UIAxes_2,[1 size(traction,2)])
                ylim(app.UIAxes_2,[1 size(traction,1)])
                quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),2e-2*app.Tx(1:4:end,1:4:end,app.sliderFrame2),2e-2*app.Ty(1:4:end,1:4:end,app.sliderFrame2),'r','AutoScale','off');
                app.shownow=6;
            elseif strcmp(value,'Displacement magnitude')
                app.UIAxes_2.YDir='reverse';
                length_x = size(app.Dx,1);
                length_y = size(app.Dx,2);
                [PosX,PosY]=meshgrid(1:length_y,1:length_x);
                %% Displacement video
                cla(app.UIAxes_2)
                title(app.UIAxes_2,'');
                xlabel(app.UIAxes_2,'');
                ylabel(app.UIAxes_2,'');
                app.UIAxes_2.XTick = '';
                app.UIAxes_2.YTick = '';
                app.UIAxes_2.YDir='reverse';
                colorbar(app.UIAxes_2,'off')
                max_disp=max(app.Dx,[],'all');
                displacement = sqrt(app.Dx(:,:,app.sliderFrame2).^2+app.Dy(:,:,app.sliderFrame2).^2);
                imagesc(app.UIAxes_2,displacement),colormap(app.UIAxes_2,app.blackcmap),colorbar(app.UIAxes_2), caxis(app.UIAxes_2,[0 max_disp]), set(app.UIAxes_2,'Visible','off'), daspect(app.UIAxes_2,[1 1 1]), %.*BWgrid
                hold(app.UIAxes_2,"on");
                xlim(app.UIAxes_2,[1 size(displacement,2)])
                ylim(app.UIAxes_2,[1 size(displacement,1)])
                quiver(app.UIAxes_2,PosX(1:4:end,1:4:end),PosY(1:4:end,1:4:end),20e6*app.Dx(1:4:end,1:4:end,app.sliderFrame2),20e6*app.Dy(1:4:end,1:4:end,app.sliderFrame2),'r','AutoScale','off');
                app.shownow=7;
            end
            
        end

        % Button pushed function: LoadSTICCSresultsButton
        function LoadSTICCSresultsButtonPushed(app, event)
            filterspec = {'*.mat','Select STICCS output file to load'};
            [app.f, app.p] = uigetfile(filterspec);
            load([app.p app.f]);
            app.timeDelay=timeDelay;
            app.tauLimit=tauLimit;
            app.filtering=filtering;
            app.MoveAverage=MoveAverage;
            app.fitRadius=fitRadius;
            %  app.TauFitLimit=TauFitLimit;
            app.threshVector=threshVector;
            app.ROIsize=ROIsize;
            app.ROIshift=ROIshift;
            app.TOIsize=TOIsize;
            app.TOIshift=TOIshift;
            app.axisTitle=axisTitle;
            app.outputName=outputName;
            app.path2=path2;
            app.exportimages=exportimages;
            app.imagesformat=imagesformat;
            app.movieformat=movieformat;
            app.pixelSize=pixelSize;
            app.timeFrame=timeFrame;
            app.position_x2=position_x;
            app.position_y2=position_y;
            app.position_t2=position_t;
            app.postouse2=postouse;
            app.velocityMap2=velocityMap;
            app.sliderFrame3=1;
            app.bckdCh='Ch1';
            app.scaleVector=2;
            app.showCorr='Auto-corr1';
            app.vecfield1='Auto-corr1';
            app.vecfield2='Displacement Beads';
            app.LBX2=1;
            app.LBY2=1;
            app.UBX2=size(app.velocityMap2{1,1}.data_raw,2);
            app.UBY2=size(app.velocityMap2{1,1}.data_raw,1);
            app.UBXSlider_2.Limits = [1 size(app.velocityMap2{1,1}.data_raw,2)];
            app.UBYSlider_2.Limits = [1 size(app.velocityMap2{1,1}.data_raw,1)];
            app.LBXSlider_2.Limits = [1 size(app.velocityMap2{1,1}.data_raw,2)];
            app.LBYSlider_2.Limits = [1 size(app.velocityMap2{1,1}.data_raw,1)];
            app.LBYSlider_2.MajorTicks = [1:200:size(app.velocityMap2{1,1}.data_raw,1)];
            app.UBYSlider_2.MajorTicks = [1:200:size(app.velocityMap2{1,1}.data_raw,1)];
            app.LBXSlider_2.MajorTicks = [1:200:size(app.velocityMap2{1,1}.data_raw,2)];
            app.UBXSlider_2.MajorTicks = [1:200:size(app.velocityMap2{1,1}.data_raw,2)];
            app.LBXSlider_2.Value = 1;
            app.LBYSlider_2.Value = 1;
            app.UBXSlider_2.Value = size(app.velocityMap2{1,1}.data_raw,2);
            app.UBYSlider_2.Value = size(app.velocityMap2{1,1}.data_raw,1);
            % plot histogram of speed chanell 1
            dummy=0;
            app.velMag1=[];
            app.good1=[];
            for i=1:length(app.position_t2)
                %                 for k=1:4
                %                 app.velocityMap3(k,1+(i-1)*app.TOIshift:1+(i-1)*app.TOIshift+app.TOIsize)=struct2cell(app.velocityMap2{k,i});
                %                 end
                app.indT(1+(i-1)*app.TOIshift:1+(i-1)*app.TOIshift+app.TOIsize,1)=i;
                vy=squeeze(app.velocityMap2{1,i}.vy);
                vx=squeeze(app.velocityMap2{1,i}.vx);
                app.velocityMap2{1,i}.magnitudesPerMin=sqrt(vx.^2+vy.^2)*60;
                velmag=app.velocityMap2{1,i}.magnitudesPerMin;
                numvel=size(velmag,1)*size(velmag,2);
                app.velMag1(1+dummy:dummy+numvel)=velmag;
                app.good1=union(app.good1,find(app.velocityMap2{1,i}.goodVectors));
                dummy=dummy+numvel;
            end
            velMag=app.velMag1(app.good1);
            cla(app.UIAxes_3)
            daspect(app.UIAxes_3,'auto')
            set(app.UIAxes_3,'Visible','on')
            app.UIAxes_3.YDir='normal';
            title(app.UIAxes_3,'')
            xlabel(app.UIAxes_3,'')
            ylabel(app.UIAxes_3,'')
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            hold(app.UIAxes_3,"on");
            histogram(app.UIAxes_3,velMag,round(length(velMag)/10))
            xlabel(app.UIAxes_3,'|v_{ch1}| (\mum min^{-1})')
            ylabel(app.UIAxes_3,'Occurence')
            xlim(app.UIAxes_3,[min(velMag) max(velMag)])
            ylim(app.UIAxes_3,[0 100])
            rangeV=max(velMag)-min(velMag);
            app.UIAxes_3.XTick =[min(velMag):rangeV/5:max(velMag)];
            app.UIAxes_3.YTick=[0:50:100];
            set(app.UIAxes_3,'Color',[1 1 1])
        end

        % Button pushed function: LoadTFMAnalysisResultsButton
        function LoadTFMAnalysisResultsButtonPushed(app, event)
            filterspec = {'*.mat','Select TFM output file to load'};
            [app.f2, app.p2] = uigetfile(filterspec);
            load([app.p2 app.f2]);
            app.Dx=Dx;
            app.Dy=Dy;
            app.gridX=gridX;
            app.gridY=gridY;
            app.Tx=Tx;
            app.Ty=Ty;
        end

        % Button pushed function: LoadBlackColormapFileButton_2
        function LoadBlackColormapFileButton_2Pushed(app, event)
            [colormapfile,path,indx] = uigetfile('*.mat','Select comlormap file to load','MultiSelect','on');
            load([path colormapfile])
            app.blackcmap=blackcmap;
            load([path 'RedBlueColormaps.mat'])
            app.redbluecmap=redbluecmap;
        end

        % Value changed function: UseFilteredVectorsOnlyButton
        function UseFilteredVectorsOnlyButtonValueChanged(app, event)
            value = app.UseFilteredVectorsOnlyButton.Value;
            app.UseGoodOnly=value;
        end

        % Value changed function: STICCSdataDropDown_2
        function STICCSdataDropDown_2ValueChanged(app, event)
            value = app.STICCSdataDropDown_2.Value;
            app.showCorr=value;
        end

        % Button pushed function: SpeedHistogramButton
        function SpeedHistogramButtonPushed(app, event)
            set(app.UIAxes_3,'Visible','on')
            if strcmp(app.showCorr,'Auto-corr1')
                app.corrch=1;
                dummy=0;
                app.velMag1=[];
                app.good1=[];
                for i=1:length(app.position_t2)
                    vy=squeeze(app.velocityMap2{1,i}.vy);
                    vx=squeeze(app.velocityMap2{1,i}.vx);
                    app.velocityMap2{1,i}.magnitudesPerMin=sqrt(vx.^2+vy.^2)*60;
                    velmag=app.velocityMap2{1,i}.magnitudesPerMin;
                    numvel=size(velmag,1)*size(velmag,2);
                    app.velMag1(1+dummy:dummy+numvel)=velmag;
                    app.good1=union(app.good1,find(app.velocityMap2{1,i}.goodVectors));
                    dummy=dummy+numvel;
                end
                
                if app.UseGoodOnly==1
                    app.good1=app.good1;
                elseif app.UseGoodOnly==0
                    app.good1=1:length(app.velMag1);
                end
                app.currvelMag=app.velMag1(app.good1);
                app.LBspeedSlider.Limits = [min(app.currvelMag) max(app.currvelMag)];
                app.LBspeedSlider.Value=min(app.currvelMag);
                app.UBspeedSlider.Limits = [min(app.currvelMag) max(app.currvelMag)];
                app.UBspeedSlider.Value=max(app.currvelMag);
                app.LBV=min(app.currvelMag);
                app.UBV=max(app.currvelMag);
                cla(app.UIAxes_3)
                daspect(app.UIAxes_3,'auto')
                app.UIAxes_3.YDir='normal';
                title(app.UIAxes_3,'')
                xlabel(app.UIAxes_3,'')
                ylabel(app.UIAxes_3,'')
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                hold(app.UIAxes_3,"on");
                histogram(app.UIAxes_3,app.currvelMag,round(length(app.currvelMag)/10))
                xlabel(app.UIAxes_3,'|v_{ch1}| (\mum min^{-1})')
                ylabel(app.UIAxes_3,'Occurence')
                xlim(app.UIAxes_3,[min(app.currvelMag) max(app.currvelMag)])
                ylim(app.UIAxes_3,[0 100])
                rangeV=max(app.currvelMag)-min(app.currvelMag);
                app.UIAxes_3.XTick =[min(app.currvelMag):rangeV/5:max(app.currvelMag)];
                app.UIAxes_3.YTick=[0:50:100];
                set(app.UIAxes_3,'Color',[1 1 1])
            elseif strcmp(app.showCorr,'Auto-corr2')
                app.corrch=2;
                dummy=0;
                app.velMag2=[];
                app.good2=[];
                for i=1:length(app.position_t2)
                    vy=squeeze(app.velocityMap2{2,i}.vy);
                    vx=squeeze(app.velocityMap2{2,i}.vx);
                    app.velocityMap2{2,i}.magnitudesPerMin=sqrt(vx.^2+vy.^2)*60;
                    velmag=app.velocityMap2{2,i}.magnitudesPerMin;
                    numvel=size(velmag,1)*size(velmag,2);
                    app.velMag2(1+dummy:dummy+numvel)=velmag;
                    app.good2=union(app.good2,find(app.velocityMap2{2,i}.goodVectors));
                    dummy=dummy+numvel;
                end
                if app.UseGoodOnly==1
                    app.good2=app.good2;
                elseif app.UseGoodOnly==0
                    app.good2=1:length(app.velMag2);
                end
                app.currvelMag=app.velMag2(app.good2);
                app.LBspeedSlider.Limits = [min(app.currvelMag) max(app.currvelMag)];
                app.LBspeedSlider.Value=min(app.currvelMag);
                app.UBspeedSlider.Limits = [min(app.currvelMag) max(app.currvelMag)];
                app.UBspeedSlider.Value=max(app.currvelMag);
                app.LBV=min(app.currvelMag);
                app.UBV=max(app.currvelMag);
                cla(app.UIAxes_3)
                daspect(app.UIAxes_3,'auto')
                app.UIAxes_3.YDir='normal';
                title(app.UIAxes_3,'')
                xlabel(app.UIAxes_3,'')
                ylabel(app.UIAxes_3,'')
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                hold(app.UIAxes_3,"on");
                histogram(app.UIAxes_3,app.currvelMag,round(length(app.currvelMag)/10))
                xlabel(app.UIAxes_3,'|v_{ch2}| (\mum min^{-1})')
                ylabel(app.UIAxes_3,'Occurence')
                xlim(app.UIAxes_3,[min(app.currvelMag) max(app.currvelMag)])
                ylim(app.UIAxes_3,[0 100])
                rangeV=max(app.currvelMag)-min(app.currvelMag);
                app.UIAxes_3.XTick =[min(app.currvelMag):rangeV/5:max(app.currvelMag)];
                app.UIAxes_3.YTick=[0:50:100];
            elseif strcmp(app.showCorr,'Cross-corr12')
                app.corrch=3;
                dummy=0;
                app.velMag12=[];
                app.good12=[];
                for i=1:length(app.position_t2)
                    vy=squeeze(app.velocityMap2{3,i}.vy);
                    vx=squeeze(app.velocityMap2{3,i}.vx);
                    app.velocityMap2{3,i}.magnitudesPerMin=sqrt(vx.^2+vy.^2)*60;
                    velmag=app.velocityMap2{3,i}.magnitudesPerMin;
                    numvel=size(velmag,1)*size(velmag,2);
                    app.velMag12(1+dummy:dummy+numvel)=velmag;
                    app.good12=union(app.good12,find(app.velocityMap2{3,i}.goodVectors));
                    dummy=dummy+numvel;
                end
                if app.UseGoodOnly==1
                    app.good12=app.good12;
                elseif app.UseGoodOnly==0
                    app.good12=1:length(app.velMag12);
                end
                app.currvelMag=app.velMag12(app.good12);
                app.LBspeedSlider.Limits = [min(app.currvelMag) max(app.currvelMag)];
                app.LBspeedSlider.Value=min(app.currvelMag);
                app.UBspeedSlider.Limits = [min(app.currvelMag) max(app.currvelMag)];
                app.UBspeedSlider.Value=max(app.currvelMag);
                app.LBV=min(app.currvelMag);
                app.UBV=max(app.currvelMag);
                cla(app.UIAxes_3)
                daspect(app.UIAxes_3,'auto')
                app.UIAxes_3.YDir='normal';
                title(app.UIAxes_3,'')
                xlabel(app.UIAxes_3,'')
                ylabel(app.UIAxes_3,'')
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                hold(app.UIAxes_3,"on");
                histogram(app.UIAxes_3,app.currvelMag,round(length(app.currvelMag)/10))
                xlabel(app.UIAxes_3,'|v_{ch12}| (\mum min^{-1})')
                ylabel(app.UIAxes_3,'Occurence')
                xlim(app.UIAxes_3,[min(app.currvelMag) max(app.currvelMag)])
                ylim(app.UIAxes_3,[0 100])
                rangeV=max(app.currvelMag)-min(app.currvelMag);
                app.UIAxes_3.XTick =[min(app.currvelMag):rangeV/5:max(app.currvelMag)];
                app.UIAxes_3.YTick=[0:50:100];
            elseif strcmp(app.showCorr,'Cross-corr21')
                app.corrch=4;
                dummy=0;
                app.velMag21=[];
                app.good21=[];
                for i=1:length(app.position_t2)
                    vy=squeeze(app.velocityMap2{4,i}.vy);
                    vx=squeeze(app.velocityMap2{4,i}.vx);
                    app.velocityMap2{4,i}.magnitudesPerMin=sqrt(vx.^2+vy.^2)*60;
                    velmag=app.velocityMap2{4,i}.magnitudesPerMin;
                    numvel=size(velmag,1)*size(velmag,2);
                    app.velMag21(1+dummy:dummy+numvel)=velmag;
                    app.good21=union(app.good21,find(app.velocityMap2{4,i}.goodVectors));
                    dummy=dummy+numvel;
                end
                if app.UseGoodOnly==1
                    app.good21=app.good21;
                elseif app.UseGoodOnly==0
                    app.good21=1:length(app.velMag21);
                end
                app.currvelMag=app.velMag21(app.good21);
                app.LBspeedSlider.Limits = [min(app.currvelMag) max(app.currvelMag)];
                app.LBspeedSlider.Value=min(app.currvelMag);
                app.UBspeedSlider.Limits = [min(app.currvelMag) max(app.currvelMag)];
                app.UBspeedSlider.Value=max(app.currvelMag);
                app.LBV=min(app.currvelMag);
                app.UBV=max(app.currvelMag);
                cla(app.UIAxes_3)
                daspect(app.UIAxes_3,'auto')
                app.UIAxes_3.YDir='normal';
                title(app.UIAxes_3,'')
                xlabel(app.UIAxes_3,'')
                ylabel(app.UIAxes_3,'')
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                hold(app.UIAxes_3,"on");
                histogram(app.UIAxes_3,app.currvelMag,round(length(app.currvelMag)/10))
                xlabel(app.UIAxes_3,'|v_{ch1}| (\mum min^{-1})')
                ylabel(app.UIAxes_3,'Occurence')
                xlim(app.UIAxes_3,[min(app.currvelMag) max(app.currvelMag)])
                ylim(app.UIAxes_3,[0 100])
                rangeV=max(app.currvelMag)-min(app.currvelMag);
                app.UIAxes_3.XTick =[min(app.currvelMag):rangeV/5:max(app.currvelMag)];
                app.UIAxes_3.YTick=[0:50:100];
            end
        end

        % Value changing function: LBspeedSlider
        function LBspeedSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LBV=changingValue;
            indGood1=find(app.currvelMag>app.LBV);
            indGood2=find(app.currvelMag<app.UBV);
            indgood=intersect(indGood1,indGood2);
            aveSpeedCur=nanmean(app.currvelMag(indgood));
            medianSpeedCur=nanmedian(app.currvelMag(indgood));
            stdSpeedCur=nanstd(app.currvelMag(indgood));
            cla(app.UIAxes_3)
            daspect(app.UIAxes_3,'auto')
            app.UIAxes_3.YDir='normal';
            set(app.UIAxes_3,'Visible','on')
            title(app.UIAxes_3,'')
            xlabel(app.UIAxes_3,'')
            ylabel(app.UIAxes_3,'')
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            hold(app.UIAxes_3,"on");
            histogram(app.UIAxes_3,app.currvelMag,round(length(app.currvelMag)/10))
            xlabel(app.UIAxes_3,'|v| (\mum min^{-1})')
            ylabel(app.UIAxes_3,'Occurence')
            xlim(app.UIAxes_3,[changingValue app.UBV])
            ylim(app.UIAxes_3,[0 100])
            rangeV=app.UBV-changingValue;
            text(app.UIAxes_3,0.75*app.UBV,80,['\langlev\rangle=' num2str(aveSpeedCur) ' \mum min^{-1}'])
            text(app.UIAxes_3,0.75*app.UBV,85,['\sigma_{v}=' num2str(stdSpeedCur) ' \mum min^{-1}'])
            text(app.UIAxes_3,0.75*app.UBV,90,['median |v|=' num2str(medianSpeedCur) ' \mum min^{-1}'])
            app.UIAxes_3.XTick =[changingValue:rangeV/5:app.UBV];
            app.UIAxes_3.YTick=[0:50:100];
        end

        % Value changed function: LBspeedSlider
        function LBspeedSliderValueChanged(app, event)
            value = app.LBspeedSlider.Value;
            app.LBV=value;
        end

        % Value changing function: UBspeedSlider
        function UBspeedSliderValueChanging(app, event)
            changingValue = event.Value;
             app.UBV=changingValue;
            indGood1=find(app.currvelMag>app.LBV);
            indGood2=find(app.currvelMag<app.UBV);
            indgood=intersect(indGood1,indGood2);
            aveSpeedCur=nanmean(app.currvelMag(indgood));
            medianSpeedCur=nanmedian(app.currvelMag(indgood));
            stdSpeedCur=nanstd(app.currvelMag(indgood));
            cla(app.UIAxes_3)
            daspect(app.UIAxes_3,'auto')
            set(app.UIAxes_3,'Visible','on')
            app.UIAxes_3.YDir='normal';
            title(app.UIAxes_3,'')
            xlabel(app.UIAxes_3,'')
            ylabel(app.UIAxes_3,'')
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            hold(app.UIAxes_3,"on");
            histogram(app.UIAxes_3,app.currvelMag,round(length(app.currvelMag)/10))
            xlabel(app.UIAxes_3,'|v| (\mum min^{-1})')
            ylabel(app.UIAxes_3,'Occurence')
            xlim(app.UIAxes_3,[app.LBV changingValue])
            ylim(app.UIAxes_3,[0 100])
            rangeV=changingValue-app.LBV;
            text(app.UIAxes_3,0.75*app.UBV,80,['\langlev\rangle=' num2str(aveSpeedCur) ' \mum min^{-1}'])
            text(app.UIAxes_3,0.75*app.UBV,85,['\sigma_{v}=' num2str(stdSpeedCur) ' \mum min^{-1}'])
            text(app.UIAxes_3,0.75*app.UBV,90,['median |v|=' num2str(medianSpeedCur) ' \mum min^{-1}'])
            app.UIAxes_3.XTick =[app.LBV:rangeV/5:changingValue];
            app.UIAxes_3.YTick=[0:50:100];
        end

        % Value changed function: UBspeedSlider
        function UBspeedSliderValueChanged(app, event)
            value = app.UBspeedSlider.Value;
            app.UBV=value;
        end

        % Button pushed function: ExportSpeedHistogramButton
        function ExportSpeedHistogramButtonPushed(app, event)
            exportgraphics(app.UIAxes_3,[app.p app.f(1:end-4) '_SpeedHistogram' app.showCorr '.png'])
        end

        % Button pushed function: ShowVectorMapsForSelectedChannelButton
        function ShowVectorMapsForSelectedChannelButtonPushed(app, event)
            app.FramesSlider_3.Limits = [1 length(app.position_t2)];
            app.sliderFrame3=1;
            cla(app.UIAxes_3)
            title(app.UIAxes_3,'');
            xlabel(app.UIAxes_3,'');
            ylabel(app.UIAxes_3,'');
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            app.UIAxes_3.YDir='reverse';
            colorbar(app.UIAxes_3,'off')
            if strcmp(app.bckdCh,'Ch1')
                bckdimg=app.velocityMap2{1,app.sliderFrame3}.data_raw;
            elseif strcmp(app.bckdCh,'Ch2')
                bckdimg=app.velocityMap2{2,app.sliderFrame3}.data_raw;
            end
            [dimx dimy]=size(bckdimg);
            vy=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vy)*60;
            vx=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vx)*60;
            indgood=app.velocityMap2{app.corrch,app.sliderFrame3}.goodVectors;
            %indgood=reshape(indgood,size(vx,1),size(vx,2));
            indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
            indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
            indgood=intersect(find(indgood),intersect(indgood1,indgood2));
            imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
            caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
            hold(app.UIAxes_3,"on");
            quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
            xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
            ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            app.shownow2=1;
            
        end

        % Button pushed function: ExportVectorMapsForSelectedChannelButton
        function ExportVectorMapsForSelectedChannelButtonPushed(app, event)
            %% define video parameters
            length_video = length(app.position_t2);
            
            cla(app.UIAxes_3)
            title(app.UIAxes_3,'');
            xlabel(app.UIAxes_3,'');
            ylabel(app.UIAxes_3,'');
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            app.UIAxes_3.YDir='reverse';
            colorbar(app.UIAxes_3,'off')
            cla(app.UIAxes2_3)
            ylim(app.UIAxes2_3,[0,1])
            xlim(app.UIAxes2_3,[0,1])
            ph = patch(app.UIAxes2_3,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_3,1,1,'Exporting Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:length_video
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,i}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,i}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                vy=squeeze(app.velocityMap2{app.corrch,i}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,i}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,i}.goodVectors;
                %indgood=reshape(indgood,size(vx,1),size(vx,2));
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
                if ~exist(fullfile(app.p,'movies'),'dir')
                    mkdir(fullfile(app.p,'movies'))
                end
                
                figurepath=cat(2,app.p,'\movies\STICCS_',num2str(i),app.showCorr,'.tif');
                exportgraphics(app.UIAxes_3,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.p,'\movies\STICCS_',app.showCorr,'.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 i/length_video i/length_video 0];
                th.String = sprintf('Exporting Movie...%.0f%%',round(i/length_video*100));
                drawnow %update graphics
            end
        end

        % Value changed function: BckdImageDropDown
        function BckdImageDropDownValueChanged(app, event)
            value = app.BckdImageDropDown.Value;
            app.bckdCh=value;
        end

        % Value changing function: FramesSlider_3
        function FramesSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.sliderFrame3=uint16(changingValue);
            if app.shownow2==1
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.sliderFrame3}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.sliderFrame3}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                vy=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.sliderFrame3}.goodVectors;
                %indgood=reshape(indgood,size(vx,1),size(vx,2));
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                %[size(indgood1) size(indgood2) size(indgood)]
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==2
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.indT(app.sliderFrame3)}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.indT(app.sliderFrame3)}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                hold(app.UIAxes_3,"on");
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,20e6*app.Dx(:,:,app.sliderFrame3),20e6*app.Dy(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                elseif strcmp(app.vecfield2,'Traction Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,2e-2*app.Tx(:,:,app.sliderFrame3),2e-2*app.Ty(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                end
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==3
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    magB=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    magB=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)>numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                end
                %[nanmin(compim(:)) nanmax(compim(:))]
                imagesc(app.UIAxes_3,compim), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(compim(:)) nanmax(compim(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==4
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    vx2=app.Dx(:,:,app.sliderFrame3);
                    vy2=app.Dy(:,:,app.sliderFrame3);
                    mag2=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    vx2=app.Tx(:,:,app.sliderFrame3);
                    vy2=app.Ty(:,:,app.sliderFrame3);
                    mag2=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx2=imresize(vx2,size(vx));
                    vy2=imresize(vy2,size(vy));
                    mag2=imresize(mag2,size(magV));
                elseif numel(magV)<numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx=imresize(vx,size(vx2));
                    vy=imresize(vy,size(vy2));
                    magV=imresize(magV,size(mag2));
                end
                vecdotprod=(vx.*vx2+vy.*vy2)./(magV.*mag2);
                imagesc(app.UIAxes_3,vecdotprod);colormap(app.UIAxes_3,'pink');colorbar(app.UIAxes_3);set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(vecdotprod(:)) nanmax(vecdotprod(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            end
        end

        % Value changed function: VectorScaleFactorEditField
        function VectorScaleFactorEditFieldValueChanged(app, event)
            value = app.VectorScaleFactorEditField.Value;
            app.scaleVector=value;
        end

        % Value changed function: STICCSdataDropDown
        function STICCSdataDropDownValueChanged(app, event)
            value = app.STICCSdataDropDown.Value;
            app.vecfield1=value;
        end

        % Value changed function: TFMdataDropDown
        function TFMdataDropDownValueChanged(app, event)
            value = app.TFMdataDropDown.Value;
            app.vecfield2=value;
        end

        % Button pushed function: ShowVectorMapsSuperimposedButton
        function ShowVectorMapsSuperimposedButtonPushed(app, event)
            app.FramesSlider_3.Limits = [1 size(app.Dy,3)];
            app.LBX2=1;
            app.LBY2=1;
            app.UBX2=size(app.velocityMap2{1,1}.data_raw,2);
            app.UBY2=size(app.velocityMap2{1,1}.data_raw,1);
            app.UBXSlider_2.Limits = [1 size(app.velocityMap2{1,1}.data_raw,2)];
            app.UBYSlider_2.Limits = [1 size(app.velocityMap2{1,1}.data_raw,1)];
            app.LBXSlider_2.Limits = [1 size(app.velocityMap2{1,1}.data_raw,2)];
            app.LBYSlider_2.Limits = [1 size(app.velocityMap2{1,1}.data_raw,1)];
            app.LBYSlider_2.MajorTicks = [1:200:size(app.velocityMap2{1,1}.data_raw,1)];
            app.UBYSlider_2.MajorTicks = [1:200:size(app.velocityMap2{1,1}.data_raw,1)];
            app.LBXSlider_2.MajorTicks = [1:200:size(app.velocityMap2{1,1}.data_raw,2)];
            app.UBXSlider_2.MajorTicks = [1:200:size(app.velocityMap2{1,1}.data_raw,2)];
            app.LBXSlider_2.Value = 1;
            app.LBYSlider_2.Value = 1;
            app.UBXSlider_2.Value = size(app.velocityMap2{1,1}.data_raw,2);
            app.UBYSlider_2.Value = size(app.velocityMap2{1,1}.data_raw,1);
            cla(app.UIAxes_3)
            title(app.UIAxes_3,'');
            xlabel(app.UIAxes_3,'');
            ylabel(app.UIAxes_3,'');
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            app.UIAxes_3.YDir='reverse';
            colorbar(app.UIAxes_3,'off')
            if strcmp(app.bckdCh,'Ch1')
                bckdimg=app.velocityMap2{1,app.indT(app.sliderFrame3)}.data_raw;
            elseif strcmp(app.bckdCh,'Ch2')
                bckdimg=app.velocityMap2{2,app.indT(app.sliderFrame3)}.data_raw;
            end
            [dimx dimy]=size(bckdimg);
            imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
            caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
            hold(app.UIAxes_3,"on");
            % vector field 1
            if strcmp(app.vecfield1,'Auto-corr1')
                app.corrch=1;
            elseif strcmp(app.vecfield1,'Auto-corr2')
                app.corrch=2;
            elseif strcmp(app.vecfield1,'Cross-corr12')
                app.corrch=3;
            elseif strcmp(app.vecfield1,'Cross-corr21')
                app.corrch=4;
            end
            vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
            vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
            indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
            indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
            indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
            indgood=intersect(find(indgood),intersect(indgood1,indgood2));
            quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
            hold(app.UIAxes_3,"on");
            % vector field 2
            if strcmp(app.vecfield2,'Displacement Beads')
                quiver(app.UIAxes_3,app.gridX,app.gridY,20e6*app.Dx(:,:,app.sliderFrame3),20e6*app.Dy(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
            elseif strcmp(app.vecfield2,'Traction Beads')
                quiver(app.UIAxes_3,app.gridX,app.gridY,2e-2*app.Tx(:,:,app.sliderFrame3),2e-2*app.Ty(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
            end
            xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
            ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            app.shownow2=2;
        end

        % Value changing function: LBXSlider_2
        function LBXSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.LBX2=changingValue;
            if app.shownow==1
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.sliderFrame3}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.sliderFrame3}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                vy=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.sliderFrame3}.goodVectors;
                %indgood=reshape(indgood,size(vx,1),size(vx,2));
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==2
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.indT(app.sliderFrame3)}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.indT(app.sliderFrame3)}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                hold(app.UIAxes_3,"on");
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,20e6*app.Dx(:,:,app.sliderFrame3),20e6*app.Dy(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                elseif strcmp(app.vecfield2,'Traction Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,2e-2*app.Tx(:,:,app.sliderFrame3),2e-2*app.Ty(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                end
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==3
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    magB=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    magB=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)>numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                end
                imagesc(app.UIAxes_3,compim), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(compim(:)) nanmax(compim(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow==4
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    vx2=app.Dx(:,:,app.sliderFrame3);
                    vy2=app.Dy(:,:,app.sliderFrame3);
                    mag2=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    vx2=app.Tx(:,:,app.sliderFrame3);
                    vy2=app.Ty(:,:,app.sliderFrame3);
                    mag2=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx2=imresize(vx2,size(vx));
                    vy2=imresize(vy2,size(vy));
                    mag2=imresize(mag2,size(magV));
                elseif numel(magV)<numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx=imresize(vx,size(vx2));
                    vy=imresize(vy,size(vy2));
                    magV=imresize(magV,size(mag2));
                end
                vecdotprod=(vx.*vx2+vy.*vy2)./(magV.*mag2);
                imagesc(app.UIAxes_3,vecdotprod);colormap(app.UIAxes_3,'pink');colorbar(app.UIAxes_3);set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(vecdotprod(:)) nanmax(vecdotprod(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            end
            
        end

        % Value changing function: UBXSlider_2
        function UBXSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.UBX2=round(changingValue);
            if app.shownow==1
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.sliderFrame3}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.sliderFrame3}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                vy=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.sliderFrame3}.goodVectors;
                %indgood=reshape(indgood,size(vx,1),size(vx,2));
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==2
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.indT(app.sliderFrame3)}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.indT(app.sliderFrame3)}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                hold(app.UIAxes_3,"on");
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,20e6*app.Dx(:,:,app.sliderFrame3),20e6*app.Dy(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                elseif strcmp(app.vecfield2,'Traction Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,2e-2*app.Tx(:,:,app.sliderFrame3),2e-2*app.Ty(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                end
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==3
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    magB=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    magB=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)>numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                end
                imagesc(app.UIAxes_3,compim), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(compim(:)) nanmax(compim(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow==4
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    vx2=app.Dx(:,:,app.sliderFrame3);
                    vy2=app.Dy(:,:,app.sliderFrame3);
                    mag2=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    vx2=app.Tx(:,:,app.sliderFrame3);
                    vy2=app.Ty(:,:,app.sliderFrame3);
                    mag2=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx2=imresize(vx2,size(vx));
                    vy2=imresize(vy2,size(vy));
                    mag2=imresize(mag2,size(magV));
                elseif numel(magV)<numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx=imresize(vx,size(vx2));
                    vy=imresize(vy,size(vy2));
                    magV=imresize(magV,size(mag2));
                end
                vecdotprod=(vx.*vx2+vy.*vy2)./(magV.*mag2);
                imagesc(app.UIAxes_3,vecdotprod);colormap(app.UIAxes_3,'pink');colorbar(app.UIAxes_3);set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(vecdotprod(:)) nanmax(vecdotprod(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            end
            
        end

        % Value changing function: LBYSlider_2
        function LBYSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.LBY2=round(changingValue);
            if app.shownow==1
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.sliderFrame3}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.sliderFrame3}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                vy=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.sliderFrame3}.goodVectors;
                %indgood=reshape(indgood,size(vx,1),size(vx,2));
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==2
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.indT(app.sliderFrame3)}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.indT(app.sliderFrame3)}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                hold(app.UIAxes_3,"on");
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,20e6*app.Dx(:,:,app.sliderFrame3),20e6*app.Dy(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                elseif strcmp(app.vecfield2,'Traction Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,2e-2*app.Tx(:,:,app.sliderFrame3),2e-2*app.Ty(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                end
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==3
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    magB=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    magB=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)>numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                end
                imagesc(app.UIAxes_3,compim), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(compim(:)) nanmax(compim(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow==4
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    vx2=app.Dx(:,:,app.sliderFrame3);
                    vy2=app.Dy(:,:,app.sliderFrame3);
                    mag2=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    vx2=app.Tx(:,:,app.sliderFrame3);
                    vy2=app.Ty(:,:,app.sliderFrame3);
                    mag2=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx2=imresize(vx2,size(vx));
                    vy2=imresize(vy2,size(vy));
                    mag2=imresize(mag2,size(magV));
                elseif numel(magV)<numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx=imresize(vx,size(vx2));
                    vy=imresize(vy,size(vy2));
                    magV=imresize(magV,size(mag2));
                end
                vecdotprod=(vx.*vx2+vy.*vy2)./(magV.*mag2);
                imagesc(app.UIAxes_3,vecdotprod);colormap(app.UIAxes_3,'pink');colorbar(app.UIAxes_3);set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(vecdotprod(:)) nanmax(vecdotprod(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            end
            
        end

        % Value changing function: UBYSlider_2
        function UBYSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.UBY2=round(changingValue);
            if app.shownow==1
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.sliderFrame3}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.sliderFrame3}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                vy=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.sliderFrame3}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.sliderFrame3}.goodVectors;
                %indgood=reshape(indgood,size(vx,1),size(vx,2));
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==2
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.indT(app.sliderFrame3)}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.indT(app.sliderFrame3)}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                hold(app.UIAxes_3,"on");
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,20e6*app.Dx(:,:,app.sliderFrame3),20e6*app.Dy(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                elseif strcmp(app.vecfield2,'Traction Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,2e-2*app.Tx(:,:,app.sliderFrame3),2e-2*app.Ty(:,:,app.sliderFrame3),'r','AutoScaleFactor',app.scaleVector);
                end
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow2==3
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    magB=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    magB=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)>numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                end
                imagesc(app.UIAxes_3,compim), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(compim(:)) nanmax(compim(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            elseif app.shownow==4
                cla(app.UIAxes_3)
                title(app.UIAxes_3,'');
                xlabel(app.UIAxes_3,'');
                ylabel(app.UIAxes_3,'');
                app.UIAxes_3.XTick = '';
                app.UIAxes_3.YTick = '';
                app.UIAxes_3.YDir='reverse';
                colorbar(app.UIAxes_3,'off')
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    vx2=app.Dx(:,:,app.sliderFrame3);
                    vy2=app.Dy(:,:,app.sliderFrame3);
                    mag2=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    vx2=app.Tx(:,:,app.sliderFrame3);
                    vy2=app.Ty(:,:,app.sliderFrame3);
                    mag2=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
                end
                
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx2=imresize(vx2,size(vx));
                    vy2=imresize(vy2,size(vy));
                    mag2=imresize(mag2,size(magV));
                elseif numel(magV)<numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx=imresize(vx,size(vx2));
                    vy=imresize(vy,size(vy2));
                    magV=imresize(magV,size(mag2));
                end
                vecdotprod=(vx.*vx2+vy.*vy2)./(magV.*mag2);
                imagesc(app.UIAxes_3,vecdotprod);colormap(app.UIAxes_3,'pink');colorbar(app.UIAxes_3);set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(vecdotprod(:)) nanmax(vecdotprod(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            end
            
        end

        % Button pushed function: SaveVectorMapsSuperimposedButton
        function SaveVectorMapsSuperimposedButtonPushed(app, event)
            cla(app.UIAxes_3)
            title(app.UIAxes_3,'');
            xlabel(app.UIAxes_3,'');
            ylabel(app.UIAxes_3,'');
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            app.UIAxes_3.YDir='reverse';
            colorbar(app.UIAxes_3,'off')
            cla(app.UIAxes2_3)
            ylim(app.UIAxes2_3,[0,1])
            xlim(app.UIAxes2_3,[0,1])
            ph = patch(app.UIAxes2_3,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_3,1,1,'Exporting Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:length(app.indT)
                if strcmp(app.bckdCh,'Ch1')
                    bckdimg=app.velocityMap2{1,app.indT(i)}.data_raw;
                elseif strcmp(app.bckdCh,'Ch2')
                    bckdimg=app.velocityMap2{2,app.indT(i)}.data_raw;
                end
                [dimx dimy]=size(bckdimg);
                imagesc(app.UIAxes_3,bckdimg),colormap(app.UIAxes_3,gray), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[min(bckdimg(:)) max(bckdimg(:))])
                hold(app.UIAxes_3,"on");
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(i)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(i)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(i)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                quiver(app.UIAxes_3,app.position_y2(indgood),app.position_x2(indgood),vx(indgood),vy(indgood),'g','AutoScaleFactor',app.scaleVector);
                hold(app.UIAxes_3,"on");
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,20e6*app.Dx(:,:,i),20e6*app.Dy(:,:,i),'r','AutoScaleFactor',app.scaleVector);
                elseif strcmp(app.vecfield2,'Traction Beads')
                    quiver(app.UIAxes_3,app.gridX,app.gridY,2e-2*app.Tx(:,:,i),2e-2*app.Ty(:,:,i),'r','AutoScaleFactor',app.scaleVector);
                end
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
                
                if ~exist(fullfile(app.p,'movies'),'dir')
                    mkdir(fullfile(app.p,'movies'))
                end
                
                figurepath=cat(2,app.p,'\movies\VecMampsCombo_',app.vecfield1,'_',app.vecfield2,num2str(i),'.tif');
                exportgraphics(app.UIAxes_3,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.p,'\movies\VecMampsCombo_',app.vecfield1,'_',app.vecfield2,'.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 i/length(app.indT) i/length(app.indT) 0];
                th.String = sprintf('Exporting Movie...%.0f%%',round(i/length(app.indT)*100));
                drawnow %update graphics
            end
        end

        % Button pushed function: ShowMagnitudesMapsSuperimposedButton
        function ShowMagnitudesMapsSuperimposedButtonPushed(app, event)
            app.FramesSlider_3.Limits = [1 size(app.Dy,3)];
            cla(app.UIAxes_3)
            title(app.UIAxes_3,'');
            xlabel(app.UIAxes_3,'');
            ylabel(app.UIAxes_3,'');
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            app.UIAxes_3.YDir='reverse';
            colorbar(app.UIAxes_3,'off')
            % vector field 1
            if strcmp(app.vecfield1,'Auto-corr1')
                app.corrch=1;
            elseif strcmp(app.vecfield1,'Auto-corr2')
                app.corrch=2;
            elseif strcmp(app.vecfield1,'Cross-corr12')
                app.corrch=3;
            elseif strcmp(app.vecfield1,'Cross-corr21')
                app.corrch=4;
            end
            % vector field 2
            if strcmp(app.vecfield2,'Displacement Beads')
                magB=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
            elseif strcmp(app.vecfield2,'Traction Beads')
                magB=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
            end
            vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
            vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
            indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
            indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
            indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
            indgood=intersect(find(indgood),intersect(indgood1,indgood2));
            vx(~indgood)=NaN;
            vy(~indgood)=NaN;
            magV=(vx.^2+vy.^2).^0.5;
            clear compim
            % asjust T and D or magV
            if numel(magV)>numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                magB=imresize(magB,size(magV));
                compim(:,:,1)=imadjust(normalisation(magB));
                compim(:,:,2)=imadjust(normalisation(magV));
                compim(:,:,3)=imadjust(normalisation(magB+magV));
            elseif numel(magV)>numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                magB=imresize(magB,size(magV));
                compim(:,:,1)=imadjust(normalisation(magB));
                compim(:,:,2)=imadjust(normalisation(magV));
                compim(:,:,3)=imadjust(normalisation(magB+magV));
            elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                magV=imresize(magV,size(magB));
                compim(:,:,1)=imadjust(normalisation(magB));
                compim(:,:,2)=imadjust(normalisation(magV));
                compim(:,:,3)=imadjust(normalisation(magB+magV));
            elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                magV=imresize(magV,size(magB));
                compim(:,:,1)=imadjust(normalisation(magB));
                compim(:,:,2)=imadjust(normalisation(magV));
                compim(:,:,3)=imadjust(normalisation(magB+magV));
            end
            imagesc(app.UIAxes_3,compim), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
            caxis(app.UIAxes_3,[nanmin(compim(:)) nanmax(compim(:))])
            app.LBX2=1;
            app.LBY2=1;
            app.UBX2=size(compim,2);
            app.UBY2=size(compim,1);
            app.UBXSlider_2.Limits = [1 size(compim,2)];
            app.UBYSlider_2.Limits = [1 size(compim,1)];
            app.LBXSlider_2.Limits = [1 size(compim,2)];
            app.LBYSlider_2.Limits = [1 size(compim,1)];
            app.LBYSlider_2.MajorTicks = [1:50:size(compim,1)];
            app.UBYSlider_2.MajorTicks = [1:50:size(compim,1)];
            app.LBXSlider_2.MajorTicks = [1:50:size(compim,2)];
            app.UBXSlider_2.MajorTicks = [1:50:size(compim,2)];
            app.LBXSlider_2.Value = 1;
            app.LBYSlider_2.Value = 1;
            app.UBXSlider_2.Value = size(compim,2);
            app.UBYSlider_2.Value = size(compim,1);
            xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
            ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            app.shownow2=3;
        end

        % Button pushed function: SaveMagnitudesMapsSuperimposedButton
        function SaveMagnitudesMapsSuperimposedButtonPushed(app, event)
            cla(app.UIAxes_3)
            title(app.UIAxes_3,'');
            xlabel(app.UIAxes_3,'');
            ylabel(app.UIAxes_3,'');
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            app.UIAxes_3.YDir='reverse';
            colorbar(app.UIAxes_3,'off')
            cla(app.UIAxes2_3)
            ylim(app.UIAxes2_3,[0,1])
            xlim(app.UIAxes2_3,[0,1])
            ph = patch(app.UIAxes2_3,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_3,1,1,'Exporting Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            for i=1:length(app.indT)
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    magB=(app.Dx(:,:,i).^2+app.Dy(:,:,i).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    magB=(app.Tx(:,:,i).^2+app.Ty(:,:,i).^2).^0.5;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(i)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(i)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(i)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)>numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magB=imresize(magB,size(magV));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Displacement Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                elseif numel(magV)<numel(magB) && strcmp(app.vecfield2,'Traction Beads')
                    magV=imresize(magV,size(magB));
                    compim(:,:,1)=imadjust(normalisation(magB));
                    compim(:,:,2)=imadjust(normalisation(magV));
                    compim(:,:,3)=imadjust(normalisation(magB+magV));
                end
                imagesc(app.UIAxes_3,compim), set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(compim(:)) nanmax(compim(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
                
                
                if ~exist(fullfile(app.p,'movies'),'dir')
                    mkdir(fullfile(app.p,'movies'))
                end
                
                figurepath=cat(2,app.p,'\movies\MagnitudesCombo_',app.vecfield1,'_',app.vecfield2,num2str(i),'.tif');
                exportgraphics(app.UIAxes_3,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.p,'\movies\MagnitudesCombo_',app.vecfield1,'_',app.vecfield2,'.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 i/length(app.indT) i/length(app.indT) 0];
                th.String = sprintf('Exporting Movie...%.0f%%',round(i/length(app.indT)*100));
                drawnow %update graphics
            end
        end

        % Button pushed function: CalculatevectordotproductandshowmapButton
        function CalculatevectordotproductandshowmapButton_2Pushed(app, event)
            app.FramesSlider_3.Limits = [1 size(app.Dy,3)];
            cla(app.UIAxes_3)
            title(app.UIAxes_3,'');
            xlabel(app.UIAxes_3,'');
            ylabel(app.UIAxes_3,'');
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            app.UIAxes_3.YDir='reverse';
            colorbar(app.UIAxes_3,'off')
            % vector field 1
            if strcmp(app.vecfield1,'Auto-corr1')
                app.corrch=1;
            elseif strcmp(app.vecfield1,'Auto-corr2')
                app.corrch=2;
            elseif strcmp(app.vecfield1,'Cross-corr12')
                app.corrch=3;
            elseif strcmp(app.vecfield1,'Cross-corr21')
                app.corrch=4;
            end
            vy=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vy)*60;
            vx=squeeze(app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.Vx)*60;
            indgood=app.velocityMap2{app.corrch,app.indT(app.sliderFrame3)}.goodVectors;
            indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
            indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
            indgood=intersect(find(indgood),intersect(indgood1,indgood2));
            vx(~indgood)=NaN;
            vy(~indgood)=NaN;
            magV=(vx.^2+vy.^2).^0.5;
            % vector field 2
            if strcmp(app.vecfield2,'Displacement Beads')
                vx2=app.Dx(:,:,app.sliderFrame3);
                vy2=app.Dy(:,:,app.sliderFrame3);
                mag2=(app.Dx(:,:,app.sliderFrame3).^2+app.Dy(:,:,app.sliderFrame3).^2).^0.5;
            elseif strcmp(app.vecfield2,'Traction Beads')
                vx2=app.Tx(:,:,app.sliderFrame3);
                vy2=app.Ty(:,:,app.sliderFrame3);
                mag2=(app.Tx(:,:,app.sliderFrame3).^2+app.Ty(:,:,app.sliderFrame3).^2).^0.5;
            end
            
            clear compim
            % asjust T and D or magV
            if numel(magV)>numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                vx2=imresize(vx2,size(vx));
                vy2=imresize(vy2,size(vy));
                mag2=imresize(mag2,size(magV));
            elseif numel(magV)<numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                vx=imresize(vx,size(vx2));
                vy=imresize(vy,size(vy2));
                magV=imresize(magV,size(mag2));
            end
            vecdotprod=(vx.*vx2+vy.*vy2)./(magV.*mag2);
            imagesc(app.UIAxes_3,vecdotprod);colormap(app.UIAxes_3,'pink');colorbar(app.UIAxes_3);set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
            caxis(app.UIAxes_3,[nanmin(vecdotprod(:)) nanmax(vecdotprod(:))])
            app.LBX2=1;
            app.LBY2=1;
            app.UBX2=size(vecdotprod,2);
            app.UBY2=size(vecdotprod,1);
            app.UBXSlider_2.Limits = [1 size(vecdotprod,2)];
            app.UBYSlider_2.Limits = [1 size(vecdotprod,1)];
            app.LBXSlider_2.Limits = [1 size(vecdotprod,2)];
            app.LBYSlider_2.Limits = [1 size(vecdotprod,1)];
            app.LBYSlider_2.MajorTicks = [1:50:size(vecdotprod,1)];
            app.UBYSlider_2.MajorTicks = [1:50:size(vecdotprod,1)];
            app.LBXSlider_2.MajorTicks = [1:50:size(vecdotprod,2)];
            app.UBXSlider_2.MajorTicks = [1:50:size(vecdotprod,2)];
            app.LBXSlider_2.Value = 1;
            app.LBYSlider_2.Value = 1;
            app.UBXSlider_2.Value = size(vecdotprod,2);
            app.UBYSlider_2.Value = size(vecdotprod,1);
            xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
            ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
            app.shownow2=4;
        end

        % Button pushed function: SavevectordotproductmapButton
        function SavevectordotproductmapButtonPushed(app, event)
            cla(app.UIAxes_3)
            title(app.UIAxes_3,'');
            xlabel(app.UIAxes_3,'');
            ylabel(app.UIAxes_3,'');
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            app.UIAxes_3.YDir='reverse';
            colorbar(app.UIAxes_3,'off')
            cla(app.UIAxes2_3)
            ylim(app.UIAxes2_3,[0,1])
            xlim(app.UIAxes2_3,[0,1])
            ph = patch(app.UIAxes2_3,[0 0 0 0],[0 0 1 1],[0.67578 1 0.18359]); %greenyello
            th = text(app.UIAxes2_3,1,1,'Exporting Movie...0%','VerticalAlignment','bottom','HorizontalAlignment','right');
            app.vecdotprodall=[];
            j=1;
            for i=1:size(app.Dx,3)
                % vector field 1
                if strcmp(app.vecfield1,'Auto-corr1')
                    app.corrch=1;
                elseif strcmp(app.vecfield1,'Auto-corr2')
                    app.corrch=2;
                elseif strcmp(app.vecfield1,'Cross-corr12')
                    app.corrch=3;
                elseif strcmp(app.vecfield1,'Cross-corr21')
                    app.corrch=4;
                end
                vy=squeeze(app.velocityMap2{app.corrch,app.indT(i)}.Vy)*60;
                vx=squeeze(app.velocityMap2{app.corrch,app.indT(i)}.Vx)*60;
                indgood=app.velocityMap2{app.corrch,app.indT(i)}.goodVectors;
                indgood1=find((vx.^2+vy.^2).^0.5>app.LBV);
                indgood2=find((vx.^2+vy.^2).^0.5<app.UBV);
                indgood=intersect(find(indgood),intersect(indgood1,indgood2));
                vx(~indgood)=NaN;
                vy(~indgood)=NaN;
                magV=(vx.^2+vy.^2).^0.5;
                % vector field 2
                if strcmp(app.vecfield2,'Displacement Beads')
                    vx2=app.Dx(:,:,i);
                    vy2=app.Dy(:,:,i);
                    mag2=(app.Dx(:,:,i).^2+app.Dy(:,:,i).^2).^0.5;
                elseif strcmp(app.vecfield2,'Traction Beads')
                    vx2=app.Tx(:,:,i);
                    vy2=app.Ty(:,:,i);
                    mag2=(app.Tx(:,:,i).^2+app.Ty(:,:,i).^2).^0.5;
                end
                
                clear compim
                % asjust T and D or magV
                if numel(magV)>numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx2=imresize(vx2,size(vx));
                    vy2=imresize(vy2,size(vy));
                    mag2=imresize(mag2,size(magV));
                elseif numel(magV)<numel(mag2) && (strcmp(app.vecfield2,'Displacement Beads') || strcmp(app.vecfield2,'Traction Beads'))
                    vx=imresize(vx,size(vx2));
                    vy=imresize(vy,size(vy2));
                    magV=imresize(magV,size(mag2));
                end
                vecdotprod=(vx.*vx2+vy.*vy2)./(magV.*mag2);
                intvec=reshape(vecdotprod,1,[]);
                app.vecdotprodall(1+j:j+length(intvec))=intvec;
                j=j+length(intvec);
                imagesc(app.UIAxes_3,vecdotprod);colormap(app.UIAxes_3,'pink');colorbar(app.UIAxes_3);set(app.UIAxes_3,'Visible','off'), daspect(app.UIAxes_3,[1 1 1]), %.*BWgrid
                caxis(app.UIAxes_3,[nanmin(vecdotprod(:)) nanmax(vecdotprod(:))])
                xlim(app.UIAxes_3,[app.LBX2 app.UBX2])
                ylim(app.UIAxes_3,[app.LBY2 app.UBY2])
                
                
                if ~exist(fullfile(app.p,'movies'),'dir')
                    mkdir(fullfile(app.p,'movies'))
                end
                
                figurepath=cat(2,app.p,'\movies\VecDotProduct_',app.vecfield1,'_',app.vecfield2,num2str(i),'.tif');
                exportgraphics(app.UIAxes_3,fullfile(figurepath));
                A=imread(figurepath);
                imwrite(A,cat(2,app.p,'\movies\VecDotProduct_',app.vecfield1,'_',app.vecfield2,'.tif'),'WriteMode','append');
                delete (figurepath)
                ph.XData = [0 i/length(app.indT) i/length(app.indT) 0];
                th.String = sprintf('Exporting Movie...%.0f%%',round(i/length(app.indT)*100));
                drawnow %update graphics
            end
        end

        % Button pushed function: ShowhistogramofvectordotproductButton
        function ShowhistogramofvectordotproductButtonPushed(app, event)
            cla(app.UIAxes_3)
            vecdotprodall=app.vecdotprodall;
            indgoodvec=find(~isnan(vecdotprodall));
            vecdotprodall=vecdotprodall(indgoodvec);
             save([app.path2 'TwoColorVelocityMap' app.outputName '.mat'],'vecdotprodall','-append');
            indAbove0=find(vecdotprodall>0);
            indAbove0_5=find(vecdotprodall>0.5);
            indBelow0=find(vecdotprodall<0);
            indBelowMin0_5=find(vecdotprodall<-0.5);
            PerAbove0=round(length(indAbove0)/length(vecdotprodall),2)*100;
            PerAbove0_5=round(length(indAbove0_5)/length(vecdotprodall),2)*100;
            PerBelow0=round(length(indBelow0)/length(vecdotprodall),2)*100;
            PerBelowMin0_5=round(length(indBelowMin0_5)/length(vecdotprodall),2)*100;
            colorbar(app.UIAxes_3,'off')
            daspect(app.UIAxes_3,'auto')
            app.UIAxes_3.YDir='normal';
            title(app.UIAxes_3,'')
            xlabel(app.UIAxes_3,'')
            ylabel(app.UIAxes_3,'')
            app.UIAxes_3.XTick = '';
            app.UIAxes_3.YTick = '';
            set(app.UIAxes_3,'Visible','on')
            histogram(app.UIAxes_3,vecdotprodall,round(length(vecdotprodall)/10))
            hold(app.UIAxes_3,"on");
            text(app.UIAxes_3,-0.8,150,[num2str(PerAbove0) '% of vector pairs > 0' ])
            text(app.UIAxes_3,-0.8,175,[num2str(PerAbove0_5) '% of vector pairs > 0.5' ])
            text(app.UIAxes_3,-0.8,195,[num2str(PerBelow0) '% of vector pairs < 0' ])
            text(app.UIAxes_3,-0.8,215,[num2str(PerBelowMin0_5) '% of vector pairs < -0.5' ])
            xlabel(app.UIAxes_3,'|v_{1}\cdot v_{2}|/|v_{1}||v_{2}| ')
            ylabel(app.UIAxes_3,'Occurence')
            title(app.UIAxes_3,['Vector dot product for ' app.vecfield1 ' and ' app.vecfield2 ])
            xlim(app.UIAxes_3,[-1 1])
            ylim(app.UIAxes_3,[0 500])
            app.UIAxes_3.XTick =[-1 -0.5 0 0.5 1];
            app.UIAxes_3.YTick=[0:50:500];
            set(app.UIAxes_3,'Color',[1 1 1])
        end

        % Button pushed function: SavehistogramofvectordotproductButton
        function SavehistogramofvectordotproductButtonPushed(app, event)
            exportgraphics(app.UIAxes_3,[app.p app.f(1:end-4) '_VectorDotProductHistogram_' app.vecfield1 '_' app.vecfield2 '.png'])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DSTICCSAnalysisTab = uitab(app.TabGroup);
            app.DSTICCSAnalysisTab.Title = '2D STIC(C)S Analysis';
            app.UIAxes2 = uiaxes(app.DSTICCSAnalysisTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes = uiaxes(app.DSTICCSAnalysisTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FramesSlider = uislider(app.DSTICCSAnalysisTab);
            app.FramesSlider.Limits = [1 100];
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.MinorTicks = [];
            app.FramesSlider.Value = 1;
            app.LoadCropandAlignDataPanel = uipanel(app.DSTICCSAnalysisTab);
            app.LoadCropandAlignDataPanel.Title = '1) Load, Crop and Align Data';
            app.LBXSliderLabel = uilabel(app.LoadCropandAlignDataPanel);
            app.LBXSliderLabel.Text = 'LB X';
            app.LBXSlider = uislider(app.LoadCropandAlignDataPanel);
            app.LBXSlider.Limits = [1 100];
            app.LBXSlider.MajorTicks = [1 20 40 60 80 100];
            app.LBXSlider.ValueChangedFcn = createCallbackFcn(app, @LBXSliderValueChanged, true);
            app.LBXSlider.ValueChangingFcn = createCallbackFcn(app, @LBXSliderValueChanging, true);
            app.LBXSlider.Value = 1;
            app.UBXSliderLabel = uilabel(app.LoadCropandAlignDataPanel);
            app.UBXSliderLabel.Text = 'UB X';
            app.UBXSlider = uislider(app.LoadCropandAlignDataPanel);
            app.UBXSlider.Limits = [1 100];
            app.UBXSlider.MajorTicks = [1 20 40 60 80 100];
            app.UBXSlider.ValueChangedFcn = createCallbackFcn(app, @UBXSliderValueChanged, true);
            app.UBXSlider.ValueChangingFcn = createCallbackFcn(app, @UBXSliderValueChanging, true);
            app.UBXSlider.Value = 1;
            app.LoadMultitiforOMEdatafileButton = uibutton(app.LoadCropandAlignDataPanel, 'push');
            app.LoadMultitiforOMEdatafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMultitiforOMEdatafileButtonPushed, true);
            app.LoadMultitiforOMEdatafileButton.Text = 'Load Multi-tif or OME data file';
            app.UBYSliderLabel = uilabel(app.LoadCropandAlignDataPanel);
            app.UBYSliderLabel.Text = 'UB Y';
            app.UBYSlider = uislider(app.LoadCropandAlignDataPanel);
            app.UBYSlider.Limits = [1 100];
            app.UBYSlider.MajorTicks = [1 20 40 60 80 100];
            app.UBYSlider.ValueChangedFcn = createCallbackFcn(app, @UBYSliderValueChanged, true);
            app.UBYSlider.ValueChangingFcn = createCallbackFcn(app, @UBYSliderValueChanging, true);
            app.UBYSlider.Value = 1;
            app.LBYSliderLabel = uilabel(app.LoadCropandAlignDataPanel);
            app.LBYSliderLabel.Text = 'LB Y';
            app.LBYSlider = uislider(app.LoadCropandAlignDataPanel);
            app.LBYSlider.Limits = [1 100];
            app.LBYSlider.MajorTicks = [1 20 40 60 80 100];
            app.LBYSlider.ValueChangedFcn = createCallbackFcn(app, @LBYSliderValueChanged, true);
            app.LBYSlider.ValueChangingFcn = createCallbackFcn(app, @LBYSliderValueChanging, true);
            app.LBYSlider.Value = 1;
            app.BeadsChIDEditFieldLabel = uilabel(app.LoadCropandAlignDataPanel);
            app.BeadsChIDEditFieldLabel.Text = 'Beads Ch ID';
            app.BeadsChIDEditField = uieditfield(app.LoadCropandAlignDataPanel, 'numeric');
            app.BeadsChIDEditField.ValueChangedFcn = createCallbackFcn(app, @BeadsChIDEditFieldValueChanged, true);
            app.BeadsChIDEditField.Value = 1;
            app.LoadUnstressedBeadsOMEImageButton = uibutton(app.LoadCropandAlignDataPanel, 'push');
            app.LoadUnstressedBeadsOMEImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUnstressedBeadsOMEImageButtonPushed, true);
            app.LoadUnstressedBeadsOMEImageButton.Text = 'Load Unstressed Beads OME Image';
            app.FramesSliderLabel = uilabel(app.DSTICCSAnalysisTab);
            app.FramesSliderLabel.Text = 'Frames';
            app.STICCInputParametersPanel = uipanel(app.DSTICCSAnalysisTab);
            app.STICCInputParametersPanel.Title = '4) STIC(C) Input Parameters';
            app.PixelSizemicrometersEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.PixelSizemicrometersEditFieldLabel.Text = 'Pixel Size (micrometers)';
            app.PixelSizemicrometersEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.PixelSizemicrometersEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizemicrometersEditFieldValueChanged, true);
            app.PixelSizemicrometersEditField.Value = 0.1;
            app.FrameTimesecondsEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.FrameTimesecondsEditFieldLabel.Text = 'Frame Time (seconds)';
            app.FrameTimesecondsEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.FrameTimesecondsEditField.ValueChangedFcn = createCallbackFcn(app, @FrameTimesecondsEditFieldValueChanged, true);
            app.FrameTimesecondsEditField.Value = 1;
            app.TimeDelay2ChsecEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.TimeDelay2ChsecEditFieldLabel.Text = 'Time Delay 2 Ch. (sec.)';
            app.TimeDelay2ChsecEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.TimeDelay2ChsecEditField.ValueChangedFcn = createCallbackFcn(app, @TimeDelay2ChsecEditFieldValueChanged, true);
            app.TauLimitframesEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.TauLimitframesEditFieldLabel.Text = 'Tau Limit (frames)';
            app.TauLimitframesEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.TauLimitframesEditField.ValueChangedFcn = createCallbackFcn(app, @TauLimitframesEditFieldValueChanged, true);
            app.TauLimitframesEditField.Value = 10;
            app.MovAveRadiusframesEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.MovAveRadiusframesEditFieldLabel.Text = 'Mov. Ave. Radius (frames) ';
            app.MovAveRadiusframesEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.MovAveRadiusframesEditField.ValueChangedFcn = createCallbackFcn(app, @MovAveRadiusframesEditFieldValueChanged, true);
            app.MovAveRadiusframesEditField.Value = 20;
            app.FitRadiuspixelsEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.FitRadiuspixelsEditFieldLabel.Text = 'Fit Radius (pixels) ';
            app.FitRadiuspixelsEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.FitRadiuspixelsEditField.ValueChangedFcn = createCallbackFcn(app, @FitRadiuspixelsEditFieldValueChanged, true);
            app.FitRadiuspixelsEditField.Value = 5;
            app.TauFitLimitframeEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.TauFitLimitframeEditFieldLabel.Text = 'Tau Fit Limit. (frame)';
            app.TauFitLimitframeEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.TauFitLimitframeEditField.ValueChangedFcn = createCallbackFcn(app, @TauFitLimitframeEditFieldValueChanged, true);
            app.TauFitLimitframeEditField.Value = 3;
            app.stdspeedthresholdEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.stdspeedthresholdEditFieldLabel.Text = 'std(speed) threshold';
            app.stdspeedthresholdEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.stdspeedthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @stdspeedthresholdEditFieldValueChanged, true);
            app.stdspeedthresholdEditField.Value = 2;
            app.ROIsizepixelsEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.ROIsizepixelsEditFieldLabel.Text = 'ROI size (pixels)';
            app.ROIsizepixelsEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.ROIsizepixelsEditField.ValueChangedFcn = createCallbackFcn(app, @ROIsizepixelsEditFieldValueChanged, true);
            app.ROIsizepixelsEditField.Value = 16;
            app.ROIshiftpixelsEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.ROIshiftpixelsEditFieldLabel.Text = 'ROI shift (pixels)';
            app.ROIshiftpixelsEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.ROIshiftpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @ROIshiftpixelsEditFieldValueChanged, true);
            app.ROIshiftpixelsEditField.Value = 4;
            app.TOIsizeframesEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.TOIsizeframesEditFieldLabel.Text = 'TOI size (frames)';
            app.TOIsizeframesEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.TOIsizeframesEditField.ValueChangedFcn = createCallbackFcn(app, @TOIsizeframesEditFieldValueChanged, true);
            app.TOIsizeframesEditField.Value = 10;
            app.TOIshiftframesEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.TOIshiftframesEditFieldLabel.Text = 'TOI shift (frames)';
            app.TOIshiftframesEditField = uieditfield(app.STICCInputParametersPanel, 'numeric');
            app.TOIshiftframesEditField.ValueChangedFcn = createCallbackFcn(app, @TOIshiftframesEditFieldValueChanged, true);
            app.TOIshiftframesEditField.Value = 5;
            app.AxisTitleEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.AxisTitleEditFieldLabel.Text = 'Axis Title';
            app.AxisTitleEditField = uieditfield(app.STICCInputParametersPanel, 'text');
            app.AxisTitleEditField.ValueChangedFcn = createCallbackFcn(app, @AxisTitleEditFieldValueChanged, true);
            app.AxisTitleEditField.Value = '''Velocity Map STICCS''';
            app.OutputFileNameEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.OutputFileNameEditFieldLabel.Text = 'Output File Name';
            app.OutputFileNameEditField = uieditfield(app.STICCInputParametersPanel, 'text');
            app.OutputFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @OutputFileNameEditFieldValueChanged, true);
            app.OutputFileNameEditField.Value = '''Velocity Map STICCS''';
            app.ExportImagesEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.ExportImagesEditFieldLabel.Text = 'Export Images?';
            app.ExportImagesEditField = uieditfield(app.STICCInputParametersPanel, 'text');
            app.ExportImagesEditField.ValueChangedFcn = createCallbackFcn(app, @ExportImagesEditFieldValueChanged, true);
            app.ExportImagesEditField.Value = ''' y''';
            app.ImagesFormatpngpdfjpgEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.ImagesFormatpngpdfjpgEditFieldLabel.Text = 'Images Format (png, pdf, jpg)';
            app.ImagesFormatpngpdfjpgEditField = uieditfield(app.STICCInputParametersPanel, 'text');
            app.ImagesFormatpngpdfjpgEditField.ValueChangedFcn = createCallbackFcn(app, @ImagesFormatpngpdfjpgEditFieldValueChanged, true);
            app.ImagesFormatpngpdfjpgEditField.Value = ''' png''';
            app.MovieFormatavimp4jpegEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.MovieFormatavimp4jpegEditFieldLabel.Text = 'Movie Format (avi, mp4, jpeg)';
            app.MovieFormatavimp4jpegEditField = uieditfield(app.STICCInputParametersPanel, 'text');
            app.MovieFormatavimp4jpegEditField.ValueChangedFcn = createCallbackFcn(app, @MovieFormatavimp4jpegEditFieldValueChanged, true);
            app.MovieFormatavimp4jpegEditField.Value = ''' avi''';
            app.OuputDirectoryEditFieldLabel = uilabel(app.STICCInputParametersPanel);
            app.OuputDirectoryEditFieldLabel.Text = 'Ouput Directory';
            app.OuputDirectoryEditField = uieditfield(app.STICCInputParametersPanel, 'text');
            app.OuputDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @OuputDirectoryEditFieldValueChanged, true);
            app.OuputDirectoryEditField.Value = '''\''';
            app.FilteringChoiceDropDownLabel = uilabel(app.STICCInputParametersPanel);
            app.FilteringChoiceDropDownLabel.Text = 'Filtering Choice';
            app.FilteringChoiceDropDown = uidropdown(app.STICCInputParametersPanel);
            app.FilteringChoiceDropDown.Items = {'none', 'Fourier Whole', 'Moving Average', 'Butterworth IIR'};
            app.FilteringChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @FilteringChoiceDropDownValueChanged, true);
            app.FilteringChoiceDropDown.Value = 'none';
            app.RunSTICCSPanel = uipanel(app.DSTICCSAnalysisTab);
            app.RunSTICCSPanel.Title = '5) Run STIC(C)S';
            app.RunSTICCSButton = uibutton(app.RunSTICCSPanel, 'push');
            app.RunSTICCSButton.ButtonPushedFcn = createCallbackFcn(app, @RunSTICCSButtonPushed, true);
            app.RunSTICCSButton.Text = 'Run STIC(C)S';
            app.BatchRunSTICCSButton = uibutton(app.RunSTICCSPanel, 'push');
            app.BatchRunSTICCSButton.Text = 'Batch Run STIC(C)S';
            app.SaveParamsButton = uibutton(app.RunSTICCSPanel, 'push');
            app.SaveParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamsButtonPushed, true);
            app.SaveParamsButton.Text = 'Save Params';
            app.LoadParamsButton = uibutton(app.RunSTICCSPanel, 'push');
            app.LoadParamsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParamsButtonPushed, true);
            app.LoadParamsButton.Text = 'Load Params';
            app.RunSTICSButton = uibutton(app.RunSTICCSPanel, 'push');
            app.RunSTICSButton.ButtonPushedFcn = createCallbackFcn(app, @RunSTICSButtonPushed, true);
            app.RunSTICSButton.Text = 'Run STICS';
            app.ChaneltowhichapplySTICSEditFieldLabel = uilabel(app.RunSTICCSPanel);
            app.ChaneltowhichapplySTICSEditFieldLabel.Text = 'Chanel to which apply STICS';
            app.ChaneltowhichapplySTICSEditField = uieditfield(app.RunSTICCSPanel, 'numeric');
            app.ChaneltowhichapplySTICSEditField.ValueChangedFcn = createCallbackFcn(app, @ChaneltowhichapplySTICSEditFieldValueChanged, true);
            app.ChaneltowhichapplySTICSEditField.Value = 1;
            app.DefineOverallCellMaskPanel = uipanel(app.DSTICCSAnalysisTab);
            app.DefineOverallCellMaskPanel.Title = '2) Define Overall Cell Mask';
            app.BinariseCellShapeSliderLabel = uilabel(app.DefineOverallCellMaskPanel);
            app.BinariseCellShapeSliderLabel.Text = 'Binarise Cell Shape';
            app.BinariseCellShapeSlider = uislider(app.DefineOverallCellMaskPanel);
            app.BinariseCellShapeSlider.Limits = [0 10];
            app.BinariseCellShapeSlider.ValueChangedFcn = createCallbackFcn(app, @BinariseCellShapeSliderValueChanged, true);
            app.BinariseCellShapeSlider.ValueChangingFcn = createCallbackFcn(app, @BinariseCellShapeSliderValueChanging, true);
            app.ImageforoverallcellshapeDropDownLabel = uilabel(app.DefineOverallCellMaskPanel);
            app.ImageforoverallcellshapeDropDownLabel.Text = ' Image for overall cell shape ';
            app.ImageforoverallcellshapeDropDown = uidropdown(app.DefineOverallCellMaskPanel);
            app.ImageforoverallcellshapeDropDown.Items = {'max. int proj. Ch1', 'max. int proj. Ch2', '<I(Ch2)>', 'max. int proj. All Ch', '<I>', '<I(Ch1)>'};
            app.ImageforoverallcellshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageforoverallcellshapeDropDownValueChanged, true);
            app.ImageforoverallcellshapeDropDown.Value = '<I(Ch1)>';
            app.GenerateOverallCellsMaskButton = uibutton(app.DefineOverallCellMaskPanel, 'push');
            app.GenerateOverallCellsMaskButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateOverallCellsMaskButtonPushed, true);
            app.GenerateOverallCellsMaskButton.Text = 'Generate Overall Cells Mask';
            app.LBCellAreaSliderLabel = uilabel(app.DefineOverallCellMaskPanel);
            app.LBCellAreaSliderLabel.Text = 'LB Cell Area';
            app.LBCellAreaSlider = uislider(app.DefineOverallCellMaskPanel);
            app.LBCellAreaSlider.Limits = [0 1];
            app.LBCellAreaSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.LBCellAreaSlider.ValueChangedFcn = createCallbackFcn(app, @LBCellAreaSliderValueChanged, true);
            app.LBCellAreaSlider.ValueChangingFcn = createCallbackFcn(app, @LBCellAreaSliderValueChanging, true);
            app.LBCellAreaSlider.Value = 0.1;
            app.orSelectCellContourManuallyButton = uibutton(app.DefineOverallCellMaskPanel, 'push');
            app.orSelectCellContourManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @orSelectCellContourManuallyButtonPushed, true);
            app.orSelectCellContourManuallyButton.Text = 'or Select Cell Contour Manually';
            app.TemporalAlignementSeriesPanel = uipanel(app.DSTICCSAnalysisTab);
            app.TemporalAlignementSeriesPanel.Title = '3) Temporal Alignement Series';
            app.AligntoUnstressedBeadsButton = uibutton(app.TemporalAlignementSeriesPanel, 'state');
            app.AligntoUnstressedBeadsButton.ValueChangedFcn = createCallbackFcn(app, @AligntoUnstressedBeadsButtonValueChanged, true);
            app.AligntoUnstressedBeadsButton.Text = 'Align to Unstressed Beads';
            app.MaxCenttoCentDistEditFieldLabel = uilabel(app.TemporalAlignementSeriesPanel);
            app.MaxCenttoCentDistEditFieldLabel.Text = 'Max Cent to Cent Dist.';
            app.MaxCenttoCentDistEditField = uieditfield(app.TemporalAlignementSeriesPanel, 'numeric');
            app.MaxCenttoCentDistEditField.Value = 50;
            app.MinIntensityBeadEditFieldLabel = uilabel(app.TemporalAlignementSeriesPanel);
            app.MinIntensityBeadEditFieldLabel.Text = 'Min Intensity Bead';
            app.MinIntensityBeadEditField = uieditfield(app.TemporalAlignementSeriesPanel, 'numeric');
            app.MinIntensityBeadEditField.Value = 3000;
            app.BeadRadiuspixelsEditFieldLabel = uilabel(app.TemporalAlignementSeriesPanel);
            app.BeadRadiuspixelsEditFieldLabel.Text = 'Bead Radius (pixels)';
            app.BeadRadiuspixelsEditField = uieditfield(app.TemporalAlignementSeriesPanel, 'numeric');
            app.BeadRadiuspixelsEditField.Value = 10;
            app.FindBeadsButton = uibutton(app.TemporalAlignementSeriesPanel, 'push');
            app.FindBeadsButton.ButtonPushedFcn = createCallbackFcn(app, @FindBeadsButtonPushed, true);
            app.FindBeadsButton.Text = 'Find Beads';
            app.WriteAlignedandCropedImagestoTIFButton = uibutton(app.DSTICCSAnalysisTab, 'push');
            app.WriteAlignedandCropedImagestoTIFButton.ButtonPushedFcn = createCallbackFcn(app, @WriteAlignedandCropedImagestoTIFButtonPushed, true);
            app.WriteAlignedandCropedImagestoTIFButton.Text = 'Write Aligned and Croped Images to TIF';
            app.TFMAnalysisTab = uitab(app.TabGroup);
            app.TFMAnalysisTab.Title = 'TFM Analysis';
            app.UIAxes2_2 = uiaxes(app.TFMAnalysisTab);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes_2 = uiaxes(app.TFMAnalysisTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.FramesSlider_2 = uislider(app.TFMAnalysisTab);
            app.FramesSlider_2.Limits = [1 100];
            app.FramesSlider_2.MajorTicks = [];
            app.FramesSlider_2.ValueChangingFcn = createCallbackFcn(app, @FramesSlider_2ValueChanging, true);
            app.FramesSlider_2.MinorTicks = [];
            app.FramesSlider_2.Value = 1;
            app.FramesSliderLabel_2 = uilabel(app.TFMAnalysisTab);
            app.FramesSliderLabel_2.Text = 'Frames';
            app.LoadordefinedatasetsandmasksPanel = uipanel(app.TFMAnalysisTab);
            app.LoadordefinedatasetsandmasksPanel.Title = 'Load or define data sets and masks';
            app.LoadBeadsTIFButton = uibutton(app.LoadordefinedatasetsandmasksPanel, 'push');
            app.LoadBeadsTIFButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBeadsTIFButtonPushed, true);
            app.LoadBeadsTIFButton.Text = 'Load  Beads TIF';
            app.LoadCellChTIFButton = uibutton(app.LoadordefinedatasetsandmasksPanel, 'push');
            app.LoadCellChTIFButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCellChTIFButtonPushed, true);
            app.LoadCellChTIFButton.Text = 'Load Cell Ch.TIF';
            app.LoadMasksTIFButton = uibutton(app.LoadordefinedatasetsandmasksPanel, 'push');
            app.LoadMasksTIFButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMasksTIFButtonPushed, true);
            app.LoadMasksTIFButton.Text = 'Load Masks TIF';
            app.ORLabel = uilabel(app.LoadordefinedatasetsandmasksPanel);
            app.ORLabel.Text = 'OR';
            app.UseImagesandMasksDefinedinSTICCSTabButton = uibutton(app.LoadordefinedatasetsandmasksPanel, 'push');
            app.UseImagesandMasksDefinedinSTICCSTabButton.ButtonPushedFcn = createCallbackFcn(app, @UseImagesandMasksDefinedinSTICCSTabButtonPushed, true);
            app.UseImagesandMasksDefinedinSTICCSTabButton.Text = 'Use Images and Masks Defined in STICCS Tab ';
            app.LoadAKBeadsImageButton = uibutton(app.LoadordefinedatasetsandmasksPanel, 'push');
            app.LoadAKBeadsImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAKBeadsImageButtonPushed, true);
            app.LoadAKBeadsImageButton.Text = 'Load A.K. Beads Image';
            app.GelpropertiesPanel = uipanel(app.TFMAnalysisTab);
            app.GelpropertiesPanel.Title = 'Gel properties';
            app.YoungModulusPaEditFieldLabel = uilabel(app.GelpropertiesPanel);
            app.YoungModulusPaEditFieldLabel.Text = 'Young Modulus (Pa)';
            app.YoungModulusPaEditField = uieditfield(app.GelpropertiesPanel, 'numeric');
            app.YoungModulusPaEditField.ValueChangedFcn = createCallbackFcn(app, @YoungModulusPaEditFieldValueChanged, true);
            app.YoungModulusPaEditField.Value = 1670;
            app.PoissonratioEditFieldLabel = uilabel(app.GelpropertiesPanel);
            app.PoissonratioEditFieldLabel.Text = 'Poisson ratio';
            app.PoissonratioEditField = uieditfield(app.GelpropertiesPanel, 'numeric');
            app.PoissonratioEditField.ValueChangedFcn = createCallbackFcn(app, @PoissonratioEditFieldValueChanged, true);
            app.PoissonratioEditField.Value = 0.5;
            app.CoefofRegularisationdependonYoungModulusEditFieldLabel = uilabel(app.GelpropertiesPanel);
            app.CoefofRegularisationdependonYoungModulusEditFieldLabel.Text = 'Coef. of Regularisation (depend on Young Modulus)';
            app.CoefofRegularisationdependonYoungModulusEditField = uieditfield(app.GelpropertiesPanel, 'numeric');
            app.CoefofRegularisationdependonYoungModulusEditField.ValueChangedFcn = createCallbackFcn(app, @CoefofRegularisationdependonYoungModulusEditFieldValueChanged, true);
            app.CoefofRegularisationdependonYoungModulusEditField.Value = 1e-19;
            app.PixelSizemEditFieldLabel = uilabel(app.TFMAnalysisTab);
            app.PixelSizemEditFieldLabel.Text = 'Pixel Size (m)';
            app.PixelSizemEditField = uieditfield(app.TFMAnalysisTab, 'numeric');
            app.PixelSizemEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizemEditFieldValueChanged, true);
            app.PixelSizemEditField.Value = 4.26e-08;
            app.PIVParametersPanel = uipanel(app.TFMAnalysisTab);
            app.PIVParametersPanel.Title = 'PIV Parameters';
            app.OverlapPIVpixelsEditFieldLabel = uilabel(app.PIVParametersPanel);
            app.OverlapPIVpixelsEditFieldLabel.Text = 'Overlap PIV (pixels)';
            app.OverlapPIVpixelsEditField = uieditfield(app.PIVParametersPanel, 'numeric');
            app.OverlapPIVpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapPIVpixelsEditFieldValueChanged, true);
            app.OverlapPIVpixelsEditField.Value = 64;
            app.OverlapTrackpixelsEditFieldLabel = uilabel(app.PIVParametersPanel);
            app.OverlapTrackpixelsEditFieldLabel.Text = 'Overlap Track (pixels)';
            app.OverlapTrackpixelsEditField = uieditfield(app.PIVParametersPanel, 'numeric');
            app.OverlapTrackpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @OverlapTrackpixelsEditFieldValueChanged, true);
            app.OverlapTrackpixelsEditField.Value = 32;
            app.IntervalpixelsEditFieldLabel = uilabel(app.PIVParametersPanel);
            app.IntervalpixelsEditFieldLabel.Text = 'Interval (pixels)';
            app.IntervalpixelsEditField = uieditfield(app.PIVParametersPanel, 'numeric');
            app.IntervalpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @IntervalpixelsEditFieldValueChanged, true);
            app.IntervalpixelsEditField.Value = 8;
            app.StepSizeMultipleofPixelEditFieldLabel = uilabel(app.PIVParametersPanel);
            app.StepSizeMultipleofPixelEditFieldLabel.Text = 'Step Size (Multiple of Pixel)';
            app.StepSizeMultipleofPixelEditField = uieditfield(app.PIVParametersPanel, 'numeric');
            app.StepSizeMultipleofPixelEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeMultipleofPixelEditFieldValueChanged, true);
            app.StepSizeMultipleofPixelEditField.Value = 8;
            app.NumberofIterationsEditFieldLabel = uilabel(app.PIVParametersPanel);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.PIVParametersPanel, 'numeric');
            app.NumberofIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofIterationsEditFieldValueChanged, true);
            app.TrackingParametersPanel = uipanel(app.TFMAnalysisTab);
            app.TrackingParametersPanel.Title = 'Tracking Parameters';
            app.MinimalparticlesizepixelsEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.MinimalparticlesizepixelsEditFieldLabel.Text = 'Minimal particle size (pixels)';
            app.MinimalparticlesizepixelsEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.MinimalparticlesizepixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimalparticlesizepixelsEditFieldValueChanged, true);
            app.MinimalparticlesizepixelsEditField.Value = 6;
            app.MaximumradiusofgyrationofparticlespixelsEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.MaximumradiusofgyrationofparticlespixelsEditFieldLabel.Text = 'Maximum radius of gyration of particles (pixels)';
            app.MaximumradiusofgyrationofparticlespixelsEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.MaximumradiusofgyrationofparticlespixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumradiusofgyrationofparticlespixelsEditFieldValueChanged, true);
            app.MaximumradiusofgyrationofparticlespixelsEditField.Value = 25;
            app.Particleseccentricity0roundEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.Particleseccentricity0roundEditFieldLabel.Text = 'Particles'' eccentricity (0=round)';
            app.Particleseccentricity0roundEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.Particleseccentricity0roundEditField.ValueChangedFcn = createCallbackFcn(app, @Particleseccentricity0roundEditFieldValueChanged, true);
            app.Particleseccentricity0roundEditField.Value = 0.3;
            app.MinimalRatioIntensitytoradiusEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.MinimalRatioIntensitytoradiusEditFieldLabel.Text = 'Minimal Ratio Intensity to radius';
            app.MinimalRatioIntensitytoradiusEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.MinimalRatioIntensitytoradiusEditField.ValueChangedFcn = createCallbackFcn(app, @MinimalRatioIntensitytoradiusEditFieldValueChanged, true);
            app.MinimalintegratedintensityEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.MinimalintegratedintensityEditFieldLabel.Text = 'Minimal integrated intensity';
            app.MinimalintegratedintensityEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.MinimalintegratedintensityEditField.ValueChangedFcn = createCallbackFcn(app, @MinimalintegratedintensityEditFieldValueChanged, true);
            app.MinimalintegratedintensityEditField.Value = 3000;
            app.MaximaldisplacementbetweentwoimagespixelsEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.MaximaldisplacementbetweentwoimagespixelsEditFieldLabel.Text = 'Maximal displacement between two images (pixels)';
            app.MaximaldisplacementbetweentwoimagespixelsEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.MaximaldisplacementbetweentwoimagespixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MaximaldisplacementbetweentwoimagespixelsEditFieldValueChanged, true);
            app.MaximaldisplacementbetweentwoimagespixelsEditField.Value = 25;
            app.MinimumdistancetonearestneighbourpixelsEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.MinimumdistancetonearestneighbourpixelsEditFieldLabel.Text = 'Minimum distance to nearest neighbour (pixels)';
            app.MinimumdistancetonearestneighbourpixelsEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.MinimumdistancetonearestneighbourpixelsEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumdistancetonearestneighbourpixelsEditFieldValueChanged, true);
            app.CorrelationThresholdEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.CorrelationThresholdEditFieldLabel.Text = 'Correlation Threshold';
            app.CorrelationThresholdEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.CorrelationThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @CorrelationThresholdEditFieldValueChanged, true);
            app.CorrelationThresholdEditField.Value = 0.5;
            app.TheminimumlengthoftrajectoryEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.TheminimumlengthoftrajectoryEditFieldLabel.Text = 'The minimum length of trajectory';
            app.TheminimumlengthoftrajectoryEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.TheminimumlengthoftrajectoryEditField.ValueChangedFcn = createCallbackFcn(app, @TheminimumlengthoftrajectoryEditFieldValueChanged, true);
            app.TheminimumlengthoftrajectoryEditField.Value = 2;
            app.MemoryframesparticlecandisappeartemporarilyEditFieldLabel = uilabel(app.TrackingParametersPanel);
            app.MemoryframesparticlecandisappeartemporarilyEditFieldLabel.Text = 'Memory: # frames particle can disappear temporarily';
            app.MemoryframesparticlecandisappeartemporarilyEditField = uieditfield(app.TrackingParametersPanel, 'numeric');
            app.MemoryframesparticlecandisappeartemporarilyEditField.ValueChangedFcn = createCallbackFcn(app, @MemoryframesparticlecandisappeartemporarilyEditFieldValueChanged, true);
            app.RunTractionForceMicroscopyAnalysisPanel = uipanel(app.TFMAnalysisTab);
            app.RunTractionForceMicroscopyAnalysisPanel.Title = 'Run Traction Force Microscopy Analysis';
            app.RunTFMButton = uibutton(app.RunTractionForceMicroscopyAnalysisPanel, 'push');
            app.RunTFMButton.ButtonPushedFcn = createCallbackFcn(app, @RunTFMButtonPushed, true);
            app.RunTFMButton.Text = 'Run TFM';
            app.ShowBeadsDisplacementVectorMapandSaveinTIFButton = uibutton(app.RunTractionForceMicroscopyAnalysisPanel, 'push');
            app.ShowBeadsDisplacementVectorMapandSaveinTIFButton.ButtonPushedFcn = createCallbackFcn(app, @ShowBeadsDisplacementVectorMapandSaveinTIFButtonPushed, true);
            app.ShowBeadsDisplacementVectorMapandSaveinTIFButton.Text = 'Show Beads Displacement Vector Map and Save in TIF ';
            app.ShowForcesExertedbyCellOnSubstrateAndSaveinTIFButton = uibutton(app.RunTractionForceMicroscopyAnalysisPanel, 'push');
            app.ShowForcesExertedbyCellOnSubstrateAndSaveinTIFButton.ButtonPushedFcn = createCallbackFcn(app, @ShowForcesExertedbyCellOnSubstrateAndSaveinTIFButtonPushed, true);
            app.ShowForcesExertedbyCellOnSubstrateAndSaveinTIFButton.Text = 'Show Forces Exerted by Cell On Substrate And Save in TIF';
            app.MakeStressMagnitudeandDisplacementsMovieButton = uibutton(app.RunTractionForceMicroscopyAnalysisPanel, 'push');
            app.MakeStressMagnitudeandDisplacementsMovieButton.ButtonPushedFcn = createCallbackFcn(app, @MakeStressMagnitudeandDisplacementsMovieButtonPushed, true);
            app.MakeStressMagnitudeandDisplacementsMovieButton.Text = 'Make Stress Magnitude and Displacements Movie';
            app.RunTFMandSaveAllDataButton = uibutton(app.RunTractionForceMicroscopyAnalysisPanel, 'push');
            app.RunTFMandSaveAllDataButton.ButtonPushedFcn = createCallbackFcn(app, @RunTFMandSaveAllDataButtonPushed, true);
            app.RunTFMandSaveAllDataButton.Text = 'Run TFM and Save All Data';
            app.DisplayTFMdataDropDownLabel = uilabel(app.RunTractionForceMicroscopyAnalysisPanel);
            app.DisplayTFMdataDropDownLabel.Text = 'Display TFM data ';
            app.DisplayTFMdataDropDown = uidropdown(app.RunTractionForceMicroscopyAnalysisPanel);
            app.DisplayTFMdataDropDown.Items = {'Displacement vector map', 'Forces exerted by cell', 'Stress magnitude', 'Displacement magnitude'};
            app.DisplayTFMdataDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayTFMdataDropDownValueChanged, true);
            app.DisplayTFMdataDropDown.Value = 'Displacement vector map';
            app.OtherParametersPanel = uipanel(app.TFMAnalysisTab);
            app.OtherParametersPanel.Title = 'Other Parameters';
            app.WindowSizepixelsEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.WindowSizepixelsEditFieldLabel.Text = 'Window Size (pixels)';
            app.WindowSizepixelsEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.WindowSizepixelsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizepixelsEditFieldValueChanged, true);
            app.WindowSizepixelsEditField.Value = 128;
            app.DisplacementfieldsmoothingEditFieldLabel = uilabel(app.OtherParametersPanel);
            app.DisplacementfieldsmoothingEditFieldLabel.Text = 'Displacement  field smoothing';
            app.DisplacementfieldsmoothingEditField = uieditfield(app.OtherParametersPanel, 'numeric');
            app.DisplacementfieldsmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @DisplacementfieldsmoothingEditFieldValueChanged, true);
            app.DisplacementfieldsmoothingEditField.Value = 2;
            app.LoadBlackColormapFileButton = uibutton(app.TFMAnalysisTab, 'push');
            app.LoadBlackColormapFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBlackColormapFileButtonPushed, true);
            app.LoadBlackColormapFileButton.Text = 'Load Black Colormap File';
            app.DataVisualisationTab = uitab(app.TabGroup);
            app.DataVisualisationTab.Title = 'Data Visualisation';
            app.UIAxes2_3 = uiaxes(app.DataVisualisationTab);
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes_3 = uiaxes(app.DataVisualisationTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.FramesSlider_3 = uislider(app.DataVisualisationTab);
            app.FramesSlider_3.Limits = [1 100];
            app.FramesSlider_3.MajorTicks = [];
            app.FramesSlider_3.ValueChangingFcn = createCallbackFcn(app, @FramesSlider_3ValueChanging, true);
            app.FramesSlider_3.MinorTicks = [];
            app.FramesSlider_3.Value = 1;
            app.FramesSliderLabel_3 = uilabel(app.DataVisualisationTab);
            app.FramesSliderLabel_3.Text = 'Frames';
            app.LoadPanel = uipanel(app.DataVisualisationTab);
            app.LoadPanel.Title = 'Load ';
            app.LoadSTICCSresultsButton = uibutton(app.LoadPanel, 'push');
            app.LoadSTICCSresultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSTICCSresultsButtonPushed, true);
            app.LoadSTICCSresultsButton.Text = 'Load STICCS results';
            app.LoadTFMAnalysisResultsButton = uibutton(app.LoadPanel, 'push');
            app.LoadTFMAnalysisResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTFMAnalysisResultsButtonPushed, true);
            app.LoadTFMAnalysisResultsButton.Text = 'Load TFM Analysis Results';
            app.LoadBlackColormapFileButton_2 = uibutton(app.LoadPanel, 'push');
            app.LoadBlackColormapFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadBlackColormapFileButton_2Pushed, true);
            app.LoadBlackColormapFileButton_2.Text = 'Load Black Colormap File';
            app.DisplaySTICCSDataPanel = uipanel(app.DataVisualisationTab);
            app.DisplaySTICCSDataPanel.Title = 'Display STICCS Data';
            app.UBspeedSliderLabel = uilabel(app.DisplaySTICCSDataPanel);
            app.UBspeedSliderLabel.Text = 'UB speed';
            app.UBspeedSlider = uislider(app.DisplaySTICCSDataPanel);
            app.UBspeedSlider.Limits = [0 1];
            app.UBspeedSlider.ValueChangedFcn = createCallbackFcn(app, @UBspeedSliderValueChanged, true);
            app.UBspeedSlider.ValueChangingFcn = createCallbackFcn(app, @UBspeedSliderValueChanging, true);
            app.UBspeedSlider.Value = 0.95;
            app.SpeedHistogramButton = uibutton(app.DisplaySTICCSDataPanel, 'push');
            app.SpeedHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpeedHistogramButtonPushed, true);
            app.SpeedHistogramButton.Text = 'Speed Histogram';
            app.ExportSpeedHistogramButton = uibutton(app.DisplaySTICCSDataPanel, 'push');
            app.ExportSpeedHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSpeedHistogramButtonPushed, true);
            app.ExportSpeedHistogramButton.Text = 'Export Speed Histogram';
            app.ExportVectorMapsForSelectedChannelButton = uibutton(app.DisplaySTICCSDataPanel, 'push');
            app.ExportVectorMapsForSelectedChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVectorMapsForSelectedChannelButtonPushed, true);
            app.ExportVectorMapsForSelectedChannelButton.Text = 'Export Vector Maps For Selected Channel';
            app.ExportVectorMapsForAllChannelsButton = uibutton(app.DisplaySTICCSDataPanel, 'push');
            app.ExportVectorMapsForAllChannelsButton.Text = 'Export Vector Maps For All Channels';
            app.STICCSdataDropDown_2Label = uilabel(app.DisplaySTICCSDataPanel);
            app.STICCSdataDropDown_2Label.Text = 'STICCS data';
            app.STICCSdataDropDown_2 = uidropdown(app.DisplaySTICCSDataPanel);
            app.STICCSdataDropDown_2.Items = {'Auto-corr1', 'Auto-corr2', 'Cross-corr12', 'Cross-corr21'};
            app.STICCSdataDropDown_2.ValueChangedFcn = createCallbackFcn(app, @STICCSdataDropDown_2ValueChanged, true);
            app.STICCSdataDropDown_2.Value = 'Auto-corr1';
            app.UseFilteredVectorsOnlyButton = uibutton(app.DisplaySTICCSDataPanel, 'state');
            app.UseFilteredVectorsOnlyButton.ValueChangedFcn = createCallbackFcn(app, @UseFilteredVectorsOnlyButtonValueChanged, true);
            app.UseFilteredVectorsOnlyButton.Text = 'Use Filtered Vectors Only';
            app.ShowVectorMapsForSelectedChannelButton = uibutton(app.DisplaySTICCSDataPanel, 'push');
            app.ShowVectorMapsForSelectedChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVectorMapsForSelectedChannelButtonPushed, true);
            app.ShowVectorMapsForSelectedChannelButton.Text = 'Show Vector Maps For Selected Channel';
            app.LBspeedSliderLabel = uilabel(app.DisplaySTICCSDataPanel);
            app.LBspeedSliderLabel.Text = 'LB speed';
            app.LBspeedSlider = uislider(app.DisplaySTICCSDataPanel);
            app.LBspeedSlider.Limits = [0 1];
            app.LBspeedSlider.ValueChangedFcn = createCallbackFcn(app, @LBspeedSliderValueChanged, true);
            app.LBspeedSlider.ValueChangingFcn = createCallbackFcn(app, @LBspeedSliderValueChanging, true);
            app.LBspeedSlider.Value = 0.05;
            app.BckdImageDropDownLabel = uilabel(app.DisplaySTICCSDataPanel);
            app.BckdImageDropDownLabel.Text = 'Bckd Image';
            app.BckdImageDropDown = uidropdown(app.DisplaySTICCSDataPanel);
            app.BckdImageDropDown.Items = {'Ch1', 'Ch2'};
            app.BckdImageDropDown.ValueChangedFcn = createCallbackFcn(app, @BckdImageDropDownValueChanged, true);
            app.BckdImageDropDown.Value = 'Ch1';
            app.VectorScaleFactorEditFieldLabel = uilabel(app.DisplaySTICCSDataPanel);
            app.VectorScaleFactorEditFieldLabel.Text = 'Vector Scale Factor';
            app.VectorScaleFactorEditField = uieditfield(app.DisplaySTICCSDataPanel, 'numeric');
            app.VectorScaleFactorEditField.ValueChangedFcn = createCallbackFcn(app, @VectorScaleFactorEditFieldValueChanged, true);
            app.VectorScaleFactorEditField.Value = 2;
            app.DisplaySTICCSandTFMdatatogetherPanel = uipanel(app.DataVisualisationTab);
            app.DisplaySTICCSandTFMdatatogetherPanel.Title = 'Display STICCS and TFM data together';
            app.STICCSdataDropDownLabel = uilabel(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.STICCSdataDropDownLabel.Text = 'STICCS data';
            app.STICCSdataDropDown = uidropdown(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.STICCSdataDropDown.Items = {'Auto-corr1', 'Auto-corr2', 'Cross-corr12', 'Cross-corr21'};
            app.STICCSdataDropDown.ValueChangedFcn = createCallbackFcn(app, @STICCSdataDropDownValueChanged, true);
            app.STICCSdataDropDown.Value = 'Auto-corr1';
            app.TFMdataDropDownLabel = uilabel(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.TFMdataDropDownLabel.Text = 'TFM data';
            app.TFMdataDropDown = uidropdown(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.TFMdataDropDown.Items = {'Displacement Beads', 'Traction Beads'};
            app.TFMdataDropDown.ValueChangedFcn = createCallbackFcn(app, @TFMdataDropDownValueChanged, true);
            app.TFMdataDropDown.Value = 'Displacement Beads';
            app.ShowVectorMapsSuperimposedButton = uibutton(app.DisplaySTICCSandTFMdatatogetherPanel, 'push');
            app.ShowVectorMapsSuperimposedButton.ButtonPushedFcn = createCallbackFcn(app, @ShowVectorMapsSuperimposedButtonPushed, true);
            app.ShowVectorMapsSuperimposedButton.Text = 'Show Vector Maps Superimposed';
            app.ShowMagnitudesMapsSuperimposedButton = uibutton(app.DisplaySTICCSandTFMdatatogetherPanel, 'push');
            app.ShowMagnitudesMapsSuperimposedButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMagnitudesMapsSuperimposedButtonPushed, true);
            app.ShowMagnitudesMapsSuperimposedButton.Text = 'Show Magnitudes Maps Superimposed';
            app.SaveVectorMapsSuperimposedButton = uibutton(app.DisplaySTICCSandTFMdatatogetherPanel, 'push');
            app.SaveVectorMapsSuperimposedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVectorMapsSuperimposedButtonPushed, true);
            app.SaveVectorMapsSuperimposedButton.Text = 'Save Vector Maps Superimposed';
            app.SaveMagnitudesMapsSuperimposedButton = uibutton(app.DisplaySTICCSandTFMdatatogetherPanel, 'push');
            app.SaveMagnitudesMapsSuperimposedButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMagnitudesMapsSuperimposedButtonPushed, true);
            app.SaveMagnitudesMapsSuperimposedButton.Text = 'Save Magnitudes Maps Superimposed';
            app.LBXSlider_2Label = uilabel(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.LBXSlider_2Label.Text = 'LB X';
            app.LBXSlider_2 = uislider(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.LBXSlider_2.Limits = [1 100];
            app.LBXSlider_2.MajorTicks = [1 20 40 60 80 100];
            app.LBXSlider_2.ValueChangingFcn = createCallbackFcn(app, @LBXSlider_2ValueChanging, true);
            app.LBXSlider_2.Value = 1;
            app.UBXSlider_2Label = uilabel(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.UBXSlider_2Label.Text = 'UB X';
            app.UBXSlider_2 = uislider(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.UBXSlider_2.Limits = [1 100];
            app.UBXSlider_2.MajorTicks = [1 20 40 60 80 100];
            app.UBXSlider_2.ValueChangingFcn = createCallbackFcn(app, @UBXSlider_2ValueChanging, true);
            app.UBXSlider_2.Value = 1;
            app.LBYSlider_2Label = uilabel(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.LBYSlider_2Label.Text = 'LB Y';
            app.LBYSlider_2 = uislider(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.LBYSlider_2.Limits = [1 100];
            app.LBYSlider_2.MajorTicks = [1 20 40 60 80 100];
            app.LBYSlider_2.ValueChangingFcn = createCallbackFcn(app, @LBYSlider_2ValueChanging, true);
            app.LBYSlider_2.Value = 1;
            app.UBYSlider_2Label = uilabel(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.UBYSlider_2Label.Text = 'UB Y';
            app.UBYSlider_2 = uislider(app.DisplaySTICCSandTFMdatatogetherPanel);
            app.UBYSlider_2.Limits = [1 100];
            app.UBYSlider_2.MajorTicks = [1 20 40 60 80 100];
            app.UBYSlider_2.ValueChangingFcn = createCallbackFcn(app, @UBYSlider_2ValueChanging, true);
            app.UBYSlider_2.Value = 1;
            app.SavevectordotproductmapButton = uibutton(app.DisplaySTICCSandTFMdatatogetherPanel, 'push');
            app.SavevectordotproductmapButton.ButtonPushedFcn = createCallbackFcn(app, @SavevectordotproductmapButtonPushed, true);
            app.SavevectordotproductmapButton.Text = 'Save vector dot product map';
            app.CalculatevectordotproductandshowmapButton = uibutton(app.DisplaySTICCSandTFMdatatogetherPanel, 'push');
            app.CalculatevectordotproductandshowmapButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatevectordotproductandshowmapButton_2Pushed, true);
            app.CalculatevectordotproductandshowmapButton.Text = 'Calculate vector dot product and show map';
            app.ShowhistogramofvectordotproductButton = uibutton(app.DisplaySTICCSandTFMdatatogetherPanel, 'push');
            app.ShowhistogramofvectordotproductButton.ButtonPushedFcn = createCallbackFcn(app, @ShowhistogramofvectordotproductButtonPushed, true);
            app.ShowhistogramofvectordotproductButton.Text = 'Show histogram of vector dot product';
            app.SavehistogramofvectordotproductButton = uibutton(app.DisplaySTICCSandTFMdatatogetherPanel, 'push');
            app.SavehistogramofvectordotproductButton.ButtonPushedFcn = createCallbackFcn(app, @SavehistogramofvectordotproductButtonPushed, true);
            app.SavehistogramofvectordotproductButton.Text = 'Save histogram of vector dot product';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STICCSandTFMgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end